classdef CellMateV19Published < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataSelection                   matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        AuromatedBGTab                  matlab.ui.container.Tab
        BackgroundGap                   matlab.ui.control.NumericEditField
        BackgroundGapLabel              matlab.ui.control.Label
        PrecedingScans                  matlab.ui.control.NumericEditField
        PrecedingScansLabel             matlab.ui.control.Label
        BackgroundScansLabel            matlab.ui.control.Label
        BackgroundScans                 matlab.ui.control.NumericEditField
        BackgroundWindowLabel           matlab.ui.control.Label
        GlobalBGTab                     matlab.ui.container.Tab
        GlobalBGEnd                     matlab.ui.control.NumericEditField
        EndtimeminLabel                 matlab.ui.control.Label
        GlobalBGStart                   matlab.ui.control.NumericEditField
        StarttimeminLabel               matlab.ui.control.Label
        SetGlobalBGButton               matlab.ui.control.Button
        UseGlobalBackgroundCheckBox     matlab.ui.control.CheckBox
        BackgroundWindowLabel_2         matlab.ui.control.Label
        DeleteSelectedRowsButton        matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        AutomatedDetectionTab           matlab.ui.container.Tab
        EventWindowExtractionPanel      matlab.ui.container.Panel
        ExportEventDataButton           matlab.ui.control.Button
        CutoffTime                      matlab.ui.control.NumericEditField
        CutoffTimeLabel                 matlab.ui.control.Label
        SN                              matlab.ui.control.NumericEditField
        mzLabel_2                       matlab.ui.control.Label
        mz                              matlab.ui.control.NumericEditField
        mzLabel                         matlab.ui.control.Label
        AutomaticDetectionLabel         matlab.ui.control.Label
        ManualExtractionLabel           matlab.ui.control.Label
        ClearEventListButton            matlab.ui.control.Button
        UploadedFileNameLabel           matlab.ui.control.Label
        UploadEventListButton           matlab.ui.control.Button
        DetectEvents                    matlab.ui.control.Button
        LogfileSyncTab                  matlab.ui.container.Tab
        EventWindowExtractionPanel_2    matlab.ui.container.Panel
        ClearSyncListButton             matlab.ui.control.Button
        UploadedFileNameLabel_2         matlab.ui.control.Label
        ManualOverrideCheckBox          matlab.ui.control.CheckBox
        ManualDelayOffsetEditField      matlab.ui.control.NumericEditField
        ManualTimeDelayLabel            matlab.ui.control.Label
        TouchdownDurationEditField      matlab.ui.control.NumericEditField
        TouchdownDurationsLabel         matlab.ui.control.Label
        UploadLogFileButton             matlab.ui.control.Button
        ExportEventDataButton_2         matlab.ui.control.Button
        SyncCalculatedButton            matlab.ui.control.Button
        StatusLampImport                matlab.ui.control.Lamp
        DataSelectionPanel              matlab.ui.container.Panel
        ScanTypeDropdown                matlab.ui.control.DropDown
        ScanTypeLabel                   matlab.ui.control.Label
        FileDropdown                    matlab.ui.control.DropDown
        FileLabel                       matlab.ui.control.Label
        LoadDLLsButton                  matlab.ui.control.Button
        SelectDataFolderButton          matlab.ui.control.Button
        PlotEICTracePanel               matlab.ui.container.Panel
        PlotIntensityCheckbox           matlab.ui.control.CheckBox
        ppm                             matlab.ui.control.NumericEditField
        ppmLabel                        matlab.ui.control.Label
        mzPlotting                      matlab.ui.control.NumericEditField
        mzLabel_3                       matlab.ui.control.Label
        PlotEICButton                   matlab.ui.control.Button
        StatusTextArea                  matlab.ui.control.TextArea
        ActionsLabel                    matlab.ui.control.Label
        TimeWindowsTable                matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
        Processing                      matlab.ui.container.Tab
        GenerateFileReportButton        matlab.ui.control.Button
        ExportEIC                       matlab.ui.control.Button
        ExportTableTab2                 matlab.ui.control.Button
        DataProcessingTargeted          matlab.ui.control.Button
        StatusLampProcessing            matlab.ui.control.Lamp
        UntargetedOptionsPanel          matlab.ui.container.Panel
        InsideCellographicPeakLabel     matlab.ui.control.Label
        MinHitsEditField                matlab.ui.control.NumericEditField
        DetectFreqinclBGEditFieldLabel  matlab.ui.control.Label
        MinScansSCEditField             matlab.ui.control.NumericEditField
        MinScansLabel                   matlab.ui.control.Label
        DetectionFreqEditField          matlab.ui.control.NumericEditField
        DetectionFreqEditFieldLabel     matlab.ui.control.Label
        BackgroundFilteringLabel        matlab.ui.control.Label
        FoldchangeEditField             matlab.ui.control.NumericEditField
        FoldchangeEditFieldLabel        matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        UntargetedTab                   matlab.ui.container.Tab
        UntargetedResultsTable          matlab.ui.control.Table
        AnnotatedTab                    matlab.ui.container.Tab
        AnnotatedResultsTable           matlab.ui.control.Table
        ProcessingOptionsPanel          matlab.ui.container.Panel
        IntensitythresholdEditField     matlab.ui.control.NumericEditField
        IntensitythresholdEditFieldLabel  matlab.ui.control.Label
        FilteringLabel                  matlab.ui.control.Label
        ExcludemzLabel                  matlab.ui.control.Label
        ppmSearch                       matlab.ui.control.NumericEditField
        ppmDatabaseSearchLabel          matlab.ui.control.Label
        ClearExclusionButton            matlab.ui.control.Button
        UploadedExclusionLabel          matlab.ui.control.Label
        UploadExclusionList             matlab.ui.control.Button
        MassDefectFilteringCheckBox     matlab.ui.control.CheckBox
        ppmIsotopeFiltering             matlab.ui.control.NumericEditField
        ppmEditField_2Label             matlab.ui.control.Label
        IsotopeExactMassEditField       matlab.ui.control.NumericEditField
        IsotopeexactmassLabel           matlab.ui.control.Label
        IsotopeFilteringCheckBox        matlab.ui.control.CheckBox
        IntensitiesDropDown             matlab.ui.control.DropDown
        IntensitiesDropDownLabel        matlab.ui.control.Label
        TICNormalizationCheckBox        matlab.ui.control.CheckBox
        TargetedDataProcessingOptionsPanel  matlab.ui.container.Panel
        RemoveDuplicatesCheckBox_2      matlab.ui.control.CheckBox
        AnnotationLabel                 matlab.ui.control.Label
        QuantificationLabel             matlab.ui.control.Label
        BaselineCorrectionCheckbox      matlab.ui.control.CheckBox
        ClearAnnotationInclusionListButton  matlab.ui.control.Button
        AnnotationListLabel             matlab.ui.control.Label
        AnnotationListButton            matlab.ui.control.Button
        ClearISExclusionListButton      matlab.ui.control.Button
        UploadedISExclusionLabel        matlab.ui.control.Label
        UploadISExclusionListButton     matlab.ui.control.Button
        FlowrateEditField               matlab.ui.control.NumericEditField
        FlowrateuLminEditFieldLabel     matlab.ui.control.Label
        DetectionFrequncyQuantified     matlab.ui.control.NumericEditField
        DetectionFrequncyquantifiedLabel  matlab.ui.control.Label
        ISNormalizationCheckBox         matlab.ui.control.CheckBox
        ClearISPairedListButton         matlab.ui.control.Button
        UploadedISPairedLabel           matlab.ui.control.Label
        UploadISPaired                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        StatusTextAreaProcessing        matlab.ui.control.TextArea
        ActionsLabel_2                  matlab.ui.control.Label
        EICAxes                         matlab.ui.control.UIAxes
        DeepLearning                    matlab.ui.container.Tab
        PrevEICButton                   matlab.ui.control.Button
        NextEICButton                   matlab.ui.control.Button
        StatusLampDL                    matlab.ui.control.Lamp
        DeepLearningModelPanel          matlab.ui.container.Panel
        ClassifyEICButton               matlab.ui.control.Button
        LoadEICDataButton               matlab.ui.control.Button
        LoadUntargetedDataButton        matlab.ui.control.Button
        LoadDLNetworkButton             matlab.ui.control.Button
        ExportClassifiedDataPanel       matlab.ui.container.Panel
        ExportButtonDL                  matlab.ui.control.Button
        ExportFilterDropDown            matlab.ui.control.DropDown
        ClassificationDropDownLabel     matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        ClassificationLabel             matlab.ui.control.Label
        ScrollablePanel                 matlab.ui.container.Panel
        StatusTextAreaDL                matlab.ui.control.TextArea
        ActionsLabel_3                  matlab.ui.control.Label
        DLClassificationTabGroup        matlab.ui.container.TabGroup
        UntargetedTab_2                 matlab.ui.container.Tab
        UntargetedResultsTable_2        matlab.ui.control.Table
        TargetedTab_2                   matlab.ui.container.Tab
        TargetedResultsTable_2          matlab.ui.control.Table
        EICAxes_2                       matlab.ui.control.UIAxes
        FileAlignment                   matlab.ui.container.Tab
        StatusLampFileAlignment         matlab.ui.control.Lamp
        FilteringPanel                  matlab.ui.container.Panel
        DetectionFreqAlignmentEachEditField  matlab.ui.control.NumericEditField
        DetectionFrequencyEditField_2Label_2  matlab.ui.control.Label
        DetectionFreqAlignmentEditField  matlab.ui.control.NumericEditField
        DetectionFrequencyGlobalLabel   matlab.ui.control.Label
        FinalAlignmentTable             matlab.ui.control.Table
        FileTable                       matlab.ui.control.Table
        StatusTextAreaAlignment         matlab.ui.control.TextArea
        ActionsLabel_4                  matlab.ui.control.Label
        AligningcsvFIlesPanel           matlab.ui.container.Panel
        RemoveDuplicatesCheckBox        matlab.ui.control.CheckBox
        ppmAlignmentEditField           matlab.ui.control.NumericEditField
        ppmAlignmentLabel               matlab.ui.control.Label
        ExportAlignedTableButton        matlab.ui.control.Button
        AlignFilesButton                matlab.ui.control.Button
        UploadcsvFolderButton           matlab.ui.control.Button
    end


    properties (Access = private)
        sourceFiles % Description
        selectedScanType = "FTMS";
        time_list_events % Stores the uploaded event list
        scanData % Structure to hold FTMS and ITMS data
        rawData
        DLLsLoaded logical = false; % Tracks whether DLLs were successfully loaded
        times_sc
        times_bg
        spectra_comb
        extractedEvents % Description
        ISExclusion
        spectra_sc % Description
        ISPaired % Description
        scanWise
        eventWise % Description
        allFeatures % Description
        untargetedTable % Description
        quantifiedTable % Description
        filteredFeatures % Description
        ExclusionList % Description
        AnnotationList % Description
        targetedTable % Description
        targetedTableEIC % Description
        untargetedTableEIC
        sc_all_data % Store individual cell data
        previousHighlightedRow = 0; % Track previously highlighted row
        highlightStyle = uistyle('BackgroundColor', [0.9 0.9 1]); % Light blue highligh
        TargetedTab % Description
        trainedNetwork_1 % Description
        predictedFeatures % Description
        predictedSNHigh
        predictedSNLow
        predictedBG
        SelectedRows % Description
        previousHighlightedRowDL = 0; % Track previously highlighted row in DL tab
        highlightStyleDL = uistyle('BackgroundColor', [0.9 0.9 1]); % Light blue highlight for DL
        RawFiles
        FileLabels
        SourceDir
        FinalTable % Description
        FilteredTable % Description
        ActiveFilter % Description
        fileType % Description
        GalleryPage % Description
        EICRawData % Description
        CurrentEICPage = 1   % Current page number for EIC gallery
        EICPageSize = 20     % Number of plots per page
        CurrentEICFilter = "All"; % stores the active filter
        EICPerPage = 8
        FilteredEICIndices % Description
        spectra_bg
        targetedTableEIC_array % Description
        predictedFeaturesTargeted % Description
        TargetedResultsTable_2_Table % Description
        min_hits_of_all_scans % Description
        rawLogTable % Description
        UploadedLogLabel % Description
        msStartTimes % Description
        MSStartTimeStatusLabel % Description
        GlobalBGTime % Description
    end

    properties (Access = public)
    end

    methods (Access = private)
        function importDLLs(app)
            % Get the full path of the current file
            currentFilePath = mfilename('fullpath');
            if isempty(currentFilePath)
                currentFilePath = which('CellMate');
            end
            currentDir = fileparts(currentFilePath);

            % Default DLL folder
            dllPath = fullfile(currentDir, 'DLLs');

            % Verify DLL files exist in the default folder
            rawFileReaderDll = fullfile(dllPath, 'ThermoFisher.CommonCore.RawFileReader.dll');
            dataDll = fullfile(dllPath, 'ThermoFisher.CommonCore.Data.dll');

            % Check if DLLs exist in the default folder
            if isfile(rawFileReaderDll) && isfile(dataDll)
                % DLLs found in the default folder
                try
                    NET.addAssembly(rawFileReaderDll);
                    NET.addAssembly(dataDll);
                    import ThermoFisher.CommonCore.RawFileReader.*
                    import ThermoFisher.CommonCore.Data.*

                    app.StatusTextArea.Value = sprintf("DLLs loaded from:\n%s", dllPath);
                    disp(['DLLs successfully loaded from: ', dllPath]);
                    app.DLLsLoaded = true; % DLLs successfully loaded
                    return; % Exit the function after successful loading
                catch ME
                    % DLLs found but failed to load
                    uialert(app.UIFigure, ME.message, 'DLL Error');
                    app.StatusTextArea.Value = "DLL Error: " + ME.message;
                    app.DLLsLoaded = false; % DLLs not loaded due to error
                end
            end

            % If DLLs were not found or failed to load, ask the user to manually select a folder
            selection = questdlg('DLLs were not automatically loaded. Do you want to manually select a DLL folder?', ...
                'DLL Selection', 'Yes', 'No', 'No');

            if strcmp(selection, 'Yes')
                dllPath = uigetdir(currentDir, 'Select the DLL Folder');
                if dllPath == 0
                    disp('No folder selected. DLLs not loaded.');
                    app.StatusTextArea.Value = "DLLs not loaded. Please select a valid DLL folder.";
                    app.DLLsLoaded = false; % DLLs not loaded
                    return;
                else
                    rawFileReaderDll = fullfile(dllPath, 'ThermoFisher.CommonCore.RawFileReader.dll');
                    dataDll = fullfile(dllPath, 'ThermoFisher.CommonCore.Data.dll');
                end
            else
                disp('User chose not to manually select a DLL folder.');
                app.StatusTextArea.Value = "DLLs not loaded. Please select a valid DLL folder.";
                app.DLLsLoaded = false; % DLLs not loaded
                return;
            end

            % Check if the selected DLLs exist
            if ~isfile(rawFileReaderDll) || ~isfile(dataDll)
                uialert(app.UIFigure, 'DLL files not found in the selected folder.', 'Error');
                app.StatusTextArea.Value = "DLLs not found. Please check the folder.";
                app.DLLsLoaded = false; % DLLs not loaded
                return;
            end

            % Try to load the DLLs from the manually selected folder
            try
                NET.addAssembly(rawFileReaderDll);
                NET.addAssembly(dataDll);
                import ThermoFisher.CommonCore.RawFileReader.*
                import ThermoFisher.CommonCore.Data.*

                app.StatusTextArea.Value = sprintf("DLLs loaded from:\n%s", dllPath);
                disp(['DLLs successfully loaded from: ', dllPath]);
                app.DLLsLoaded = true; % DLLs successfully loaded
            catch ME
                uialert(app.UIFigure, ME.message, 'DLL Error');
                app.StatusTextArea.Value = "DLL Error: " + ME.message;
                app.DLLsLoaded = false; % DLLs not loaded due to error
            end
        end

        function selectFolder(app)
            app.setLampRunning();
            folder = uigetdir();
            if folder == 0
                return;
            end

            % Check for both .raw and .mzML files
            raw_files = dir(fullfile(folder, '*.raw'));
            mzml_files = dir(fullfile(folder, '*.mzML'));

            if isempty(raw_files) && isempty(mzml_files)
                app.StatusTextArea.Value = "No .raw or .mzML files found.";
                return;
            end

            % Determine which file type to load
            if ~isempty(raw_files)
                app.StatusTextArea.Value = "Loading .raw files...";
                sourceFiles = raw_files;
                fileType = 'raw';
            else
                app.StatusTextArea.Value = "Loading .mzML files...";
                sourceFiles = mzml_files;
                fileType = 'mzml';
            end
            app.fileType = fileType; % 'raw' or 'mzml'

            app.sourceFiles = sourceFiles;
            app.FileDropdown.Items = {sourceFiles.name};
            app.FileDropdown.Value = sourceFiles(1).name;

            if strcmp(fileType, 'raw')
                % [Keep your existing raw file processing code]
                % Load .RAW Files
                %function scanData = load_raw_file_all_scans(app, sourceFiles)
                % Initialize waitbar
                h = waitbar(0,'Loading raw files');
                p = 0;
                app.rawData = cell(size(app.sourceFiles,1),1);

                % Initialize a cell array to store start times if there are multiple files
                app.msStartTimes = datetime.empty(size(app.sourceFiles,1),0);

                for k = 1:size(app.sourceFiles,1)
                    direc = strcat(app.sourceFiles(k).folder,"\",app.sourceFiles(k).name);
                    disp(['Loading file: ', direc]);
                    try
                        rawFile = ThermoFisher.CommonCore.RawFileReader.RawFileReaderAdapter.ThreadedFileFactory(direc); % Create the IRawDataPlus object for accessing the RAW file
                        rawFile_accessor = rawFile.CreateThreadAccessor(); % RawFileAccessBase
                        rawFile_accessor.SelectInstrument(ThermoFisher.CommonCore.Data.Business.Device.MS,1); % Get the number of instruments (controllers) present in the RAW file
                        % --- NEW: Capture Exact Wall-Clock Start Time ---
                        % Access the .NET DateTime object and convert to MATLAB datetime
                        netDate = rawFile_accessor.CreationDate;
                        app.msStartTimes(k) = datetime(netDate.Year, netDate.Month, netDate.Day, ...
                            netDate.Hour, netDate.Minute, netDate.Second);

                        % and set the selected instrument to the MS instrument, first instance of it
                        %rawFile_filters = string(rawFile_accessor.GetAutoFilters()); % Scan event
                        n_scans = rawFile_accessor.RunHeaderEx.SpectraCount;
                        data = cell(n_scans,6);

                        % load multiple files
                        for i = (1:1:n_scans) % load data for every scan (spectrum)
                            scanStatistics = rawFile_accessor.GetScanStatsForScanNumber(i);
                            if contains(scanStatistics.ScanType.string,'FTMS')
                                raw = rawFile_accessor.GetAdvancedPacketData(i).CentroidData();
                                mz = raw.Masses.double()';
                                int = raw.Intensities.double()';
                                noise = raw.Noises.double()';
                                baseline = raw.Baselines.double()';
                                start_scan_time = rawFile_accessor.GetScanStatsForScanNumber(i).StartTime;
                                TIC = rawFile_accessor.GetScanStatsForScanNumber(i).TIC;
                                data{i, 1} = [mz, int, noise, baseline, (int-baseline)./(noise-baseline)];
                                data{i, 2} = start_scan_time;
                                data{i, 3} = TIC;
                                data{i, 4} = string(rawFile_accessor.GetScanStatsForScanNumber(i).ScanType); % Scan filter
                                data{i, 5} = str2double(rawFile_accessor.GetTrailerExtraInformation(i).Values(5).string); % Ion injection time
                                data{i, 7} = str2double(rawFile_accessor.GetTrailerExtraInformation(i).Values(8).string); % Resolution
                            end
                            if  contains(scanStatistics.ScanType.string,'ITMS')
                                raw = rawFile_accessor.GetSegmentedScanFromScanNumber(i,scanStatistics);
                                mz = raw.Positions.double()';
                                int = raw.Intensities.double()';
                                start_scan_time = scanStatistics.StartTime;
                                data{i,1} = [mz, int];
                                data{i,2} = start_scan_time;
                                data{i,3} = scanStatistics.TIC;
                                data{i, 4} = string(rawFile_accessor.GetScanStatsForScanNumber(i).ScanType); % Scan filter
                                data{i, 5} = str2double(rawFile_accessor.GetTrailerExtraInformation(i).Values(5).string); % Ion injection time
                                data{i, 7} = str2double(rawFile_accessor.GetTrailerExtraInformation(i).Values(6).string); % Resolution
                            end
                        end
                    catch ME
                        disp(['Error loading file: ', direc]);
                        disp(ME.message);
                        continue;
                    end

                    % Dont calculate Duty cycle here since FTMS and ITMS are mixed
                    % Update waitbar
                    p = p + 1;
                    waitbar(p/size(app.sourceFiles,1) , h)
                    app.rawData{k} = data;
                    rawFile_accessor.Dispose()
                end
                % Close waitbar
                close(h)
            else
                % read mzXML files in paralell
                % Use bioinformatics package to read .mzXML file
                % Export metadata (msLevel, polarity and scanType) in the
                % scan_event variable
                %initialize paralell pool and waitbar
                raw_dat = cell(length(app.sourceFiles),1);

                for k= 1:size(app.sourceFiles,1)
                    % Get full file path by combining folder and filename
                    filename = fullfile(app.sourceFiles(k).folder, app.sourceFiles(k).name);

                    file = readstruct(filename,'FileType','xml','StructNodeName','run');

                    %         file.startTimeAttribute = sscanf(file.startTimeAttribute,'PT%f');
                    %         file.endTimeAttribute = sscanf(file.endTimeAttribute,'PT%f');
                    data_out = cell(size(file.spectrumList.spectrum,2),4);
                    for i = 1:size(file.spectrumList.spectrum,2)


                        mz = char(file.spectrumList.spectrum(i).binaryDataArrayList.binaryDataArray(1).binary);
                        intensity = char(file.spectrumList.spectrum(i).binaryDataArrayList.binaryDataArray(2).binary);

                        bitsize = [file.spectrumList.spectrum(i).binaryDataArrayList.binaryDataArray(2).cvParam.nameAttribute];
                        double_pres = any(strcmp(bitsize,'64-bit float'));
                        %single_pres = any(strcmp(bitsize,'32-bit float'))
                        precision = '';
                        switch double_pres
                            case 0
                                precision = 'single';
                            case 1
                                precision = 'double';
                        end


                        textBytes = app.base64decode(mz,'', 'java');
                        mz_vec = typecast(textBytes,precision)';

                        textBytes = app.base64decode(intensity,'', 'java');
                        int_vec = typecast(textBytes,precision)';

                        name_attributes = [file.spectrumList.spectrum(i).cvParam.nameAttribute];
                        total_ion_current_index = find(strcmp(name_attributes,'total ion current'));

                        % Store data in the data_out cell array
                        name_attributes = [file.spectrumList.spectrum(i).cvParam.nameAttribute];
                        total_ion_current_index = find(strcmp(name_attributes,'total ion current'));
                        data_out{i,1} = [mz_vec, int_vec];
                        data_out{i,2} = file.spectrumList.spectrum(i).scanList.scan.cvParam(1).valueAttribute;
                        data_out{i,3} = file.spectrumList.spectrum(i).cvParam(total_ion_current_index).valueAttribute;
                        location_of_scan_filter = [file.spectrumList.spectrum(i).scanList.scan.cvParam.nameAttribute];
                        index_of_scan_filter = find(ismember(location_of_scan_filter,"filter string"));
                        data_out{i,4} = file.spectrumList.spectrum(i).scanList.scan.cvParam(index_of_scan_filter).valueAttribute;


                        %file.scan(i).retentionTimeAttribute = sscanf(file.scan(i).retentionTimeAttribute,'PT%f');

                    end
                    raw_dat{k}=data_out;
                end


                for i = 1:size(raw_dat,1)
                    scanheaders = [raw_dat{1,1}{:,4}]';
                end
                unique_scanheaders = unique(scanheaders);
                app.rawData = raw_dat;
            end
            app.StatusTextArea.Value = "Loaded .raw files!";
            app.setLampReady();
            disp(size(app.rawData{1,1}))
        end
        function y = base64decode(app, x, outfname, alg)
            %BASE64DECODE Perform base64 decoding on a string.
            %
            % INPUT:
            %   x    - block of data to be decoded.  Can be a string or a numeric
            %          vector containing integers in the range 0-255. Any character
            %          not part of the 65-character base64 subset set is silently
            %          ignored.  Characters occuring after a '=' padding character are
            %          never decoded. If the length of the string to decode (after
            %          ignoring non-base64 chars) is not a multiple of 4, then a
            %          warning is generated.
            %
            %   outfname - if provided the binary date from decoded string will be
            %          saved into a file. Since Base64 coding is often used to embbed
            %          binary data in xml files, this option can be used to extract and
            %          save them.
            %
            %   alg  - Algorithm to use: can take values 'java' or 'matlab'. Optional
            %          variable defaulting to 'java' which is a little faster. If
            %          'java' is chosen than core of the code is performed by a call to
            %          a java library. Optionally all operations can be performed using
            %          matleb code.
            %
            % OUTPUT:
            %   y    - array of binary data returned as uint8
            %
            %   This function is used to decode strings from the Base64 encoding specified
            %   in RFC 2045 - MIME (Multipurpose Internet Mail Extensions).  The Base64
            %   encoding is designed to represent arbitrary sequences of octets in a form
            %   that need not be humanly readable.  A 65-character subset ([A-Za-z0-9+/=])
            %   of US-ASCII is used, enabling 6 bits to be represented per printable
            %   character.
            %
            %   See also BASE64ENCODE.
            %
            %   Written by Jarek Tuszynski, SAIC, jaroslaw.w.tuszynski_at_saic.com
            %
            %   Matlab version based on 2004 code by Peter J. Acklam
            %   E-mail:      pjacklam@online.no
            %   URL:         http://home.online.no/~pjacklam
            %   http://home.online.no/~pjacklam/matlab/software/util/datautil/base64encode.m

            if nargin<3, alg='java';  end
            if nargin<2, outfname=''; end

            % if x happen to be a filename than read the file
            if (numel(x)<256)
                if (exist(x, 'file')==2)
                    fid = fopen(x,'rb');
                    x = fread(fid, 'uint8');
                    fclose(fid);
                end
            end
            x = uint8(x(:)); % unify format

            % Perform conversion
            switch (alg)
                case 'java'
                    base64 = org.apache.commons.codec.binary.Base64;
                    y = base64.decode(x);
                    y = mod(int16(y),256); % convert from int8 to uint8
                case 'matlab'
                    %  Perform the mapping
                    %   A-Z  ->  0  - 25
                    %   a-z  ->  26 - 51
                    %   0-9  ->  52 - 61
                    %   + -  ->  62       '-' is URL_SAFE alternative
                    %   / _  ->  63       '_' is URL_SAFE alternative
                    map = uint8(zeros(1,256)+65);
                    map(uint8(['A':'Z', 'a':'z', '0':'9', '+/=']))= 0:64;
                    map(uint8('-_'))= 62:63;  % URL_SAFE alternatives
                    x = map(x);  % mapping

                    x(x>64)=[]; % remove non-base64 chars
                    if rem(numel(x), 4)
                        warning('Length of base64 data not a multiple of 4; padding input.');
                    end
                    x(x==64)=[]; % remove padding characters

                    % add padding and reshape
                    nebytes = length(x);         % number of encoded bytes
                    nchunks = ceil(nebytes/4);   % number of chunks/groups
                    if rem(nebytes, 4)>0
                        x(end+1 : 4*nchunks) = 0;  % add padding
                    end
                    x = reshape(uint8(x), 4, nchunks);
                    y = repmat(uint8(0), 3, nchunks);            % for the decoded data

                    % Rearrange every 4 bytes into 3 bytes
                    %    00aaaaaa 00bbbbbb 00cccccc 00dddddd
                    % to form
                    %    aaaaaabb bbbbcccc ccdddddd
                    y(1,:) = bitshift(x(1,:), 2);                 % 6 highest bits of y(1,:)
                    y(1,:) = bitor(y(1,:), bitshift(x(2,:), -4)); % 2 lowest  bits of y(1,:)
                    y(2,:) = bitshift(x(2,:), 4);                 % 4 highest bits of y(2,:)
                    y(2,:) = bitor(y(2,:), bitshift(x(3,:), -2)); % 4 lowest  bits of y(2,:)
                    y(3,:) = bitshift(x(3,:), 6);                 % 2 highest bits of y(3,:)
                    y(3,:) = bitor(y(3,:), x(4,:));               % 6 lowest  bits of y(3,:)

                    % remove extra padding
                    switch rem(nebytes, 4)
                        case 2
                            y = y(1:end-2);
                        case 3
                            y = y(1:end-1);
                    end
            end

            % reshape to a row vector and make it a character array
            y = uint8(reshape(y, 1, numel(y)));

            % save to file if needed
            if ~isempty(outfname)
                fid = fopen(outfname,'wb');
                fwrite(fid, y, 'uint8');
                fclose(fid);
            end
        end

        function detectEventScans(app)
            % 1. Initialization and Parameter Fetching
            cutoff_time = app.CutoffTime.Value;
            preceding_scans = app.PrecedingScans.Value;
            background_scans = app.BackgroundScans.Value;
            background_gap = app.BackgroundGap.Value;

            if isempty(app.rawData)
                app.appendStatusMessage('Error: No data loaded. Please select a folder first.');
                return;
            end

            selectedFile = app.FileDropdown.Value;
            selectedIndex = find(strcmp({app.sourceFiles.name}, selectedFile));

            if isempty(selectedIndex) || selectedIndex > numel(app.rawData)
                app.appendStatusMessage('Error: Selected file not found in rawData.');
                return;
            end

            % 2. Prepare Scan Data
            app.scanData = app.rawData{selectedIndex};
            if isempty(app.scanData)
                app.appendStatusMessage('Error: No scan data available for the selected file.');
                return;
            end

            % Filter by Scan Type
            if strcmpi(app.selectedScanType, 'FTMS')
                app.scanData = app.scanData(contains(string(app.scanData(:, 4)), "FTMS", 'IgnoreCase', true), :);
            elseif strcmpi(app.selectedScanType, 'ITMS')
                app.scanData = app.scanData(contains(string(app.scanData(:, 4)), "ITMS", 'IgnoreCase', true), :);
            end

            % Calculate Duty Cycle
            app.scanData{1, 6} = 0;
            app.scanData{size(app.scanData, 1), 6} = 0;
            for i = 2:size(app.scanData, 1) - 1
                app.scanData{i, 6} = app.scanData{i, 2} - app.scanData{i - 1, 2};
            end

            % 3. Event Detection Logic
            try
                if ~isempty(app.time_list_events)
                    % Manual detection mode
                    [~, ~, ~, app.times_sc, app.times_bg] = ...
                        app.find_single_cell_extraction_manually(...
                        preceding_scans, app.scanData, background_scans, background_gap, app.time_list_events);
                else
                    % Automatic detection mode (Intensity vs SN)
                    [~, ~, ext] = fileparts(selectedFile);
                    if strcmpi(ext, '.mzML') || app.PlotIntensityCheckbox.Value
                        plotMode = 'Intensity';
                    else
                        plotMode = 'SN';
                    end

                    sn_table = app.find_cell_scans(app.scanData, app.mz.Value, app.ppm.Value, app.SN.Value, plotMode);
                    [~, ~, app.spectra_comb, app.times_sc, app.times_bg, ~] = ...
                        app.find_event_extraction_window_automatically(...
                        sn_table, cutoff_time, preceding_scans, background_scans, background_gap);

                    if isempty(app.spectra_comb) || isempty(app.times_sc)
                        uialert(app.UIFigure, 'No cells detected. Adjust Threshold/SN.', 'Detection Warning');
                        return;
                    end
                end

                % 4. CHRONOLOGICAL FILTER (The Fix for Jump-backs)
                % This ensures the data is linear before it ever hits the table
                if ~isempty(app.times_sc)
                    % Sort first just in case detection returned out of order
                    [app.times_sc, sortIdx] = sortrows(app.times_sc, 1);
                    app.times_bg = app.times_bg(sortIdx, :);

                    keepIdx = true(size(app.times_sc, 1), 1);
                    last_end = -1;
                    for k = 1:size(app.times_sc, 1)
                        % Reject if start is before previous end OR if duration is 0
                        if app.times_sc(k, 1) < last_end || app.times_sc(k, 1) == app.times_sc(k, 2)
                            keepIdx(k) = false;
                        else
                            last_end = app.times_sc(k, 2);
                        end
                    end
                    app.times_sc = app.times_sc(keepIdx, :);
                    app.times_bg = app.times_bg(keepIdx, :);
                end

                % 5. Build the Table with the Duration Column
                num_cells = size(app.times_sc, 1);
                if num_cells > 0
                    % Calculate Duration: End - Start
                    durations = app.times_sc(:, 2) - app.times_sc(:, 1);

                    % Align BG data (pad with NaNs if necessary)
                    bg_data = nan(num_cells, 2);
                    if size(app.times_bg, 1) == num_cells
                        bg_data = app.times_bg;
                    end

                    % Assemble: # | Select | SC_Start | SC_End | Duration | BG_Start | BG_End
                    row_numbers = (1:num_cells)';
                    checkboxes = num2cell(false(num_cells, 1));

                    % Create main numeric block
                    main_numeric = [app.times_sc(:,1), app.times_sc(:,2), durations, bg_data(:,1), bg_data(:,2)];

                    % Combine into final table body
                    table_body = [num2cell(row_numbers), checkboxes, num2cell(main_numeric)];

                    % Updated Column Names (must match your UI Table exactly)
                    column_names = {'#', 'Select', 'SC_Start', 'SC_End', 'Duration', 'BG_Start', 'BG_End'};
                    app.TimeWindowsTable.Data = array2table(table_body, 'VariableNames', column_names);

                    app.appendStatusMessage(sprintf('Detected %d valid sequential events.', num_cells));
                else
                    app.TimeWindowsTable.Data = [];
                    app.appendStatusMessage('No valid events detected after chronological filtering.');
                end

                % 6. Update Visuals
                app.plotMzTrace();

            catch ME
                uialert(app.UIFigure, sprintf('Error during cell detection:\n%s', ME.message), 'Detection Error');
            end
        end

        function [spectra_sc, spectra_bg, spectra_comb, times_sc, times_bg] = find_single_cell_extraction_manually(app, preceding_scans, scanData, background_scans, background_gap, time_list_events)
            % Validate inputs
            if isempty(time_list_events)
                error('Time list is empty.');
            end

            % 1. Ensure time_list_events is a numeric matrix for processing
            if istable(time_list_events)
                time_data = table2array(time_list_events);
            elseif iscell(time_list_events)
                time_data = cell2mat(time_list_events);
            else
                time_data = time_list_events;
            end

            % 2. Initialize Data
            num_events = size(time_data, 1);
            all_ms_times = [scanData{:, 2}]'; % Extract scan times once for speed

            spectra_sc = cell(num_events, 4);
            spectra_bg = cell(num_events, 4);
            times_sc = zeros(num_events, 2);
            times_bg = zeros(num_events, 2);

            for i = 1:num_events
                % Find the start and end indices for the peak window
                [~, idx_start_raw] = min(abs(all_ms_times - time_data(i, 1)));
                [~, idx_end_raw] = min(abs(all_ms_times - time_data(i, 2)));

                % Apply preceding scans
                idx_pre = max(1, idx_start_raw - preceding_scans);
                edx = idx_end_raw;

                % --- Extract SC Data (Parentheses used to avoid brace error) ---
                spectra_sc{i, 1} = scanData(idx_pre:edx, :);
                spectra_sc{i, 2} = all_ms_times(idx_pre:edx)';
                spectra_sc{i, 3} = [scanData{idx_pre:edx, 5}]'; % Injection Time
                spectra_sc{i, 4} = [scanData{idx_pre:edx, 6}]'; % Duty Cycle
                times_sc(i, 1) = all_ms_times(idx_pre);
                times_sc(i, 2) = all_ms_times(edx);

                % --- BACKGROUND LOGIC ---
                if app.UseGlobalBackgroundCheckBox.Value && ~isempty(app.GlobalBGTime)
                    % GLOBAL MODE
                    [~, idx_bg] = min(abs(all_ms_times - app.GlobalBGTime(1)));
                    [~, edx_bg] = min(abs(all_ms_times - app.GlobalBGTime(2)));
                else
                    % ROLLING MODE
                    idx_bg = max(1, idx_pre - background_scans - background_gap);
                    edx_bg = max(1, idx_pre - 1 - background_gap);
                end

                % --- Extract BG Data ---
                spectra_bg{i, 1} = scanData(idx_bg:edx_bg, :);
                spectra_bg{i, 2} = all_ms_times(idx_bg:edx_bg)';
                spectra_bg{i, 3} = [scanData{idx_bg:edx_bg, 5}]';
                spectra_bg{i, 4} = [scanData{idx_bg:edx_bg, 6}]';
                times_bg(i, 1) = all_ms_times(idx_bg);
                times_bg(i, 2) = all_ms_times(edx_bg);
            end

            % 3. Create Combined Spectra (BG + SC)
            spectra_comb = cell(num_events, 5);
            for i = 1:num_events
                spectra_comb{i, 2} = spectra_sc{i, 2};
                spectra_comb{i, 3} = spectra_sc{i, 3};
                spectra_comb{i, 4} = spectra_sc{i, 4};
                spectra_comb{i, 5} = size(spectra_bg{i, 1}, 1); % Number of BG scans

                if isempty(spectra_bg{i, 1})
                    spectra_comb{i, 1} = spectra_sc{i, 1};
                else
                    % Background scans placed BEFORE single cell scans
                    spectra_comb{i, 1} = [spectra_bg{i, 1}; spectra_sc{i, 1}];
                end
            end

            % Update Global App Properties
            app.spectra_sc = spectra_sc;
            app.spectra_bg = spectra_bg;
            app.spectra_comb = spectra_comb;
            app.times_sc = times_sc;
            app.times_bg = times_bg;
        end

        function [sn_table] = find_cell_scans(app, scanData, mzValue, ppmValue, snValue, plotMode)
            % plotMode: 'SN' (default) or 'Intensity'


            % Check for NaN or invalid values
            if isnan(mzValue) || isempty(mzValue) || mzValue <= 0
                appendStatusMessage(app, 'Error: Invalid m/z value. Please enter a positive number.');
                sn_table = table(); % Return an empty table
                return;
            end
            if isnan(ppmValue) || isempty(ppmValue) || ppmValue <= 0
                appendStatusMessage(app, 'Error: Invalid ppm value. Please enter a positive number.');
                sn_table = table(); % Return an empty table
                return;
            end
            if isnan(snValue) || isempty(snValue) || snValue < 0
                appendStatusMessage(app, 'Error: Invalid SN value. Please enter a non-negative number.');
                sn_table = table(); % Return an empty table
                return;
            end

            % Debugging: Display the calculated m/z range
            mz_low = mzValue - mzValue / 1E6 * ppmValue;
            mz_high = mzValue + mzValue / 1E6 * ppmValue;
            disp('m/z range:');
            disp([mz_low, mz_high]);

            % Rest of the function...

            data = scanData;
            n_scans = length(data);
            channels = {zeros(size(mz_low, 2))};
            hitlist = cell(n_scans, 5);

            % Iterate through mass channels
            for k = 1:size(mz_low, 2)
                for i = 1:size(data, 1)
                    idx = find(data{i, 1}(:, 1) >= mz_low(k) & data{i, 1}(:, 1) <= mz_high(k));
                    if ~isempty(idx)
                        hitlist{i, 1} = 1; % Hit detected
                        hitlist{i, 2} = data{i, 1}(idx, 2); % Intensity
                        hitlist{i, 3} = data{i, 2}; % Scan start time

                        % Check for Noise/Baseline columns (only in .raw)
                        if size(data{i, 1}, 2) >= 4
                            hitlist{i, 4} = data{i, 1}(idx, 3); % Noise
                            hitlist{i, 5} = data{i, 1}(idx, 4); % Baseline
                        else
                            hitlist{i, 4} = 0;
                            hitlist{i, 5} = 0;
                        end
                    else
                        [hitlist{i, 1:5}] = deal(0);
                    end
                end
                channels{k, :} = hitlist;
            end

            % --- [Existing Shoulder Peak Logic remains the same] ---

            % Calculate Signal-to-Noise Ratio (SN) or Intensity
            found = {zeros(size(mz_low, 2))};
            for k = 1:size(mz_low, 2)
                tbl = cell2table(channels{k});
                hit_tbl = tbl(tbl.Var1 > 0, :);

                safeConvert = @(x) ifelse(isempty(x), 0, double(x(1)));

                % Apply to each column - this ensures the output is always a numeric column of the correct height
                % --- ROBUST CONVERSION BLOCK ---
                % Only use cellfun if the variable is actually a cell array
                if iscell(hit_tbl.Var2)
                    hit_tbl.Var2 = cellfun(@(x) full(double(sum(x))), hit_tbl.Var2);
                end
                if iscell(hit_tbl.Var3)
                    hit_tbl.Var3 = cellfun(@(x) full(double(sum(x))), hit_tbl.Var3);
                end
                if iscell(hit_tbl.Var4)
                    hit_tbl.Var4 = cellfun(@(x) full(double(sum(x))), hit_tbl.Var4);
                end
                if iscell(hit_tbl.Var5)
                    hit_tbl.Var5 = cellfun(@(x) full(double(sum(x))), hit_tbl.Var5);
                end
                % Initialize the result column as a numeric array
                hit_tbl.Metric = zeros(height(hit_tbl), 1);

                for i = 1:height(hit_tbl)
                    intensity = hit_tbl.Var2(i);
                    noise = hit_tbl.Var4(i);
                    baseline = hit_tbl.Var5(i);

                    % Use the plotMode switch
                    if strcmpi(plotMode, 'Intensity') || (noise == 0 && baseline == 0)
                        hit_tbl.Metric(i) = intensity;
                    else
                        % Standard SN Calculation
                        hit_tbl.Metric(i) = (intensity - baseline) ./ (noise - baseline);
                    end
                end
                found{k, :} = hit_tbl;
            end

            % Filter based on the chosen metric
            hit_tbl = found{1, :};
            idx_cut = find(hit_tbl.Metric >= snValue);
            sn_cutoff = hit_tbl(idx_cut, :);

            % Renaming with safety check
            if ~isempty(sn_cutoff)
                sn_table = renamevars(sn_cutoff, ["Var1", "Var2", "Var3", "Var4", "Var5", "Metric"], ...
                    ["Hit", "Int", "Time", "Noise", "Baseline", "MetricValue"]);
            else
                % Return empty table with correct headers if no hits
                sn_table = table([], [], [], [], [], [], 'VariableNames', ...
                    ["Hit", "Int", "Time", "Noise", "Baseline", "MetricValue"]);
            end
        end

        function [spectra_sc, spectra_bg, spectra_comb, times_sc, times_bg, sn_table] = find_event_extraction_window_automatically(app, sn_table, cutoff_time, preceding_scans, background_scans, background_gap)
            % Initialize output variables
            spectra_sc = [];
            spectra_bg = [];
            app.spectra_comb = [];
            times_sc = [];
            times_bg = [];

            % Check if sn_table is empty
            if isempty(sn_table) || size(sn_table, 1) < 2
                appendStatusMessage(app, 'Warning: S/N is too high!');
                return;
            end

            sn_table.TimeDiff = zeros(size(sn_table,1),1);
            sn_table.TimeDiff(1) = 0;
            % Calculating time difference between scans with sufficient SNR
            for i = 2:size(sn_table)-1
                sn_table.TimeDiff(i) = sn_table.Time(i) - sn_table.Time(i-1);
            end

            % start times based on SNR
            sn_table.idx = zeros(size(sn_table,1),1);
            sn_table.idx(1) = 1;
            for i = 2:size(sn_table,1)
                if sn_table.TimeDiff(i) > cutoff_time % TimeDiff between scans with sufficient SNR needs to be bigger than cutoff_time to be considered as new cell
                    sn_table.idx(i) = i;
                end
            end

            % end time based on SNR
            sn_table.edx = zeros(size(sn_table,1),1);
            sn_table.edx(1) = 0;
            sn_table.edx(size(sn_table,1)) = 0;
            for i = 1:size(sn_table,1)-1
                if sn_table.idx(i+1) > sn_table.idx(i)
                    sn_table.edx(i) = i;
                end
                sn_table.edx(size(sn_table,1)) = i+1;
            end

            % Extract times from sn_table
            start_time = sn_table.Time([nonzeros(sn_table.idx)]);
            end_time = sn_table.Time([nonzeros(sn_table.edx)]);
            times = [start_time, end_time];


            % Extract spectra from times
            spectra = cell(size(times,1),4);
            idx = [];
            for i = 1:size(times,1)-1
                idx = [app.scanData{: ,2}]' >= times(i,1) & [app.scanData{:,2}]' <= times(i+1,1);
                spectra{i} = app.scanData(idx,:); % [mz, int, noise, baseline, S/N]
                spectra{i, 2} = app.scanData(idx,2); % scan start time
                spectra{i, 3} = app.scanData(idx,5); % ion injection time
                spectra{i, 4} = app.scanData(idx,6); % Dutycycle [*60s]
            end

            % Find extraction window for the last cell
            diff_start_times = diff(times(:,1));
            pct95_time_diff = prctile(diff_start_times, 95); % 95 percentile for time differences between single cells
            idx_last = [app.scanData{: ,2}]' >= times(size(times,1),1) & [app.scanData{:,2}]' <= times(size(times,1),1)+pct95_time_diff;
            spectra{size(times,1), 1} = app.scanData(idx_last, :); % [mz, int, noise, baseline, S/N]
            spectra{size(times,1), 2} = app.scanData(idx_last, 2); % scan start time
            spectra{size(times,1), 3} = app.scanData(idx_last, 5); % ion injection time
            spectra{size(times,1), 4} = app.scanData(idx_last, 6); % Dutycycle [*60s]

            % Update indices with preceding & background scans
            spectra_sc = cell(size(spectra,1),4);
            spectra_bg = cell(size(spectra,1),4);
            idx = [];

            for i = 1:size(spectra, 1)
                idx = [app.scanData{: ,2}]' == spectra{i,1}{1,2}; % find start time in all scans
                edx = [app.scanData{: ,2}]' == spectra{i,1}{size(spectra{i,1},1),2}; % find end time in all scans
                idx = find(idx); % find index
                edx = find(edx);

                try % regular background and preceding scan selection
                    idx_pre = idx - preceding_scans; % update idx to include preceding scans
                    edx = edx - preceding_scans - background_scans; % update edx to exclude preceding & background scans
                    spectra_sc{i, 1} = app.scanData(idx_pre:edx,:); % Single cells scans
                    spectra_sc{i, 2} = app.scanData(idx_pre:edx,2); % scan start time
                    spectra_sc{i, 3} = app.scanData(idx_pre:edx,5); % ion injection time
                    spectra_sc{i, 4} = app.scanData(idx_pre:edx,6); % Dutycycle [*60s]
                    % Extract updated times for single cell scan window
                    times_sc(i, 1) = app.scanData{idx_pre,2}; % scan start time
                    times_sc(i, 2) = app.scanData{edx,2}; % scan end time
                    % Background scans
                    idx_bg = idx_pre - background_scans - background_gap;
                    edx_bg = idx_pre - 1 - background_gap;
                    spectra_bg{i,1} = app.scanData(idx_bg:edx_bg,:);
                    spectra_bg{i, 2} = app.scanData(idx_bg:edx_bg,2); % scan start time
                    spectra_bg{i, 3} = app.scanData(idx_bg:edx_bg,5); % ion injection time
                    spectra_bg{i, 4} = app.scanData(idx_bg:edx_bg,6); % Dutycycle [*60s]
                    % Extract updated time for background window
                    times_bg(i, 1) = app.scanData{idx_bg,2}; % scan start time
                    times_bg(i, 2) = app.scanData{edx_bg,2}; % scan end time
                catch % if a cell is immedieatly detected at the start of the files no
                    % preceding/background scans are possible
                    appendStatusMessage(app, "Cell "+i+ " has no preceding and background scans")
                    idx = [app.scanData{: ,2}]' == spectra{i,1}{1,2}; % find start time in all scans
                    edx = [app.scanData{: ,2}]' == spectra{i,1}{size(spectra{i,1},1),2}; % find end time in all scans
                    idx = find(idx); % find index
                    edx = find(edx);
                    idx_pre = idx; % update idx to include preceding scans
                    spectra_sc{i, 1} = app.scanData(idx_pre:edx,:); % Single cells scans
                    spectra_sc{i, 2} = app.scanData(idx_pre:edx,2); % scan start time
                    spectra_sc{i, 3} = app.scanData(idx_pre:edx,5); % ion injection time
                    spectra_sc{i, 4} = app.scanData(idx_pre:edx,6); % Dutycycle [*60s]
                    % Extract updated times for single cell scan window
                    times_sc(i, 1) = app.scanData{idx_pre,2}; % scan start time
                    times_sc(i, 2) = app.scanData{edx,2}; % scan end time
                    % Background scans
                    idx_bg = idx_pre;
                    try
                        edx_bg = idx_pre - 1;
                        spectra_bg{i,1} = app.scanData(idx_bg:edx_bg,:);
                        spectra_bg{i, 2} = app.scanData(idx_bg:edx_bg,2); % scan start time
                        spectra_bg{i, 3} = app.scanData(idx_bg:edx_bg,5); % ion injection time
                        spectra_bg{i, 4} = app.scanData(idx_bg:edx_bg,6); % Dutycycle [*60s]
                        % Extract updated time for background window
                        times_bg(i, 1) = app.scanData{idx_bg,2}; % scan start time
                        times_bg(i, 2) = app.scanData{edx_bg,2}; % scan end time
                    catch % if first cell with no background scans consists of only one scan
                        edx_bg = idx_pre;
                        spectra_bg{i,1} = app.scanData(idx_bg:edx_bg,:);
                        spectra_bg{i, 2} = app.scanData(idx_bg:edx_bg,2); % scan start time
                        spectra_bg{i, 3} = app.scanData(idx_bg:edx_bg,5); % ion injection time
                        spectra_bg{i, 4} = app.scanData(idx_bg:edx_bg,6); % Dutycycle [*60s]
                        % Extract updated time for background window
                        times_bg(i, 1) = app.scanData{idx_bg,2}; % scan start time
                        times_bg(i, 2) = app.scanData{edx_bg,2}; % scan end time
                    end
                end
            end

            % Merge background and single cell scans
            spectra_comb = cell(size(spectra,1),5);
            for k = 1:size(spectra,2)
                for i = 1:length(spectra)
                    empty_list = cellfun(@isempty,spectra_bg);
                    if empty_list(i) == 1 % when Background spectra adding is disabled (0 scans)
                        spectra_comb{i, k} = spectra{i,k};
                        spectra_comb{i, size(spectra,2)+1} = size(spectra_bg{i,1},1);
                    else
                        spectra_comb{i, k} = vertcat(spectra_bg{i,k}, spectra_sc{i,k});
                        spectra_comb{i, size(spectra,2)+1} = size(spectra_bg{i,1},1);
                    end
                end
            end
            app.spectra_comb = spectra_comb;
            app.spectra_sc = spectra_sc;

            if sum(empty_list) == length(empty_list)
                spectra_bg = empty_list - 1;
                times_bg(:,1:2) = [];
            end
            if app.UseGlobalBackgroundCheckBox.Value
                % If Global is on, we don't want the rolling times_bg to exist
                times_bg = repmat(app.GlobalBGTime, size(times_sc, 1), 1);
            end
        end

        function extracted_data = find_cell_scans_from_time_window(~, all_mz_scans, times_sc)
            % Validate inputs
            if ~istable(all_mz_scans) || isempty(times_sc)
                error('Invalid input: all_mz_scans must be a table, times_sc must be non-empty.');
            end

            % Initialize output table
            extracted_data = table();

            % Loop through each time window and extract matching scans
            for i = 1:size(times_sc, 1)
                start_time = times_sc(i, 1);
                end_time = times_sc(i, 2);

                % Find scans within the current time window
                idx = all_mz_scans.Time >= start_time & all_mz_scans.Time <= end_time;
                window_scans = all_mz_scans(idx, :);

                % Append to extracted_data
                extracted_data = [extracted_data; window_scans];
            end

            % Handle empty cases
            if isempty(extracted_data)
                extracted_data = table('Size', [0, width(all_mz_scans)], ...
                    'VariableNames', all_mz_scans.Properties.VariableNames, ...
                    'VariableTypes', varfun(@class, all_mz_scans, 'OutputFormat', 'cell'));
            end
        end


        function appendStatusMessage(app, message)
            % Append a new message to the StatusTextArea
            currentMessages = app.StatusTextArea.Value;
            if isempty(currentMessages)
                app.StatusTextArea.Value = message;
                app.StatusTextAreaProcessing.Value = message;
                app.StatusTextAreaDL.Value = message;
                app.StatusTextAreaAlignment.Value = message;
            else
                app.StatusTextArea.Value = [currentMessages; message];
                app.StatusTextAreaProcessing.Value = [currentMessages; message];
                app.StatusTextAreaDL.Value = [currentMessages; message];
                app.StatusTextAreaAlignment.Value = [currentMessages; message];
            end
            %drawnow; % Refresh the UI
        end

        function updatePlotAfterRowDeletion(app)
            % 1. Clear the current plot
            cla(app.UIAxes);

            % 2. Get the updated table data from the UI Component
            table_data = app.TimeWindowsTable.Data;
            if isempty(table_data)
                appendStatusMessage(app, 'No time windows remaining.');
                legend(app.UIAxes, 'off');
                return;
            end

            % 3. Extract and convert time windows to numeric arrays
            app.times_sc = [table_data.SC_Start, table_data.SC_End];
            app.times_bg = [table_data.BG_Start, table_data.BG_End];

            if istable(app.times_sc), app.times_sc = table2array(app.times_sc); end
            if istable(app.times_bg), app.times_bg = table2array(app.times_bg); end
            if iscell(app.times_sc), app.times_sc = cell2mat(app.times_sc); end
            if iscell(app.times_bg), app.times_bg = cell2mat(app.times_bg); end

            % 4. Determine Plotting Mode (Consistency check)
            if strcmpi(app.fileType, 'mzML') || app.PlotIntensityCheckbox.Value
                plotMode = 'Intensity';
                yLabelText = 'Intensity';
            else
                plotMode = 'SN';
                yLabelText = 'S/N Ratio';
            end

            % 5. Update the combined spectra data
            app.spectra_comb = app.updateSpectraCombAfterDeletion(app.times_sc, app.times_bg);

            % 6. Retrieve data for plotting using the app method
            % Pass 5 arguments: app, data, mz, ppm, threshold(0), mode
            all_mz_scans = app.find_cell_scans(app.scanData, app.mz.Value, app.ppm.Value, 0, plotMode);

            if isempty(all_mz_scans) || height(all_mz_scans) == 0
                appendStatusMessage(app, 'Warning: No valid data points found for current m/z.');
                return;
            end

            % 7. Plot the main trace (Using MetricValue to handle both SN/Intensity)
            plot(app.UIAxes, all_mz_scans.Time, all_mz_scans.MetricValue, 'k-', 'LineWidth', 1);
            hold(app.UIAxes, 'on');

            % 8. Initialize Patch Handles (CRITICAL: Prevents "Unrecognized variable" error)
            sc_patches = gobjects(0);
            bg_patches = gobjects(0);
            sc_colors = {'#043395', '#009fcd'};

            y_lim = ylim(app.UIAxes);
            ymin = y_lim(1);
            ymax = y_lim(2);

            % 9. Draw Single-cell windows
            if ~isempty(app.times_sc) && all(~isnan(app.times_sc(:)))
                for i = 1:size(app.times_sc, 1)
                    color_idx = mod(i - 1, 2) + 1;
                    sc_color = sc_colors{color_idx};
                    x = [app.times_sc(i, 1), app.times_sc(i, 1), app.times_sc(i, 2), app.times_sc(i, 2)];
                    y = [ymin, ymax, ymax, ymin];

                    p = patch(app.UIAxes, 'XData', x, 'YData', y, ...
                        'FaceColor', sscanf(sc_color(2:end),'%2x%2x%2x',[1 3])/255, ...
                        'FaceAlpha', 0.2, 'EdgeColor', 'none');
                    sc_patches = [sc_patches, p];
                end
            end

            % 10. Draw Background windows
            if ~isempty(app.times_bg) && all(~isnan(app.times_bg(:)))
                for i = 1:size(app.times_bg, 1)
                    bg_color = '#E56200';
                    x = [app.times_bg(i, 1), app.times_bg(i, 1), app.times_bg(i, 2), app.times_bg(i, 2)];
                    y = [ymin, ymax, ymax, ymin];

                    p = patch(app.UIAxes, 'XData', x, 'YData', y, ...
                        'FaceColor', sscanf(bg_color(2:end),'%2x%2x%2x',[1 3])/255, ...
                        'FaceAlpha', 0.4, 'EdgeColor', 'none');
                    bg_patches = [bg_patches, p];
                end
            end

            % 11. Finalize Axis and Legend
            hold(app.UIAxes, 'off');
            app.UIAxes.XLabel.String = 'Time (min)';
            app.UIAxes.YLabel.String = yLabelText;
            app.UIAxes.Title.String = 'Intensity with Remaining Time Windows';

            % Safe Legend Logic (checks number of handles before indexing)
            if numel(sc_patches) >= 2 && ~isempty(bg_patches)
                legend(app.UIAxes, [sc_patches(1), sc_patches(2), bg_patches(1)], ...
                    {'Extracted Window', 'Subsequent Window', 'Selected Background'}, 'Location', 'northeast');
            elseif numel(sc_patches) >= 1 && ~isempty(bg_patches)
                legend(app.UIAxes, [sc_patches(1), bg_patches(1)], ...
                    {'Extracted Window', 'Selected Background'}, 'Location', 'northeast');
            elseif numel(sc_patches) >= 2
                legend(app.UIAxes, [sc_patches(1), sc_patches(2)], ...
                    {'Extracted Window', 'Subsequent Window'}, 'Location', 'northeast');
            elseif ~isempty(bg_patches)
                legend(app.UIAxes, bg_patches(1), 'Selected Background', 'Location', 'northeast');
            else
                legend(app.UIAxes, 'off');
            end

            drawnow;
        end

        function plotMzTrace(app)
            % --- 1. Data Loading & Indexing (Original) ---
            if isempty(app.rawData)
                appendStatusMessage(app, 'Error: No data loaded.');
                return;
            end
            selectedFile = app.FileDropdown.Value;
            selectedIndex = find(strcmp({app.sourceFiles.name}, selectedFile));
            if isempty(selectedIndex), return; end
            scanData = app.rawData{selectedIndex};

            % --- 2. Filter Scan Type (Original) ---
            if strcmpi(app.selectedScanType, 'FTMS')
                scanData = scanData(contains(string(scanData(:, 4)), "FTMS", 'IgnoreCase', true), :);
            elseif strcmpi(app.selectedScanType, 'ITMS')
                scanData = scanData(contains(string(scanData(:, 4)), "ITMS", 'IgnoreCase', true), :);
            end

            % --- 3. Determine Mode & Generate sn_table (Original) ---
            if strcmpi(app.fileType, 'mzML') || app.PlotIntensityCheckbox.Value
                plotMode = 'Intensity';
                yLabel = 'Intensity';
            else
                plotMode = 'SN';
                yLabel = 'S/N Ratio';
            end

            % Call find_cell_scans
            sn_table = app.find_cell_scans(scanData, app.mzPlotting.Value, app.ppm.Value, 0, plotMode);

            if isempty(sn_table) || height(sn_table) == 0
                appendStatusMessage(app, 'No data points found for the selected m/z.');
                cla(app.UIAxes); % Added: Clear axes if no data
                return;
            end

            % --- 4. Plotting Core (Original) ---
            time = sn_table.Time;
            yData = sn_table.MetricValue;

            cla(app.UIAxes);
            plot(app.UIAxes, time, yData, 'k-', 'LineWidth', 1);
            hold(app.UIAxes, 'on');

            % --- 5. Aesthetics & Y-Axis (CRITICAL FIX) ---
            ylabel(app.UIAxes, yLabel);
            xlabel(app.UIAxes, 'Time (min)');
            title(app.UIAxes, sprintf('Extracted Ion Chromatogram: m/z %.4f', app.mzPlotting.Value));

            % Re-calculate limits based on current plot to ensure patches are tall enough
            y_lim = [min(yData), max(yData) * 1.1];
            if y_lim(1) == y_lim(2), y_lim(2) = y_lim(1) + 1; end % Avoid flat axes
            ylim(app.UIAxes, y_lim);

            ymin = y_lim(1);
            ymax = y_lim(2);
            app.UIAxes.YTick = unique(round(linspace(ymin, ymax, 11)));

            % --- 6. Overlay Time Windows (Patches) ---
            sc_patches = gobjects(0);
            bg_patches = gobjects(0);
            sc_colors = {'#043395', '#009fcd'}; % Restored: Dark Blue and Light Blue

            % 6a. Draw Cell Windows with Alternating Colors
            if ~isempty(app.times_sc) && all(~isnan(app.times_sc(:)))
                for i = 1:size(app.times_sc, 1)
                    % Restored alternating color logic
                    color_idx = mod(i - 1, 2) + 1;

                    x = [app.times_sc(i, 1), app.times_sc(i, 1), app.times_sc(i, 2), app.times_sc(i, 2)];
                    y = [ymin, ymax, ymax, ymin];

                    p = patch(app.UIAxes, 'XData', x, 'YData', y, ...
                        'FaceColor', sc_colors{color_idx}, 'FaceAlpha', 0.2, 'EdgeColor', 'none');
                    sc_patches = [sc_patches, p];
                end
            end

            % 6b. Background Patch Logic (Conditional)
            if app.UseGlobalBackgroundCheckBox.Value
                % GLOBAL MODE: Draw ONLY the Gold patch
                if diff(app.GlobalBGTime) > 0
                    x = [app.GlobalBGTime(1), app.GlobalBGTime(1), app.GlobalBGTime(2), app.GlobalBGTime(2)];
                    y = [ymin, ymax, ymax, ymin];
                    p = patch(app.UIAxes, 'XData', x, 'YData', y, 'FaceColor', '#FFD700', ...
                        'FaceAlpha', 0.5, 'EdgeColor', '#B8860B', 'LineWidth', 1.5, 'LineStyle', '--');
                    bg_patches = p;
                end
            else
                % ROLLING MODE: Draw multiple Orange patches
                if ~isempty(app.times_bg)
                    for i = 1:size(app.times_bg, 1)
                        x = [app.times_bg(i, 1), app.times_bg(i, 1), app.times_bg(i, 2), app.times_bg(i, 2)];
                        y = [ymin, ymax, ymax, ymin];
                        p = patch(app.UIAxes, 'XData', x, 'YData', y, ...
                            'FaceColor', '#E56200', 'FaceAlpha', 0.4, 'EdgeColor', 'none');
                        bg_patches = [bg_patches, p];
                    end
                end
            end

            % --- 7. Legend & Refresh ---
            % Restored logic to handle the legend dynamically based on what's visible
            if ~isempty(sc_patches)
                if ~isempty(bg_patches)
                    % Detect if we should name it 'Global' or 'Background'
                    bgLabel = 'Background';
                    if app.UseGlobalBackgroundCheckBox.Value, bgLabel = 'Global BG'; end

                    legend(app.UIAxes, [sc_patches(1), bg_patches(1)], ...
                        {'Cell Window', bgLabel}, 'Location', 'northeast');
                else
                    legend(app.UIAxes, sc_patches(1), {'Cell Window'}, 'Location', 'northeast');
                end
            end
            hold(app.UIAxes, 'off');
            drawnow;
        end

        function exportData(app, event)
            % Check if there is data in the TimeWindowsTable
            if isempty(app.TimeWindowsTable.Data)
                appendStatusMessage(app, 'No data to export.');
                return;
            end

            % Get the table data
            table_data = app.TimeWindowsTable.Data;

            % Extract the column names
            column_names = {'SC_Start', 'SC_End', 'BG_Start', 'BG_End'};

            % Ensure the data is in a proper cell format
            try
                export_table = table(table_data.SC_Start, table_data.SC_End, table_data.BG_Start, table_data.BG_End, ...
                    'VariableNames', column_names);
            catch ME
                appendStatusMessage(app, ['Error processing table data: ', ME.message]);
                return;
            end

            % Ask the user to select a file name and path
            [file, path] = uiputfile({'*.csv', 'CSV Files (*.csv)'; '*.xlsx', 'Excel Files (*.xlsx)'}, ...
                'Save Extraction Windows');

            % If the user cancels, exit the function
            if isequal(file, 0)
                appendStatusMessage(app, 'Export cancelled.');
                return;
            end

            % Full file path
            fullFileName = fullfile(path, file);

            % Export the table as CSV or Excel
            [~, ~, ext] = fileparts(fullFileName);
            try
                if strcmp(ext, '.csv')
                    writetable(export_table, fullFileName);
                elseif strcmp(ext, '.xlsx')
                    writetable(export_table, fullFileName, 'Sheet', 1);
                else
                    appendStatusMessage(app, 'Error: Unsupported file format.');
                    return;
                end
                appendStatusMessage(app, ['Exported successfully to ', fullFileName]);
            catch ME
                appendStatusMessage(app, ['Error during export: ', ME.message]);
            end
        end


        function alignExtractedData(app)
            % Update the spectra_comb data based on remaining time windows
            app.spectra_comb = app.updateSpectraCombAfterDeletion(app.times_sc, app.times_bg);

            % --- Dynamic Variables from UI ---
            % Default to 5 if the UI field isn't found
            app.min_hits_of_all_scans = 5; % Percent of total spectra where the peak needs to be present in SC & BG
            if isprop(app, 'MinHitsEditField')
                app.min_hits_of_all_scans = app.MinHitsEditField.Value;
            end
            intensity_thresh = 1E3;
            ppm = 5;
            num_cells = size(app.spectra_comb,1);
            app.extractedEvents = cell(size(app.spectra_comb,1), 13);
            for i = 1:num_cells
                [masses_out, all_int] = app.find_features_all_int(app.spectra_comb(i,:),ppm, app.min_hits_of_all_scans, intensity_thresh);
                app.extractedEvents{i,1} = masses_out;
                app.extractedEvents{i,12} = all_int;
            end
            [app.extractedEvents{:, 3}] = app.spectra_comb{:, 1}; % [mz, int, noise, baseline, S/N]
            [app.extractedEvents{:, 4}] = app.spectra_comb{:, 3}; % ion injection time
            for i = 1:num_cells
                app.extractedEvents{i, 10} = app.spectra_comb{i, 4}; % Duty cycle [*60]
            end
            [app.extractedEvents{:, 11}] = app.spectra_comb{:, 5}; % number of BG spectra
            for i = 1:size(app.spectra_comb)
                app.spectra_comb{i,6} = app.spectra_comb{i, 1}(:,3); % moving data
            end
            [app.extractedEvents{:, 13}] = app.spectra_comb{:, 6}; % TIC
        end

        function targetedProcessing(app)
            fprintf('\n--- EMERGENCY DATA INVESTIGATION START ---\n');

            % 1. List ALL properties that have any content
            all_props = properties(app);
            found_potential_source = false;

            for i = 1:length(all_props)
                try
                    curr_val = app.(all_props{i});
                    if ~isempty(curr_val)
                        [r, c] = size(curr_val);
                        % We are looking for the big table of mass spec scans
                        if r > 10
                            fprintf('POTENTIAL SOURCE: "app.%s" | Size: [%d x %d] | Type: %s\n', ...
                                all_props{i}, r, c, class(curr_val));
                            found_potential_source = true;
                        end
                    end
                catch
                    % Skip properties that can't be read
                end
            end

            if ~found_potential_source
                fprintf('RESULT: No numeric/cell properties found with data.\n');
            end
            fprintf('--- EMERGENCY DATA INVESTIGATION END ---\n\n');
            % Set lamp to running state
            app.setLampRunning();
            alignExtractedData(app)
            disp("Aligned data")
            % Baseline correction & Background Filtering - Tab 2
            %fold_change_SC_BG = 3; % fold change of I(SC/BG) for background filtering, min value of 3 as it can be seen as S/N > 3
            min_hits_of_all_SC_scans = 0; % percent of which features need to be in SC scans

            % Update min_scans from the UI
            % Replace 'MinScansEditField' with the actual name of your component
            % 1. Get values from UI FIRST
            if isprop(app, 'MinScansSCEditField')
                min_scans = app.MinScansSCEditField.Value; % min scans overwrites min_hits_of_all_SC_scans in the background_filtering.. function
            else
                min_scans = 4; % Emergency fallback only
            end

            % 2. DEBUG PRINT
            fprintf('ALIGNMENT: Using %d percent threshold\n', min_scans);

            n_highest_SC_spectra = 1; % number of the n highest intensities for averaging of the extracted single cell window for each m/z for the background filtering
            if ~isempty(app.FoldchangeEditField)
                fold_change_SC_BG = app.FoldchangeEditField.Value;
            end
            [temp] = app.background_filtering_division_baseline_correction_orig(app.extractedEvents, fold_change_SC_BG, min_hits_of_all_SC_scans, min_scans, app.ISExclusion, n_highest_SC_spectra);
            % --- NEW SAFETY CHECK ---
            % Calculate total features remaining across all cells
            totalFeaturesRemaining = sum(cellfun(@(x) size(x, 1), temp(:, 15)));

            if totalFeaturesRemaining == 0
                % Set lamp back to ready/error state
                app.setLampReady();

                % Show a clear warning to the user
                uialert(app.UIFigure, ...
                    sprintf('No features survived the current filters!\n\nCheck:\n- Min Scans (%d)\n- Fold Change (%.1f)\n- Detect. Freq. (incl. BG) (%d%%)', ...
                    min_scans, fold_change_SC_BG, app.min_hits_of_all_scans), ...
                    'Filtering Warning', 'Icon', 'warning');

                appendStatusMessage(app, 'Processing aborted: 0 features remaining.');
                return; % Stop the function here so it doesn't crash later
            end

            % If we have features, continue as normal...
            appendStatusMessage(app, sprintf('Processing %d features...', totalFeaturesRemaining));

            [app.extractedEvents{:,2}] = temp{:,15};
            [app.extractedEvents{:,15}] = temp{:,17};
            [app.extractedEvents{:,2}] = temp{:,15}; % BG filtered features and baseline corrected, IS included without baseline correction
            [app.extractedEvents{:,15}] = temp{:,17}; % BG filtered features and baseline corrected, BG scans included, IS excluded, used for EIC of species
            if iscell(app.spectra_sc) && ~isempty(app.spectra_sc)
                % If it is a cell array, we can use brace indexing safely
                [app.extractedEvents{:, 5}] = app.spectra_sc{:, 4};
            elseif istable(app.spectra_sc)
                % If it returned a table instead, use dot notation or table2cell
                temp_data = table2cell(app.spectra_sc(:, 4));
                [app.extractedEvents{:, 5}] = temp_data{:};
            else
                fprintf('>>> [ERROR] app.spectra_sc is type %s, cannot index!\n', class(app.spectra_sc));
            end
            disp("Finished background subtraction filtering & baseline correction")

            % TIC normalization
            % Inside targetedProcessing
            if app.TICNormalizationCheckBox.Value
                % Get the actual BG count from the first window's metadata
                actual_bg_count = app.spectra_comb{1, 5};

                [TIC_normalized, TIC_sum, TIC_avg] = app.TIC_normalization(app.extractedEvents, actual_bg_count);
                app.extractedEvents(:,14) = TIC_normalized;
                disp("Finished TIC normalization")
            end

            % Normalize features with labelled standards for every scan event
            if app.ISNormalizationCheckBox.Value
                % the format should be like : "Analyte", "m/z labelled", "m/z endogenous"; but the column names can differ
                labelled_table = app.ISPaired;
                if ~isempty(labelled_table)
                    for i = 1:size(app.extractedEvents,1)
                        % last file is potentially to big
                        single_cell = app.extractedEvents{i,2};
                        [int_norm, int_norm_sum] = app.find_normalized_analytes_every_scan(labelled_table, single_cell, app.ppmSearch.Value);
                        app.extractedEvents{i,6} = int_norm;
                        app.extractedEvents{i,7} = int_norm_sum;
                    end
                    disp("Finished IS normalization")

                    % Find total concentration for IS targted analytes - Tab 3
                    flow_rate = app.FlowrateEditField.Value; % in uL/min
                    app.find_total_concentration(app.extractedEvents, flow_rate);

                    % Assign results back to extractedEvents
                    for i = 1:size(app.extractedEvents,1)
                        app.extractedEvents{i,8} = app.scanWise{i};
                        app.extractedEvents{i,9} = app.eventWise{i};
                    end
                    disp("Finished calculating total amount");
                else
                    disp("Upload IS paired list!")
                end
            end
            % Find all features of the experiment - Runs in background
            % Find all features from the experiment with intensity threshold
            ppm = 5; % ppm tolerance, m/z alignment

            spectra_reshaped = app.reshape_bg_filtered_feat_for_aligning(app.extractedEvents);
            % --- M/Z BLOODHOUND TEST ---
            target_mz = 369.3515;
            tolerance = 0.005; % Look within a small window
            fprintf('\n>>> M/Z BLOODHOUND: Searching for %.4f in reshaped data...\n', target_mz);

            found_in_window = false;
            for w = 1:size(spectra_reshaped, 1)
                current_window_data = spectra_reshaped{w,1};
                if ~isempty(current_window_data)
                    % Check if any m/z in the first column matches our target
                    matches = abs(current_window_data(:,1) - target_mz) < tolerance;
                    if any(matches)
                        fprintf('MATCH FOUND! Window %d contains m/z %.4f (Max Int: %.0f)\n', ...
                            w, target_mz, max(current_window_data(matches, 2)));
                        found_in_window = true;
                    end
                end
            end

            if ~found_in_window
                fprintf('!!! NOT FOUND: m/z %.4f is missing from all %d windows.\n', target_mz, size(spectra_reshaped, 1));
            end
            % ---------------------------
            fprintf('\n>>> COMPATIBILITY CHECK: Filtering empty windows...\n');

            % Identify which windows still have data in Column 1
            if ~isempty(spectra_reshaped)
                keep_idx = false(size(spectra_reshaped, 1), 1);
                for k = 1:size(spectra_reshaped, 1)
                    % A window is only "safe" if spectra_reshaped{k,1} has data
                    if ~isempty(spectra_reshaped{k,1}) && size(spectra_reshaped{k,1}, 1) > 0
                        keep_idx(k) = true;
                    end
                end

                % Remove the empty "ghost" windows
                numRemoved = sum(~keep_idx);
                spectra_reshaped = spectra_reshaped(keep_idx, :);

                if numRemoved > 0
                    fprintf('>>> [FIX] Removed %d empty windows to prevent crash at Line 4009.\n', numRemoved);
                end
            end

            if isempty(spectra_reshaped)
                app.setLampReady();
                uialert(app.UIFigure, 'No windows contain valid data after background subtraction.', 'Processing Aborted');
                return;
            end
            app.allFeatures = app.find_experiment_features(spectra_reshaped, ppm);
            disp(size(app.allFeatures))
            disp(app.allFeatures(1,6))
            disp("Finished feature list")

            % Get untargeted table
            ppm = 3; % ppm tolerance between mass list and features detected in single cell scans

            if app.IntensitiesDropDown.Value == "Sum"
                mode = "Sum"; % 'sum' to get the sum of all scans for a single cell or 'mean' for the average, the mean is without zero values
            else
                mode = "mean";
            end
            column = 2; % column in single_cells(:,column); 2 without TIC normalization
            if app.TICNormalizationCheckBox.Value
                column = 14; % 14 for TIC normalization
            end
            if app.DetectionFreqEditField.Value <= 33
                try
                    min_hits_of_all_cells = app.DetectionFreqEditField.Value; %
                    [~, app.untargetedTable] = app.find_final_table(app.allFeatures, app.extractedEvents, column, ppm, app.times_sc, mode, min_hits_of_all_cells); % times is used to identify the cells
                    disp("Finished untargeted table")
                catch
                    min_hits_of_all_cells = 33; %
                    [~, app.untargetedTable] = app.find_final_table(app.allFeatures, app.extractedEvents, column, ppm, app.times_sc, mode, min_hits_of_all_cells); % times is used to identify the cells
                    disp("Finished untargeted table with 33% DF due to computational limits")
                end
            else
                [~, app.untargetedTable] = app.find_final_table(app.allFeatures, app.extractedEvents, column, ppm, app.times_sc, mode, app.DetectionFreqEditField.Value); % times is used to identify the cells
                disp("Finished untargeted table")
            end

            % Quantification of targeted analytes with IS
            if app.ISNormalizationCheckBox.Value
                min_hits_of_all_cells_norm = app.DetectionFrequncyQuantified.Value; % Percent of total single cells where the mz needs to be present
                [~, app.untargetedTable] = app.find_final_table_norm(app.allFeatures, app.extractedEvents, ppm, app.times_sc, min_hits_of_all_cells_norm); % targeted with standards normalized endogenous analytes using single scan normalization
                disp("Finished normalized table")
            end
            % Isotope Filtering - C13 Removeal
            disp(size(app.untargetedTable))
            if app.IsotopeFilteringCheckBox.Value
                ppm_C13 = app.ppmIsotopeFiltering.Value;
                C13 = app.IsotopeExactMassEditField.Value;
                position_of_mz = 1;
                app.untargetedTable = app.exclude_C13_isotopes(app.untargetedTable, position_of_mz, C13, ppm_C13);
                disp("Finished isotope exclusion")
                disp(size(app.untargetedTable))
            end

            % Exclude m/z
            disp(size(app.untargetedTable))
            if ~isempty(app.ExclusionList)
                IS_list = app.ExclusionList;
                app.untargetedTable = app.exclude_mz(app.untargetedTable, IS_list, 1, app.ppmSearch.Value);
                disp("Excluded m/z from Exclusion list")
                disp(size(app.untargetedTable))
            end
            disp("Before MDF")
            disp(size(app.untargetedTable))
            % Mass Defect Filtering
            if app.MassDefectFilteringCheckBox.Value
                app.untargetedTable = app.MassDefectFiltering(app.untargetedTable, 1, 3);
                disp("Mass defect filtered")
                disp(size(app.untargetedTable))
            end

            % Annotation section inside targetedProcessing
            if ~isempty(app.AnnotationList)
                [app.targetedTable, ~, ~] = app.find_analytes_from_list(app.AnnotationList, 2, 1, app.untargetedTable, app.ppmSearch.Value);

                disp("Annotation Complete.");
                if ~isempty(app.targetedTable)
                    fprintf('Targeted table contains %d entries.\n', height(app.targetedTable));
                end
            end

            % Update tables in the UI
            if ~isempty(app.untargetedTable)
                app.UntargetedResultsTable.Data = app.untargetedTable;
                disp("Updated table in UI")
                if isa(app.untargetedTable, 'table')
                    app.UntargetedResultsTable.ColumnName = app.untargetedTable.Properties.VariableNames;
                end
                % Enable row selection
                % Add these initialization lines:
                app.UntargetedResultsTable.UserData.SelectedCell = [];
                app.UntargetedResultsTable.SelectionType = 'row';
                app.UntargetedResultsTable.Multiselect = 'off';
                % Add message about number of untargeted species found

                numUntargeted = size(app.untargetedTable, 1);
                appendStatusMessage(app, sprintf("Found %d untargeted species", numUntargeted));
            end

            % --- Inside targetedProcessing(app) ---
            if ~isempty(app.targetedTable)
                % 1. Ensure the table is visible and populated
                app.AnnotatedResultsTable.Data = app.targetedTable;

                % 2. Explicitly set column names to match the table headers
                app.AnnotatedResultsTable.ColumnName = app.targetedTable.Properties.VariableNames;

                % 3. Check if the table is hidden
                app.AnnotatedResultsTable.Visible = 'on';

                % 4. FORCE UI REFRESH
                drawnow;

                fprintf('GUI Table updated with %d rows.\n', size(app.targetedTable, 1));
            else
                fprintf('Warning: targetedTable is empty, nothing to show in GUI.\n');
            end

            % --- START OF UPDATED CHRONOGRAM EXTRACTION ---
            % This logic bypasses the fragmented 'temp' blocks to provide a
            % completely raw, continuous chronological EIC trace.

            num_scans = size(app.scanData, 1);
            ppm_tol = app.ppmSearch.Value;

            % 1. Extract Untargeted EIC
            if ~isempty(app.untargetedTable)
                target_mz_u = app.untargetedTable{:, 1};
                num_feat_u = length(target_mz_u);
                eic_matrix_u = zeros(num_feat_u, num_scans + 1);
                eic_matrix_u(:, 1) = target_mz_u;

                % Direct lookup in raw scanData
                for s = 1:num_scans
                    raw_peaks = app.scanData{s, 1};
                    if isempty(raw_peaks), continue; end
                    s_mz = raw_peaks(:, 1);
                    s_int = raw_peaks(:, 2);

                    for f = 1:num_feat_u
                        tol = target_mz_u(f) * ppm_tol / 1e6;
                        idx = s_mz >= (target_mz_u(f) - tol) & s_mz <= (target_mz_u(f) + tol);
                        if any(idx), eic_matrix_u(f, s + 1) = max(s_int(idx)); end
                    end
                end
                app.untargetedTableEIC = eic_matrix_u;
            end

            % 2. Extract Targeted EIC
            if ~isempty(app.targetedTable)
                % Target m/z is typically in the 2nd column of the Annotated table
                target_mz_t = app.targetedTable{:, 2};
                num_feat_t = length(target_mz_t);
                eic_matrix_t = zeros(num_feat_t, num_scans + 1);
                eic_matrix_t(:, 1) = target_mz_t;

                for s = 1:num_scans
                    raw_peaks = app.scanData{s, 1};
                    if isempty(raw_peaks), continue; end
                    s_mz = raw_peaks(:, 1);
                    s_int = raw_peaks(:, 2);

                    for f = 1:num_feat_t
                        tol = target_mz_t(f) * ppm_tol / 1e6;
                        idx = s_mz >= (target_mz_t(f) - tol) & s_mz <= (target_mz_t(f) + tol);
                        if any(idx), eic_matrix_t(f, s + 1) = max(s_int(idx)); end
                    end
                end
                app.targetedTableEIC = eic_matrix_t;
                % Plotting utility expects the array part starting from column 2
                app.targetedTableEIC_array = eic_matrix_t(:, 2:end);
            end
            % --- END OF UPDATED CHRONOGRAM EXTRACTION ---

            disp("Extracted Continuous Raw EIC")
            app.PlotEICButton.Enable = 'on';
            app.setLampReady();
        end

        function [EIC, sc_all] = find_chronogram_of_single_cells(app, mass_list, position_of_mz, single_cells, column, ppm)
            % 1. Extract the Target Masses (the m/z column)
            % If position_of_mz is 2, search_masses becomes the 2nd column of your CSV
            search_masses = mass_list{:, position_of_mz};

            num_rows = size(mass_list, 1);
            num_cells = size(single_cells, 1);
            sc_all = cell(num_cells, 1);

            for i = 1:num_cells
                % Get the raw data for this cell (Col 1 = mz, Col 2:end = intensities)
                raw_data = single_cells{i, column};
                raw_mz = raw_data(:, 1);
                raw_ints = raw_data(:, 2:end);

                % 2. Matrix Comparison (Mass vs Raw Data)
                % We compare every search_mass against every raw_mz
                diff = (abs(search_masses - raw_mz') ./ raw_mz') * 1e6 < ppm;

                sum_of_isomers = sum(diff, 2);
                sum_of_isomers(sum_of_isomers == 0) = 1; % Prevent divide by zero

                % 3. Calculate weighted m/z and intensities
                annotated_mz = (diff * raw_mz) ./ sum_of_isomers;
                ints = (diff * raw_ints) ./ sum_of_isomers;

                % Cleanup
                annotated_mz(isnan(annotated_mz)) = 0;
                ints(isnan(ints)) = 0;

                % Store result for this cell [mz, int1, int2...]
                sc_all{i, 1} = [annotated_mz, ints];

                % --- Debug Logging ---
                if any(diff(:))
                    fprintf('Success: Found matches in cell %d\n', i);
                else
                    % Use the first mass in your list for a realistic PPM check
                    [min_val, ~] = min(abs(search_masses(1) - raw_mz));
                    actual_ppm = (min_val / search_masses(1)) * 1e6;
                    fprintf('Cell %d: No match. Target %.4f vs Closest Raw %.4f (%.1f ppm)\n', ...
                        i, search_masses(1), min(raw_mz), actual_ppm);
                end
            end

            % 4. Merge Results
            % Start with the original target masses
            EIC = search_masses;
            for i = 1:num_cells
                % Append only the intensity columns from each cell result
                EIC = [EIC, sc_all{i, 1}(:, 2:end)];
            end
        end

        function setLampRunning(app)
            % Set lamp to orange/yellow (running state)
            app.StatusLampProcessing.Color = [1 0.65 0]; % Orange
            app.StatusLampDL.Color = [1 0.65 0]; % Orange
            app.StatusLampImport.Color = [1 0.65 0]; % Orange
            app.StatusLampFileAlignment.Color = [1 0.65 0]; % Orange
            drawnow; % Force UI update
        end

        function setLampReady(app)
            % Set lamp to green (ready/completed state)
            app.StatusLampProcessing.Color = [0 1 0]; % Green
            app.StatusLampDL.Color = [0 1 0]; % Green
            app.StatusLampImport.Color = [0 1 0]; % Green
            app.StatusLampFileAlignment.Color = [0 1 0]; % Green
            drawnow; % Force UI update
        end

        function setLampError(app)
            % Optional: Set lamp to red (error state)
            app.StatusLampProcessing.Color = [1 0 0]; % Red
            app.StatusLampDL.Color = [1 0 0]; % Red
            app.StatusLampImport.Color = [1 0 0]; % Red
            app.StatusLampFileAlignment.Color = [1 0 0]; % Red
            drawnow; % Force UI update
        end

        function PlotEICfromTableButtonPushed(app, event)
            % Determine which table is active
            if app.TabGroup2.SelectedTab == app.AnnotatedTab
                table = app.AnnotatedResultsTable;
                table_type = 'targeted';
            else
                table = app.UntargetedResultsTable;
                table_type = 'untargeted';
            end

            % Check if any row is selected
            if isempty(table.UserData) || ~isfield(table.UserData, 'SelectedCell') || isempty(table.UserData.SelectedCell)
                uialert(app.UIFigure, 'Please select a row first', 'No Selection');
                return;
            end

            selectedRow = table.UserData.SelectedCell(1);

            % Handle different table types
            if strcmp(table_type, 'untargeted')
                if isempty(app.untargetedTableEIC)
                    uialert(app.UIFigure, 'No untargeted EIC data available', 'Error');
                    return;
                end
                selectedMz = table.Data{selectedRow, 1};
            else
                if isempty(app.targetedTableEIC)
                    uialert(app.UIFigure, 'No targeted EIC data available', 'Error');
                    return;
                end
                if istable(table.Data) && ismember('mz', table.Data.Properties.VariableNames)
                    selectedMz = table.Data.mz(selectedRow);
                else
                    uialert(app.UIFigure, 'No m/z data available in table', 'Error');
                    return;
                end
            end

            plotEICForSelectedMz(app, selectedMz, table_type);
        end

        function spectra_comb = updateSpectraCombAfterDeletion(app, times_sc, times_bg)
            fprintf('\n>>> [DEBUG 1] updateSpectraComb (START)\n');

            % Initialize output (6 columns for alignExtractedData compatibility)
            spectra_comb = cell(0, 6);
            scanData = app.scanData;

            if isempty(scanData)
                fprintf('>>> [DEBUG 1] ABORT: app.scanData is empty!\n');
                return;
            end

            % Clean table/cell inputs to numeric
            if iscell(times_sc), times_sc = cellfun(@(x) double(x{1}), times_sc); end
            if iscell(times_bg), times_bg = cellfun(@(x) helperExtract(x), times_bg); end

            scan_times = [scanData{:, 2}]';
            num_input = size(times_sc, 1);
            temp_comb = cell(num_input, 6);
            valid_count = 0;

            fprintf('>>> [DEBUG 1] Attempting to build %d windows...\n', num_input);

            for i = 1:num_input
                % 1. Find Sample Scans
                sc_idx = scan_times >= times_sc(i, 1) & scan_times <= times_sc(i, 2);
                spectra_sc = scanData(sc_idx, :);

                if ~isempty(spectra_sc)
                    valid_count = valid_count + 1;

                    % 2. Find Background Scans
                    spectra_bg = [];
                    if i <= size(times_bg, 1) && ~isnan(times_bg(i, 1))
                        bg_idx = scan_times >= times_bg(i, 1) & scan_times <= times_bg(i, 2);
                        spectra_bg = scanData(bg_idx, :);
                    end

                    % Column 1: Combined SC and BG
                    if isempty(spectra_bg)
                        temp_comb{valid_count, 1} = spectra_sc;
                    else
                        temp_comb{valid_count, 1} = [spectra_bg; spectra_sc];
                    end

                    % Metadata
                    temp_comb{valid_count, 2} = [spectra_sc{:, 2}]; % times
                    temp_comb{valid_count, 3} = [spectra_sc{:, 5}]; % inj times

                    % Duty Cycle extraction
                    dVals = spectra_sc(:, 6);
                    if iscell(dVals), temp_comb{valid_count, 4} = cell2mat(dVals)';
                    else, temp_comb{valid_count, 4} = dVals'; end

                    temp_comb{valid_count, 5} = size(spectra_bg, 1);
                    temp_comb{valid_count, 6} = [spectra_sc{:, 4}]; % TIC

                    % Check inside the cell we just created
                    if isempty(temp_comb{valid_count, 1})
                        fprintf('>>> [DEBUG 1] WARNING: Window %d resulted in an empty Column 1!\n', i);
                    end
                else
                    fprintf('>>> [DEBUG 1] Window %d (%.2f-%.2f) found NO SCANS in scanData.\n', ...
                        i, times_sc(i,1), times_sc(i,2));
                end
            end

            % Finalizing the output
            spectra_comb = temp_comb(1:valid_count, :);

            fprintf('>>> [DEBUG 1] updateSpectraComb (END). Windows Produced: %d\n', size(spectra_comb, 1));
            if ~isempty(spectra_comb)
                fprintf('>>> [DEBUG 1] Size of Col 1 in first window: [%d x %d]\n', ...
                    size(spectra_comb{1,1}, 1), size(spectra_comb{1,1}, 2));
            end
        end

        function val = helperExtract(x)
            if iscell(x), val = x{1}; else, val = x; end
            if isempty(val), val = NaN; else, val = double(val); end
        end
        function applyMultiExperimentDetectionFilter(app)
            currentThreshold = app.DetectionFreqAlignmentEachEditField.Value;
            if isfield(app, 'ActiveFilter') && strcmp(app.ActiveFilter, 'global') && currentThreshold ~= 0
                uialert(app.UIFigure, 'Global filter is active. Set global threshold to 0 first or use per-experiment filtering exclusively.', 'Filter Conflict');
                return;
            end

            if isempty(app.FinalTable)
                return;
            end

            % Get threshold and convert to fraction
            threshold = currentThreshold / 100;

            % Validate threshold
            if isnan(threshold) || ~isscalar(threshold) || threshold < 0 || threshold > 1
                uialert(app.UIFigure, 'Please enter a valid threshold between 0 and 100.', 'Invalid Value');
                return;
            end

            % Set active filter type (unless threshold is 0)
            if threshold > 0
                app.ActiveFilter = 'per-experiment';
            else
                app.ActiveFilter = 'none';
            end

            % Determine if targeted or untargeted by checking table structure
            try
                % Check if first column is character (likely targeted)
                if iscell(app.FinalTable{:,1}) && ischar(app.FinalTable{1,1}{1})
                    numericData = app.FinalTable{:, 3:end};
                    colNames = app.FinalTable.Properties.VariableNames(3:end);
                    keepColsStart = 3;
                else
                    numericData = app.FinalTable{:, 2:end};
                    colNames = app.FinalTable.Properties.VariableNames(2:end);
                    keepColsStart = 2;
                end
            catch
                numericData = app.FinalTable{:, 2:end};
                colNames = app.FinalTable.Properties.VariableNames(2:end);
                keepColsStart = 2;
            end

            % Only keep intensity columns with '_' in their names
            expColIdx = contains(colNames, '_');
            expColNames = colNames(expColIdx);

            if isempty(expColNames)
                uialert(app.UIFigure, 'No experiment columns found (columns should contain "_").', 'Naming Error');
                return;
            end

            % Split names of intensity columns by '_'
            splitNames = cellfun(@(x) strsplit(x, '_'), expColNames, 'UniformOutput', false);
            splitNames = vertcat(splitNames{:});

            % Check for consistent naming structure
            if size(splitNames, 2) < 2
                uialert(app.UIFigure, 'Intensity columns must be prefixed like "Exp1_"', 'Naming Error');
                return;
            end

            % Get unique experiment prefixes (e.g., Exp1, Exp2, etc.)
            expNames = unique(splitNames(:,1));

            % Initialize mask: start with all rows kept
            keepRowMask = true(size(app.FinalTable, 1), 1);

            % Apply detection frequency filter per experiment
            for e = 1:numel(expNames)
                expName = expNames{e};
                % Find intensity columns for this experiment
                expCols = startsWith(colNames, [expName '_']);
                if ~any(expCols)
                    continue; % Skip if no columns found for this experiment
                end
                expData = numericData(:, expCols);

                % Detection frequency for this experiment
                freq = sum(expData > 0, 2) / size(expData, 2);

                % Remove rows where freq < threshold for this experiment
                keepRowMask = keepRowMask & (freq >= threshold);
            end

            % Only keep rows where threshold is met for all experiments
            app.FilteredTable = app.FinalTable(keepRowMask, :);

            % Update table display
            app.FinalAlignmentTable.Data = app.FilteredTable;
            app.FinalAlignmentTable.ColumnName = app.FilteredTable.Properties.VariableNames;
            app.FinalAlignmentTable.Visible = 'on';

            % Update plot
            if ~isempty(app.FilteredTable) && size(app.FilteredTable, 1) > 0
                try
                    filteredIntensities = app.FilteredTable{:, keepColsStart:end};
                    totalIntensity = sum(filteredIntensities, 1, 'omitnan');

                    cla(app.ResultsAxes)
                    bar(app.ResultsAxes, totalIntensity)
                    app.ResultsAxes.XTick = 1:numel(colNames);
                    app.ResultsAxes.XTickLabel = colNames;
                    app.ResultsAxes.XTickLabelRotation = 45;
                    app.ResultsAxes.Title.String = 'Total Intensity (Per-Experiment Filtered)';
                    app.ResultsAxes.YLabel.String = 'Total Intensity';
                    app.ResultsAxes.Box = 'on';
                catch ME
                    disp("Unable to plot filtered results: " + ME.message);
                end
            else
                cla(app.ResultsAxes);
            end

            % Modified status message
            if threshold > 0
                appendStatusMessage(app, "Per-experiment filter (" + currentThreshold + "%): " + num2str(sum(keepRowMask)) + " features remaining");
            else
                appendStatusMessage(app, "Per-experiment filter reset (threshold = 0)");
            end
        end

        function filterAlignment(app, event)
            % Check if per-experiment filter is active (unless threshold is 0)
            currentThreshold = app.DetectionFreqAlignmentEditField.Value;
            if isfield(app, 'ActiveFilter') && strcmp(app.ActiveFilter, 'per-experiment') && currentThreshold ~= 0
                uialert(app.UIFigure, 'Per-experiment filter is active. Set per-experiment threshold to 0 first or use global filtering exclusively.', 'Filter Conflict');
                return;
            end

            if isempty(app.FinalTable)
                return;
            end

            % Get threshold value
            threshold = currentThreshold / 100;
            if threshold < 0 || threshold > 1
                uialert(app.UIFigure, 'Threshold must be between 0 and 100.', 'Invalid Value');
                return;
            end

            % Set active filter type (unless threshold is 0)
            if threshold > 0
                app.ActiveFilter = 'global';
            else
                app.ActiveFilter = 'none';
            end

            % Determine if targeted or untargeted based on file content
            try ischar(app.Files{1,1}{1,1}{1,1});
                numericData = app.FinalTable{:, 3:end};
                colNames = app.FinalTable.Properties.VariableNames(3:end);
                keepColsStart = 3;
            catch
                numericData = app.FinalTable{:, 2:end};
                colNames = app.FinalTable.Properties.VariableNames(2:end);
                keepColsStart = 2;
            end

            % Calculate detection frequency (non-zero / total samples)
            detectionFreq = sum(numericData > 0, 2) ./ size(numericData, 2);

            % Filter rows by threshold
            rowsToKeep = detectionFreq >= threshold;

            % Create filtered table
            app.FilteredTable = app.FinalTable(rowsToKeep, :);

            % Update table display
            app.FilteredTable = app.FinalTable(rowsToKeep, :);

            % Update FinalAlignmentTable display
            app.FinalAlignmentTable.Data = app.FilteredTable;
            app.FinalAlignmentTable.ColumnName = app.FinalTable.Properties.VariableNames;
            app.FinalAlignmentTable.Visible = 'on';

            % Update plot
            try
                filteredIntensities = app.FilteredTable{:, keepColsStart:end};
                totalIntensity = sum(filteredIntensities, 1);

                % Clear axes
                cla(app.ResultsAxes)

                % Create bar plot
                bar(app.ResultsAxes, totalIntensity)
                app.ResultsAxes.XTick = 1:numel(colNames);
                app.ResultsAxes.XTickLabel = colNames;
                app.ResultsAxes.XTickLabelRotation = 45;
                app.ResultsAxes.Title.String = 'Total Intensity per Sample (Filtered)';
                app.ResultsAxes.YLabel.String = 'Total Intensity';
                app.ResultsAxes.Box = 'on';
            catch
                disp("Unable to plot filtered results.")
            end

            % Modified status message
            if threshold > 0
                appendStatusMessage(app, "Global filter (" + currentThreshold + "%): " + num2str(sum(rowsToKeep)) + " features remaining");
            else
                appendStatusMessage(app, "Global filter reset (threshold = 0)");
            end
        end

        % Button pushed function: ExportButton
        function exportAlignment(app, event)
            % Check if there's a result to export
            if isempty(app.FinalTable)
                uialert(app.UIFigure, 'No processed data available to export.', 'No Data');
                return;
            end

            % Ask user for save location and filename
            [file, path] = uiputfile("*.csv", "Save Results As");
            if isequal(file,0) || isequal(path,0)
                return; % user canceled
            end

            % Build full file path
            fullFileName = fullfile(path, file);

            % Decide which table to export
            if ~isempty(app.FilteredTable) && height(app.FilteredTable) ~= height(app.FinalTable)
                tableToExport = app.FilteredTable;
            else
                tableToExport = app.FinalTable;
            end

            % Write table to CSV
            try
                writetable(tableToExport, fullFileName);
                appendStatusMessage(app, "Results exported to " + fullFileName);
            catch ME
                uialert(app.UIFigure, "Error exporting file: " + ME.message, 'Export Error');
            end
        end

        function [final_table] = find_alignment_untargeted(app, files, masterlist, threshold)
            % Updating the experiment files with the aligned masterlist values and combining all experiments
            experiments = files;

            for k = 1:length(files)
                for i = 1:length(masterlist)
                    mz_files = files{k, 1}.mz;
                    mz_diff = abs(masterlist(i, 1) - mz_files);
                    mask = mz_diff ./ masterlist(i, 1) * 1E6 < threshold;
                    sub_table = experiments{k, 1};
                    sub_table.mz(mask) = masterlist(i, 1);
                    experiments{k, 1} = sub_table;
                end
            end

            % combine all m/z values for joining
            all_values = experiments{1,1}(:,1);
            for i = 2:length(experiments)
                all_values = vertcat(all_values, experiments{i,1}(:,1));
            end

            % rename columns with file labels
            for i = 1:length(experiments)
                table_i = experiments{i,1};
                var_names = table_i.Properties.VariableNames;
                mz_name = var_names{1}; % 'mz' column name remains unchanged

                % Generate new names for intensity columns using file label
                new_var_names = cell(1, length(var_names));
                new_var_names{1} = mz_name; % Keep original 'mz'

                for v = 2:length(var_names)
                    new_var_names{v} = ['Exp' num2str(i) '_' char(var_names{v})];
                end

                table_i.Properties.VariableNames = new_var_names;
                experiments{i,1} = table_i;
            end


            % joining intensities
            all_values = unique(all_values);

            for i = 1:length(experiments)
                all_values = outerjoin(all_values, experiments{i,1}, "Keys",'mz', MergeKeys=true);
            end

            % final formatting
            table_aligned = unique(all_values,"rows");
            final_aligned = fillmissing(table_aligned,"constant",0,'DataVariables',@isnumeric);
            final_table = unique(final_aligned, "rows");
            final_table = sortrows(final_table,"mz");

        end

        function [final_table] = find_alignment_targeted(app, files, masterlist, threshold)
            % update m/z values from aligned masterlist
            experiments = files;

            for k = 1:length(files)
                for i = 1:length(masterlist)
                    mz_files = files{k, 1}.mz;
                    mz_diff = abs(masterlist(i, 1) - mz_files);
                    mask = mz_diff ./ masterlist(i, 1) * 1E6 < threshold;
                    sub_table = experiments{k, 1};
                    sub_table.mz(mask) = masterlist(i, 1);
                    experiments{k, 1} = sub_table;
                end
            end

            % combine all m/z values and analytes from all files
            all_values = experiments{1,1}(:,1:2); % assumes columns: mz, Analyte
            for i = 2:length(experiments)
                all_values = vertcat(all_values, experiments{i,1}(:,1:2));
            end

            % rename intensity columns with prefixes: exp1_, exp2_, ...
            for i = 1:length(experiments)
                t = experiments{i,1};
                var_names = t.Properties.VariableNames;
                new_names = var_names;
                for v = 3:length(var_names)
                    new_names{v} = ['Exp' num2str(i) '_' var_names{v}];
                end
                t.Properties.VariableNames = new_names;
                experiments{i,1} = t;
            end

            % join intensities
            all_values = unique(all_values);

            for i = 1:length(experiments)
                all_values = outerjoin(all_values, experiments{i,1}(:,2:end), "Keys",'mz', MergeKeys=true);
            end

            % format final table
            table_aligned = unique(all_values,"rows");
            final_aligned = fillmissing(table_aligned,"constant",0,'DataVariables',@isnumeric);
            final_table = unique(final_aligned, "rows");
            final_table = sortrows(final_table,"mz");

        end

        function [all_features] = find_masterlist(app, all_analytes,ppmgap)
            % Function to find all m/z features from the experiment. The
            % algorithm accounts for mass tolerance (ppmgap), frequency of detection (min_hits)
            % in the given experiment
            spectra = all_analytes;

            %remove empty rows
            spectra(~cellfun(@isempty, spectra(:,1)), :);

            % Preallocate cells
            num_scan = 0;
            for i = 1:size(spectra,1)
                num_scan = num_scan+size(spectra{i,1},1);
            end

            %Stack all mass spectra in a preallocated array and an intitialized counter
            %variable
            comp_masslist = zeros(num_scan,2);
            count = 1;
            for j = 1:size(spectra,1)
                datnow = spectra{j,1};
                comp_masslist(count:count+size(datnow,1)-1,:) = datnow;
                count = count+size(datnow,1);
            end

            % Sort the list of all m/z and intensity values
            comp_masslist = comp_masslist(comp_masslist(:,1)~=0,:);
            comp_masslist = sortrows(comp_masslist,1);

            % Determine peak groups by determining where the ppm error is bigger than
            % the treshold ppmgap value. All values that pass the test are sorted into
            % the same peak group. The variable pk initializes the ppm calculation
            % frame, i is the iterator for comparing with pk and c is the peakgroup
            % counter.
            i = 1;
            pk = 1;
            c = 1;
            intensity = 0;
            mz = 0;
            pkgroup = {};

            while lt(i,length(comp_masslist)+1) %|| eq(i, length(comp_masslist))

                %intensity = intensity+comp_masslist(i,2);
                mz = mz+comp_masslist(i,1);
                mz_norm = mz/numel(pk:1:i);

                if abs((mz_norm-comp_masslist(i,1))/mz_norm)*1E6<ppmgap
                    i = i+1;

                else
                    pkgroup{c,1} = pk:1:i-1;
                    c = c+1;
                    pk=i;
                    intensity = 0;
                    mz = 0;

                end
            end

            pkgroup{c,1} = pk:1:i-1;

            % Extract data and calculate statisitcs on the peaks in peak groups that
            % are bigger than the threshold min_hits.
            no_features=size(pkgroup,1);
            all_features = zeros(no_features,11);
            for feat = (1:no_features)
                temp_set=comp_masslist(pkgroup{feat,1},:);
                %Feature number
                %all_features(feat,1)=temp_set(1,2);
                %Average accurate mass value weighted based on intensity
                all_features(feat,1)= sum(temp_set(:,2).*temp_set(:,1))./sum(temp_set(:,2));
                %Standard deviation of accurate masss
                %mass_stats(feat,3)=std(temp_set(:,1));
                %Spread of accurate mass
                %mass_stats(feat,4)=max(temp_set(:,1))-min(temp_set(:,1));
                %Detection frequency
                all_features(feat,5)=size(temp_set,1)/num_scan;
                %Mean intensity over the whole region of interest
                all_features(feat,6)=mean(temp_set(:,2));
                %Max intensity
                %mass_stats(feat,7)=max(temp_set(:,6));
                %Standard deviation of intensity
                %mass_stats(feat,8)=std(temp_set(:,6));
                %Mean noise over all spectra
                %all_features(feat,9) = mean(temp_set(:, 9));
                %Mean baseline over all spectra
                %all_features(feat,10) = mean(temp_set(:,10));
                %index for single cell
                % all_features(feat,11) = temp_set(1,11);
            end
        end

        % Button pushed function: ProcessButton
        function alignFiles(app, event)
            % Get table data
            tableData = app.FileTable.Data;
            useFlags = cell2mat(tableData(:,1));  % get the checkboxes

            % Find which files are selected
            selectedFiles = app.RawFiles(useFlags);

            % If no files selected, warn and exit
            if isempty(selectedFiles)
                uialert(app.UIFigure, 'No files selected for processing.', 'No Selection');
                return;
            end

            % Initialize analytes cell
            all_analytes = {};

            % Process the selected files
            try ischar(selectedFiles{1,1}{1,1}{1,1})
                % Targeted approach
                for i = 1:length(selectedFiles)
                    ints = selectedFiles{i,1}{:,3:end};
                    avg_ints = mean(ints,2);
                    mz = selectedFiles{i,1}{:,2};
                    analytes = [mz, avg_ints];
                    all_analytes{i,1} = analytes;
                end
                disp("Found analytes for targeted approach")
            catch
                % Untargeted approach
                for i = 1:length(selectedFiles)
                    ints = selectedFiles{i,1}{:,2:end};
                    avg_ints = mean(ints,2);
                    mz = selectedFiles{i,1}{:,1};
                    analytes = [mz, avg_ints];
                    all_analytes{i,1} = analytes;
                end
                disp("Found analytes for untargeted approach")
            end

            % Create Masterlist
            ppmgap = app.ppmAlignmentEditField.Value;
            masterlist = app.find_masterlist(all_analytes, ppmgap);
            disp("Finished Masterlist")

            % Align Features
            threshold = app.ppmAlignmentEditField.Value;
            try
                ischar(selectedFiles{1,1}{1,1}{1,1})
                final_table_annotated = app.find_alignment_targeted(selectedFiles, masterlist, threshold);

                % Check if duplicates should be removed based on checkbox
                if app.RemoveDuplicatesCheckBox.Value
                    [~, uidx] = unique(final_table_annotated(:,2));
                    annotatedNoDup = final_table_annotated(uidx,:);
                    annotatedNoDup(~any(annotatedNoDup{:,2:end}, 2),:) = [];
                    disp("Aligned targeted analysis - duplicates removed");

                    % Store result in app property
                    app.FinalTable = annotatedNoDup;
                else
                    % Just remove rows with all zeros but keep duplicates
                    final_table_annotated(~any(final_table_annotated{:,2:end}, 2),:) = [];
                    disp("Aligned targeted analysis - duplicates kept");

                    % Store result in app property
                    app.FinalTable = final_table_annotated;
                end
            catch
                final_table_untargeted = app.find_alignment_untargeted(selectedFiles, masterlist, threshold);
                disp("Aligned untargeted analysis")

                % Optionally: store result in app property
                app.FinalTable = final_table_untargeted;
            end

            app.FinalAlignmentTable.Data = app.FinalTable;
            app.FinalAlignmentTable.ColumnName = app.FinalTable.Properties.VariableNames;
            app.FinalAlignmentTable.Visible = 'on';
            % Update status label
            appendStatusMessage(app, "Processed " + numel(selectedFiles) + " files successfully.");
        end

        function importAlignFiles(app, event)
            % Button pushed function: ImportButton
            % Select folder
            source_dir = uigetdir;

            % If user cancels
            if source_dir == 0
                return;
            end

            % Find all files in the selected folder and subfolders
            allEntries = dir(fullfile(source_dir, '**', '*.*'));

            % Filter out '.' and '..' directories
            allEntries = allEntries(~ismember({allEntries.name}, {'.', '..'}));

            % Keep only files (exclude folders)
            source_files = allEntries(~[allEntries.isdir]);

            % Check if files were found
            if isempty(source_files)
                uialert(app.UIFigure, 'No files found in the selected folder.', 'No Files');
                return;
            end

            % Initialize cell arrays
            files = {};
            file_labels = {};
            tableData = cell(numel(source_files), 2);  % For the UITable

            % Load raw files
            for i = 1:numel(source_files)
                fullFilePath = fullfile(source_files(i).folder, source_files(i).name);
                try
                    file = readtable(fullFilePath, Delimiter=",");
                catch
                    file = readtable(fullFilePath);
                end
                files{i, 1} = file;
                file_labels{i, 1} = "exp" + i;

                % Add to table data: default checked (true), and file name
                tableData{i, 1} = true;
                tableData{i, 2} = source_files(i).name;
            end

            % Store in app properties for later use
            app.RawFiles = files;
            app.FileLabels = file_labels;
            app.SourceDir = source_dir;

            % Update UITable with file list and checkboxes
            app.FileTable.Data = tableData;
            % Update status label
            appendStatusMessage(app, "Imported " + numel(files) + " files.");
        end

        function exportDLResults(app)
            % 1. Determine Mode
            % Using .Title check to match your tab naming
            isTargeted = strcmp(app.DLClassificationTabGroup.SelectedTab.Title, 'Targeted');
            filterType = app.ExportFilterDropDown.Value;

            if isTargeted
                % Use the correct property name we used yesterday
                tableData = app.TargetedResultsTable_2.Data;
                classifications = app.predictedFeaturesTargeted;
                modeName = 'Targeted';
            else
                tableData = app.UntargetedResultsTable_2.Data;
                classifications = app.predictedFeatures;
                modeName = 'Untargeted';
            end

            % 2. Safety Check
            if isempty(tableData)
                uialert(app.UIFigure, ['No ' modeName ' data to export.'], 'Export Error');
                return;
            end

            % 3. Filter Logic (Old Theme Style)
            % We filter the table directly based on the 'Classification' column
            if ~strcmpi(filterType, "All")
                % Convert to string to ensure comparison works regardless of categorical status
                matchIdx = strcmpi(string(tableData.Classification), filterType);
                tableData = tableData(matchIdx, :);
            end

            if isempty(tableData)
                uialert(app.UIFigure, ['No features match the filter: ', char(filterType)], 'Export Warning');
                return;
            end

            % 4. Export to File
            [file, path] = uiputfile({'*.xlsx', 'Excel Files (*.xlsx)'; '*.csv', 'CSV Files (*.csv)'}, ...
                ['Save ' modeName ' Results']);

            if ~isequal(file, 0)
                try
                    writetable(tableData, fullfile(path, file));
                    appendStatusMessage(app, ['Successfully exported ' modeName ' (', char(filterType), ') results.']);
                catch ME
                    uialert(app.UIFigure, ['Export failed: ', ME.message], 'Export Error');
                end
            end
        end

        function tableCellSelectionDL(app, src, event, table_type)
            if ~isempty(event.Indices)
                row = event.Indices(1);

                % Set column based on UI structure
                if strcmpi(table_type, 'targeted')
                    colIdx = 3; % [Classification, Analyte, mz, ...]
                else
                    colIdx = 2; % [Classification, mz, ...]
                end

                % Robust Extraction logic
                try
                    selectedMz = src.Data{row, colIdx};

                    % Ensure it's a numeric double (not a cell or string)
                    if iscell(selectedMz); selectedMz = selectedMz{1}; end
                    selectedMz = double(selectedMz);

                    % Trigger Plotting
                    app.plotEICForSelectedMzDL(selectedMz, table_type);

                catch ME
                    fprintf('Error extracting m/z at Row %d, Col %d: %s\n', row, colIdx, ME.message);
                end
            end
        end
        function highlightTableRowDL(app, target_mz, source_table)
            % 1. Identify which UI table to highlight
            if strcmpi(source_table, 'untargeted')
                targetTableUI = app.UntargetedResultsTable_2;
            else
                % Ensure this matches your Targeted Table name
                targetTableUI = app.TargetedResultsTable_2_Table;
            end

            % 2. Clear previous styles
            removeStyle(app.UntargetedResultsTable_2);
            removeStyle(app.TargetedResultsTable_2_Table);

            % 3. Get m/z values from the table (Classification is Col 1, m/z is Col 2)
            if isempty(targetTableUI.Data), return; end

            if istable(targetTableUI.Data)
                % Using {:,2} because Classification was prepended as Col 1
                mz_col = targetTableUI.Data{:, 2};
            else
                mz_col = cell2mat(targetTableUI.Data(:, 2));
            end

            % 4. Find row index and apply style
            [~, rowIdx] = min(abs(mz_col - target_mz));
            addStyle(targetTableUI, app.highlightStyleDL, 'row', rowIdx);

            % Scroll to that row so the user sees it
            scroll(targetTableUI, 'row', rowIdx);
        end

        function plotEICForSelectedMzDL(app, target_mz, source_table)
            % 1. Ensure target_mz is a numeric double
            if iscell(target_mz)
                target_mz = target_mz{1};
            end
            target_mz = double(target_mz);

            % 2. Select the correct EIC data source based on the mode
            if strcmpi(source_table, 'targeted')
                eic_data = app.targetedTableEIC;
            else
                eic_data = app.untargetedTableEIC;
            end

            if isempty(eic_data)
                uialert(app.UIFigure, ['No ' source_table ' EIC data matrix found.'], 'Data Error');
                return;
            end

            % 3. Extract m/z list safely
            if istable(eic_data)
                mz_list = eic_data{:, 1};
            elseif iscell(eic_data)
                mz_list = cell2mat(eic_data(:, 1));
            else
                mz_list = eic_data(:, 1);
            end

            % 4. Find the match using PPM tolerance
            ppm_diffs = (abs(mz_list - target_mz) ./ target_mz) * 1e6;
            [min_ppm, idx] = min(ppm_diffs);
            effective_tolerance = max(app.ppmSearch.Value, 10);

            if min_ppm > effective_tolerance
                uialert(app.UIFigure, ...
                    sprintf('No EIC match found.\nClicked m/z: %.4f\nClosest in data: %.4f\nDiff: %.1f ppm', ...
                    target_mz, mz_list(idx), min_ppm), 'Lookup Error');
                return;
            end

            % 5. Extract intensities safely
            if istable(eic_data)
                intensities = table2array(eic_data(idx, 2:end));
            elseif iscell(eic_data)
                intensities = cell2mat(eic_data(idx, 2:end));
            else
                intensities = eic_data(idx, 2:end);
            end

            % 6. Update the UI Axes
            cla(app.EICAxes_2);

            % Plot the data
            plot(app.EICAxes_2, intensities, 'LineWidth', 1.5, 'Color', [0 0.447 0.741]);

            % --- DYNAMIC Y-AXIS SCALING ---
            % Calculate the max intensity for THIS specific species
            current_max = max(intensities);

            if current_max > 0
                % Set y-limit from 0 to 110% of the highest peak for this m/z
                ylim(app.EICAxes_2, [0, current_max * 1.1]);
            else
                % Fallback if data is all zeros to prevent axis errors
                ylim(app.EICAxes_2, [0, 1]);
            end
            % ------------------------------

            % Add styling and labels
            title(app.EICAxes_2, sprintf('%s Mode | m/z: %.4f', ...
                upper(source_table), target_mz));
            xlabel(app.EICAxes_2, 'Scan Number');
            ylabel(app.EICAxes_2, 'Intensity');
            grid(app.EICAxes_2, 'on');

            % 7. Synchronize the Table Highlighting
            highlightTableRowDL(app, target_mz, source_table);
        end

        function storeSelectedRow(app, src, event)
            if ~isempty(event.Indices)
                src.Selection = event.Indices(1); % Store the selected row
            end
        end

        function updateClassification(app, newLabel)
            % 1. Identify active tab
            isTargeted = (app.DLClassificationTabGroup.SelectedTab == app.TargetedTab_2);
            if isTargeted
                targetTable = app.TargetedResultsTable_2;
                featureProperty = 'predictedFeaturesTargeted';
            else
                targetTable = app.UntargetedResultsTable_2;
                featureProperty = 'predictedFeatures';
            end

            % 2. Get Selection
            fullSelection = targetTable.Selection;
            if isempty(fullSelection), return; end
            rowIdx = fullSelection(1);

            % 3. Update the Data Storage (Cell Array)
            if ~isempty(app.(featureProperty))
                app.(featureProperty){rowIdx, 2} = newLabel;
            end

            % 4. Update the Table Display (UI Table)
            tableData = targetTable.Data;

            if iscategorical(tableData.Classification)
                % --- FIX FOR ORDINAL ERROR ---
                % Instead of 'categorical({newLabel})', we convert the string
                % to match the existing categorical scale of the column.
                existingCategories = categories(tableData.Classification);
                tableData.Classification(rowIdx) = categorical({newLabel}, existingCategories, 'Ordinal', isordinal(tableData.Classification));
            else
                tableData.Classification(rowIdx) = {newLabel};
            end

            % Push back to table
            targetTable.Data = tableData;

            % 5. Refresh Gallery
            updateEICPlotGallery(app, app.FilterDropDown.Value);
        end

        function createContextMenu(app)
            % 1. Create the context menu container
            contextMenu = uicontextmenu(app.UIFigure);

            % 2. Create menu items using the EXACT strings the counters expect
            uimenu(contextMenu, 'Text', 'High S/N', ...
                'MenuSelectedFcn', @(src,event) app.updateClassification('High S/N'));
            uimenu(contextMenu, 'Text', 'Low S/N', ...
                'MenuSelectedFcn', @(src,event) app.updateClassification('Low S/N'));
            uimenu(contextMenu, 'Text', 'Background', ...
                'MenuSelectedFcn', @(src,event) app.updateClassification('Background'));

            % 3. Attach to BOTH tables (This was the missing link for Targeted)
            app.UntargetedResultsTable_2.ContextMenu = contextMenu;
            app.TargetedResultsTable_2.ContextMenu = contextMenu;
        end

        function updateEICPlotGallery(app, selectedFilter)
            % 1. Determine active mode based on Tab Title
            isTargeted = strcmp(app.DLClassificationTabGroup.SelectedTab.Title, 'Targeted');

            % 2. Select the correct Data Sources
            if isTargeted
                % Use targeted classification and EIC data
                preds = app.predictedFeaturesTargeted;
                eic_source = app.targetedTableEIC;
            else
                % Use untargeted classification and EIC data
                preds = app.predictedFeatures;
                eic_source = app.untargetedTableEIC;
            end

            % Safety: No data yet or classification hasn't run
            if isempty(preds) || size(preds, 2) < 2
                delete(app.ScrollablePanel.Children);
                uilabel(app.ScrollablePanel, 'Text', 'No EICs classified for this mode yet.', ...
                    'Position', [10 10 250 22], 'FontWeight', 'bold');
                return;
            end

            % 3. Filtering Logic
            labels = string(preds(:, 2)); % Classification column
            if strcmp(selectedFilter, "All")
                filteredIdx = 1:numel(labels);
            else
                filteredIdx = find(labels == string(selectedFilter));
            end

            app.FilteredEICIndices = filteredIdx;
            if isempty(filteredIdx)
                delete(app.ScrollablePanel.Children);
                uilabel(app.ScrollablePanel, 'Text', sprintf('No results for "%s"', selectedFilter), ...
                    'Position', [10 10 250 22]);
                return;
            end

            % 4. Paging
            totalPages = ceil(numel(filteredIdx) / app.EICPerPage);
            app.CurrentEICPage = max(1, min(app.CurrentEICPage, totalPages));
            startIdx = (app.CurrentEICPage - 1) * app.EICPerPage + 1;
            endIdx = min(startIdx + app.EICPerPage - 1, numel(filteredIdx));
            pageIdx = filteredIdx(startIdx:endIdx);

            % --- 5. Redraw Gallery ---
            delete(app.ScrollablePanel.Children);

            % Get dimensions of the container
            panelWidth = app.ScrollablePanel.Position(3);
            panelHeight = app.ScrollablePanel.Position(4);

            % MATH FOR 8 PLOTS: 4 rows of 2 columns
            plotWidth = (panelWidth - 25) / 2;
            plotHeight = (panelHeight - 20) / 4; % Divide height by 4 rows

            % Keep the inner panel the same size as the container to hide scrollbars
            numRows = ceil(numel(pageIdx) / 2);
            totalHeight = max(panelHeight, numRows * plotHeight);

            innerPanel = uipanel(app.ScrollablePanel, ...
                'Position', [0 0 panelWidth totalHeight], ...
                'BackgroundColor', 'white', ...
                'BorderType', 'none');

            for k = 1:numel(pageIdx)
                idx = pageIdx(k);
                row = floor((k-1)/2);
                col = mod(k-1, 2);

                ax = uiaxes(innerPanel);

                % Tight positioning to maximize plot area
                % We subtract 5-10 pixels from height to ensure titles don't touch the plot above
                ax.Position = [col*(plotWidth+5)+10, totalHeight-(row+1)*plotHeight, plotWidth, plotHeight-15];

                % Data Plotting
                current_data = eic_source(idx, :);
                if istable(current_data); current_data = table2array(current_data); end
                plot(ax, current_data(2:end), 'LineWidth', 1.3, 'Color', [0 0.447 0.741]);

                % --- FONT SIZE CALIBRATION ---
                % With 4 rows, we can safely use a 12pt font
                ax.Title.String = sprintf('m/z: %.4f | %s', current_data(1), labels(idx));
                ax.Title.FontSize = 20;
                ax.Title.FontWeight = 'bold';

                % Formatting
                ax.XTick = [];
                ax.YTick = [];
                ax.Box = 'on';
                ax.FontSize = 10; % Axis label font size
            end
        end

        function changeEICPage(app, direction)
            app.CurrentEICPage = app.CurrentEICPage + direction;
            updateEICPlotGallery(app, app.CurrentEICFilter);
        end

        function changePage(app, filterClass, direction)
            app.GalleryPage = max(app.GalleryPage + direction, 1);
            updateEICPlotGallery(app, filterClass);
        end

        function tableCellSelection(app, src, event, tableCategory)
            if isempty(event.Indices), return; end
            selectedRow = event.Indices(1);

            % Get the correct table data
            if strcmp(tableCategory, 'untargeted')
                currentTable = app.UntargetedResultsTable.Data;
            else
                currentTable = app.AnnotatedResultsTable.Data;
            end

            try
                % Find the column named 'mz' regardless of its position
                colNames = currentTable.Properties.VariableNames;
                mzColIdx = find(strcmpi(colNames, 'mz'), 1);

                if isempty(mzColIdx)
                    % Fallback if header not found: Untargeted=1, Targeted=2
                    if strcmp(tableCategory, 'untargeted'), mzColIdx = 1; else, mzColIdx = 2; end
                end

                % Extract m/z safely
                rawMz = currentTable{selectedRow, mzColIdx};
                if iscell(rawMz), valMz = rawMz{1}; else, valMz = rawMz; end

                % Check if the value is numeric
                if isnumeric(valMz) && ~isnan(valMz)
                    app.plotEICForSelectedMz(valMz, tableCategory);
                else
                    fprintf('Selected value is not a valid number.\n');
                end
            catch ME
                fprintf('Error in selection: %s\n', ME.message);
            end
        end

        function uploadEICData(app, event)
            % Open file selection dialog
            [file, path] = uigetfile({'*.csv;*.xlsx;*.xls', 'Table Files (*.csv, *.xlsx, *.xls)'}, ...
                'Select EIC Data File');

            % If user cancels
            if isequal(file, 0)
                appendStatusMessage(app, 'EIC data upload cancelled.');
                return;
            end

            fullFileName = fullfile(path, file);

            try
                % Read the file
                [~, ~, ext] = fileparts(fullFileName);
                if strcmpi(ext, '.csv')
                    data = readtable(fullFileName);
                elseif any(strcmpi(ext, {'.xlsx', '.xls'}))
                    data = readtable(fullFileName, 'Sheet', 1);
                else
                    appendStatusMessage(app, 'Error: Unsupported file format.');
                    return;
                end

                % Store based on currently selected tab
                if app.TabGroup.SelectedTab == app.TargetedTab
                    app.targetedTableEIC = data;
                    eic_type = 'Targeted';
                else
                    app.untargetedTableEIC = data;
                    eic_type = 'Untargeted';
                end

                appendStatusMessage(app, [eic_type ' EIC data successfully loaded from: ' fullFileName]);
            catch ME
                appendStatusMessage(app, ['Error loading EIC data: ' ME.message]);
            end
        end

        function exportEICData(app, event)
            % Determine which EIC data to export based on visible tab and data availability
            if app.TabGroup2.SelectedTab == app.AnnotatedTab
                % Check if targeted EIC data exists and is valid
                if ~isempty(app.targetedTableEIC) && isprop(app, 'targetedTableEIC')
                    eic_data = app.targetedTableEIC;
                    eic_type = 'Targeted';

                    % Get annotation names if available
                    if ~isempty(app.targetedTable) && isprop(app, 'targetedTable') && ...
                            ismember('Analyte', app.targetedTable.Properties.VariableNames)
                        % Match m/z values between EIC data and annotated table
                        mz_values = eic_data(:,1);
                        annotated_mz = app.targetedTable.mz;
                        names = app.targetedTable.Analyte;

                        % Create a cell array for the names (initialize with empty strings)
                        name_column = repmat({''}, size(mz_values));

                        % Match m/z values and assign names
                        for i = 1:length(mz_values)
                            [min_diff, idx] = min(abs(annotated_mz - mz_values(i)));
                            ppm_diff = (min_diff / mz_values(i)) * 1e6;
                            if ppm_diff < app.ppmSearch.Value
                                name_column{i} = names{idx};
                            end
                        end

                        % Add names as first column
                        eic_data = [name_column, num2cell(eic_data)];
                    end
                else
                    % Fall back to untargeted if targeted isn't available
                    if ~isempty(app.untargetedTableEIC)
                        eic_data = app.untargetedTableEIC;
                        eic_type = 'Untargeted';
                        appendStatusMessage(app, 'Using untargeted EIC data (targeted not available)');
                    else
                        appendStatusMessage(app, 'No EIC data available to export.');
                        return;
                    end
                end
            else
                % Default to untargeted EIC data
                if ~isempty(app.untargetedTableEIC)
                    eic_data = app.untargetedTableEIC;
                    eic_type = 'Untargeted';
                else
                    appendStatusMessage(app, 'No untargeted EIC data available to export.');
                    return;
                end
            end

            % Ask the user to select a file name and path
            [file, path] = uiputfile({'*.csv', 'CSV Files (*.csv)'; '*.xlsx', 'Excel Files (*.xlsx)'}, ...
                ['Save ' eic_type ' EIC Data'], [lower(eic_type) '_eic_data']);

            % If user cancels
            if isequal(file, 0)
                appendStatusMessage(app, 'Export cancelled.');
                return;
            end

            % Full file path
            fullFileName = fullfile(path, file);

            % Export the data
            [~, ~, ext] = fileparts(fullFileName);
            try
                if strcmp(ext, '.csv')
                    if iscell(eic_data)
                        % For cell array (when we added names)
                        writecell(eic_data, fullFileName);
                    else
                        % For numeric matrix
                        writematrix(eic_data, fullFileName);
                    end
                elseif strcmp(ext, '.xlsx')
                    if iscell(eic_data)
                        writecell(eic_data, fullFileName, 'Sheet', 1);
                    else
                        writematrix(eic_data, fullFileName, 'Sheet', 1);
                    end
                else
                    appendStatusMessage(app, 'Error: Unsupported file format.');
                    return;
                end
                appendStatusMessage(app, [eic_type ' EIC data exported successfully to ', fullFileName]);
            catch ME
                appendStatusMessage(app, ['Error during EIC export: ', ME.message]);
            end
        end

        function uploadUntargetedTable(app, event)
            % Open file selection dialog
            [file, path] = uigetfile({'*.csv;*.xlsx;*.xls', 'Table Files (*.csv, *.xlsx, *.xls)'}, ...
                'Select Untargeted Table File');

            % If user cancels selection
            if isequal(file, 0)
                appendStatusMessage(app, 'Untargeted table upload cancelled.');
                return;
            end

            % Full file path
            fullFileName = fullfile(path, file);

            try
                % Determine file type and read data
                [~, ~, ext] = fileparts(fullFileName);
                if strcmpi(ext, '.csv')
                    data = readtable(fullFileName);
                elseif any(strcmpi(ext, {'.xlsx', '.xls'}))
                    data = readtable(fullFileName, 'Sheet', 1);
                else
                    appendStatusMessage(app, 'Error: Unsupported file format.');
                    return;
                end

                % Store the data in the app
                app.untargetedTable = data;

                % Update the UI table if it exists
                if isprop(app, 'UntargetedTable') % Check if the UI table component exists
                    app.UntargetedTable.Data = data;
                    if isa(data, 'table')
                        app.UntargetedTable.ColumnName = data.Properties.VariableNames;
                    end
                end

                appendStatusMessage(app, ['Untargeted table successfully loaded from: ' fullFileName]);
            catch ME
                appendStatusMessage(app, ['Error loading untargeted table: ' ME.message]);
            end
        end

        function exportTableDataTab2(app, event)
            % Check which table is currently selected in the tab group
            currentTab = app.TabGroup2.SelectedTab;

            % Get the appropriate table data based on the selected tab
            if currentTab == app.UntargetedTab
                table_data = app.UntargetedResultsTable.Data;
                table_name = 'Untargeted';
            elseif currentTab == app.AnnotatedTab
                table_data = app.AnnotatedResultsTable.Data;
                table_name = 'Targeted';
            else
                appendStatusMessage(app, 'No table data available to export.');
                return;
            end

            % Check if there's actually data in the selected table
            if isempty(table_data)
                appendStatusMessage(app, 'No data in the selected table to export.');
                return;
            end

            % Ask the user to select a file name and path
            [file, path] = uiputfile({'*.csv', 'CSV Files (*.csv)'; '*.xlsx', 'Excel Files (*.xlsx)'}, ...
                ['Save ' table_name ' Table Data'], [lower(table_name) '_data']);

            % If the user cancels, exit the function
            if isequal(file, 0)
                appendStatusMessage(app, 'Export cancelled.');
                return;
            end

            % Full file path
            fullFileName = fullfile(path, file);

            % Export the table as CSV or Excel
            [~, ~, ext] = fileparts(fullFileName);
            try
                if strcmp(ext, '.csv')
                    writetable(table_data, fullFileName);
                elseif strcmp(ext, '.xlsx')
                    writetable(table_data, fullFileName, 'Sheet', 1);
                else
                    appendStatusMessage(app, 'Error: Unsupported file format.');
                    return;
                end
                appendStatusMessage(app, [table_name ' table exported successfully to ', fullFileName]);
            catch ME
                appendStatusMessage(app, ['Error during export: ', ME.message]);
            end
        end

        function importDLNetwork(app)
            % --- 1. Identify the App Directory ---
            currentFilePath = mfilename('fullpath');
            if isempty(currentFilePath)
                currentFilePath = which(mfilename);
            end
            currentDir = fileparts(currentFilePath);
            modelFile = fullfile(currentDir, 'trainedNetwork_1.mat');

            % --- 2. Attempt Auto-load (Quietly) ---
            wasAutoLoaded = false;
            if isfile(modelFile) && isempty(app.trainedNetwork_1)
                try
                    S = load(modelFile);
                    fields = fieldnames(S);
                    if ~isempty(fields)
                        app.trainedNetwork_1 = S.(fields{1});
                        appendStatusMessage(app, "Auto-loaded default model: " + modelFile);
                        wasAutoLoaded = true;
                    end
                catch
                    % If auto-load fails, we just proceed to manual selection
                    app.trainedNetwork_1 = [];
                end
            end

            % --- 3. Manual Selection / Override ---
            % Ask the user if they want to manually upload a network
            if wasAutoLoaded
                % If it was already loaded, ask if they want to CHANGE it
                selection = questdlg('Default model auto-loaded. Would you like to upload a different network?', ...
                    'Model Selection', 'Use Default', 'Upload New', 'Use Default');
                doManualLoad = strcmp(selection, 'Upload New');
            else
                % If nothing is loaded yet, ask to select one
                selection = questdlg('No model loaded. Select model file manually?', ...
                    'Model Selection', 'Yes', 'Cancel', 'Yes');
                doManualLoad = strcmp(selection, 'Yes');
            end

            if doManualLoad
                [file, path] = uigetfile({'*.mat;*.onnx;*.h5', 'Model Files (*.mat, *.onnx, *.h5)'}, ...
                    'Select Trained Model', currentDir);

                if file ~= 0
                    modelFullFile = fullfile(path, file);
                    try
                        if endsWith(file, '.mat')
                            S = load(modelFullFile);
                            fields = fieldnames(S);
                            app.trainedNetwork_1 = S.(fields{1});
                        elseif endsWith(file, '.onnx')
                            app.trainedNetwork_1 = importONNXNetwork(modelFullFile);
                        elseif endsWith(file, '.h5')
                            app.trainedNetwork_1 = importKerasNetwork(modelFullFile);
                        end
                        appendStatusMessage(app, sprintf("Successfully uploaded model:\n%s", file));
                    catch ME
                        uialert(app.UIFigure, ME.message, 'Model Load Error');
                        appendStatusMessage(app, "Manual load failed: " + ME.message);
                        return;
                    end
                else
                    if isempty(app.trainedNetwork_1)
                        appendStatusMessage(app, "No model loaded.");
                        return;
                    end
                end
            end

            % --- 4. Final Verification & Plot Update ---
            if ~isempty(app.trainedNetwork_1)
                % Only update if data exists to avoid the "Index exceeds array bounds" error
                if ~isempty(app.extractedEvents)
                    updateEICPlotGallery(app, 'High S/N');
                end
            end
        end

        function classifyEIC(app)
            % 1. Check if Network is loaded
            if isempty(app.trainedNetwork_1) || ...
                    (~isa(app.trainedNetwork_1, 'SeriesNetwork') && ~isa(app.trainedNetwork_1, 'DAGNetwork'))
                appendStatusMessage(app, 'Trained network not properly loaded');
                return
            end

            app.setLampRunning();

            % 2. Identify Active Tab and Data Source
            isTargeted = strcmp(app.DLClassificationTabGroup.SelectedTab.Title, 'Targeted');

            if isTargeted
                data = app.targetedTableEIC;
                sourceName = 'Targeted';
            else
                data = app.untargetedTableEIC;
                sourceName = 'Untargeted';
            end

            if isempty(data)
                appendStatusMessage(app, ['Warning: No ', sourceName, ' data found to classify']);
                return;
            end

            if ~isnumeric(data)
                data = table2array(data);
            end

            % 3. Progress Dialog Setup
            d = uiprogressdlg(app.UIFigure, ...
                'Title', ['Classifying ', sourceName, ' EIC'], ...
                'Message', 'Processing features...', ...
                'Value', 0);

            % 4. Image Processing Loop
            tempImages = cell(1, size(data, 1));
            inputSize = [224 224 3];

            for i = 1:size(data, 1)
                fig = figure('Visible', 'off');
                plot(data(i, 2:end));
                set(gca, 'XTick', [], 'YTick', [], 'Box', 'off', 'XLabel', [], 'YLabel', [], 'Title', []);

                frame = getframe(fig);
                tempImages{i} = imresize(frame2im(frame), inputSize(1:2));
                close(fig);

                d.Value = (i / size(data, 1)) * 0.8; % Reserve last 20% for classification
                d.Message = sprintf('Processing %s feature %d of %d...', sourceName, i, size(data, 1));
                drawnow;
            end

            % 5. Classification
            imageData = zeros([inputSize, length(tempImages)], 'uint8');
            for i = 1:length(tempImages)
                imageData(:,:,:,i) = tempImages{i};
            end

            d.Message = 'Running Neural Network...';
            d.Value = 0.9;
            drawnow;

            predictedLabels = classify(app.trainedNetwork_1, imageData);
            close(d);

            % 6. Formatting Results
            mz = data(:,1);
            labels = string(predictedLabels);
            
            % Map network shorthand to readable labels
            labels(labels == "SNHigh") = "High S/N";
            labels(labels == "SNLow")  = "Low S/N";
            labels(labels == "BG")     = "Background";

            % 7. Save results to property
            resultsCell = [num2cell(mz), cellstr(labels)];
            if isTargeted
                app.predictedFeaturesTargeted = resultsCell;
            else
                app.predictedFeatures = resultsCell;
            end

       % 8. Statistics (Old Theme) - Robust Version
            totalFeatures = size(resultsCell, 1);
            
            % Extract labels and clean them (convert to string array and trim spaces)
            extractedLabels = strtrim(string(resultsCell(:,2)));
            
            % Use sum on the cleaned string array
            numHighSN = sum(extractedLabels == "High S/N");
            numLowSN  = sum(extractedLabels == "Low S/N");
            numBG     = sum(extractedLabels == "Background");

            if totalFeatures > 0
                % Calculate percentages
                percHigh = round(100 * numHighSN / totalFeatures, 1);
                percLow  = round(100 * numLowSN / totalFeatures, 1);
                percBG   = round(100 * numBG / totalFeatures, 1);

                % Log using your global helper (updates all status windows)
                appendStatusMessage(app, sprintf('--- %s Classification Results ---', upper(sourceName)));
                appendStatusMessage(app, sprintf('Total features: %d', totalFeatures));
                appendStatusMessage(app, sprintf('High S/N: %d (%.1f%%)', numHighSN, percHigh));
                appendStatusMessage(app, sprintf('Low S/N: %d (%.1f%%)', numLowSN, percLow));
                appendStatusMessage(app, sprintf('Background: %d (%.1f%%)', numBG, percBG));
                appendStatusMessage(app, '---------------------------------');
            end

            % 9. Update the UI Table with Specific Column Ordering
            if isTargeted
                tableObj = app.TargetedResultsTable_2;
                rawTable = app.targetedTable;

                if ~isempty(rawTable)
                    % 1. Create Classification column
                    validClasses = ["High S/N", "Low S/N", "Background"];
                    classCol = categorical(labels, validClasses, 'Ordinal', true);

                    % 2. Extract Data from rawTable
                    % Assuming Col 1 = Annotation, Col 2 = m/z
                    Annotation = rawTable{:, 1};
                    mz_val = rawTable{:, 2};

                    % 3. Construct Table with specific names
                    % This names the first 3 columns correctly
                    tableData = table(classCol, Annotation, mz_val, ...
                        'VariableNames', {'Classification', 'Annotation', 'mz'});

                    % 4. Append the Single Cell Window intensity columns
                    intensityData = rawTable(:, 3:end);
                    tableData = [tableData, intensityData];
                else
                    tableData = table(labels, mz, 'VariableNames', {'Classification', 'mz'});
                end
            else
                % UNTARGETED MODE
                tableObj = app.UntargetedResultsTable_2;
                rawTable = app.untargetedTable;

                classCol = categorical(labels, ["High S/N", "Low S/N", "Background"], 'Ordinal', true);
                tableData = table(classCol, 'VariableNames', {'Classification'});

                if ~isempty(rawTable)
                    % Append untargeted data (usually starts with m/z)
                    tableData = [tableData, rawTable];
                end
            end

            % --- REFRESH UI TABLE NAMES ---
            tableObj.Data = tableData;

            % Update the UI headers to match the table variable names
            if isa(tableData, 'table')
                tableObj.ColumnName = tableData.Properties.VariableNames;
            end
            
            tableObj.ColumnSortable = true;
            
            % 10. Trigger Gallery Update
            app.FilterDropDown.Value = "High S/N";
            updateEICPlotGallery(app, "High S/N");
        end

        % Function to find analytes from external/internal list
        function [analytes_found] = keep_significant_analytes_untargeted_annotated(app, final_table, analytes_list, position_of_mz, ppmgap)

            if ~istable(final_table)
                final_table = table(final_table);
            else
                if ischar(final_table{1,1}(1,1)) == 1 % annotated tables
                    var_names = final_table.Properties.VariableNames;
                    var_names_1 = var_names(3:end);

                    diff = (abs(analytes_list{:,position_of_mz}-final_table.("mz")'))./final_table.("mz")'*1e6<ppmgap;
                    sum_of_isomers = sum(diff,2); % isomer accounting

                    annotated_mz = diff*final_table.("mz")./sum_of_isomers;
                    annotated_mz(isnan(annotated_mz))=0;

                    ints = diff*final_table{:,3:end}./sum_of_isomers;
                    ints(isnan(ints))=0;

                    analytes_found = table(annotated_mz, ints, VariableNames=["mz", "Int"]);
                    analytes_found = splitvars(analytes_found,"Int", "NewVariableNames", var_names_1);

                    mask = all(analytes_found{:,2:end}==0,2);
                    analytes_found(mask,:) = [];

                else % non-annotated tables/doubles
                    var_names = final_table.Properties.VariableNames;
                    var_names_1 = var_names(2:end);

                    try
                        diff = (abs(analytes_list{:,position_of_mz}-final_table.("mz")'))./final_table.("mz")'*1e6<ppmgap;
                    catch
                        diff = (abs(analytes_list(:,position_of_mz)-final_table{:,1}'))./final_table{:,1}'*1e6<ppmgap;
                    end
                    sum_of_isomers = sum(diff,2); % isomer accounting

                    annotated_mz = diff*final_table{:,1}./sum_of_isomers;
                    annotated_mz(isnan(annotated_mz))=0;

                    ints = diff*final_table{:,2:end}./sum_of_isomers;
                    ints(isnan(ints))=0;

                    analytes_found = table(annotated_mz, ints, VariableNames=["mz", "Int"]);
                    analytes_found = splitvars(analytes_found,"Int", "NewVariableNames", var_names_1);

                    mask = all(analytes_found{:,2:end}==0,2);
                    analytes_found(mask,:) = [];
                end
            end
        end

        function[filtered_features] = exclude_C13_isotopes(app, features, position_of_mz, C13, ppm_C13)

            features.Sum = sum(features{:, position_of_mz+1:end},2);

            col1 = features{:, position_of_mz};
            col2 = features.Sum;

            filtered_features = features;
            % Initialize Isotope column with zeros
            filtered_features.Isotope = zeros(size(features, 1), 1);

            % Use vectorized operations
            for k = 1:size(features, 1)
                indices = (k+1):size(features, 1);

                % Check conditions using vectorized operations
                condition = ((abs(col1(indices) - col1(indices - k) - C13)./col1(indices)*1E6) < ppm_C13) & (col2(indices) < col2(indices - k));
                % Update Isotope column based on the conditions
                filtered_features.Isotope(indices(condition)) = 1;
            end

            % delete isotopes
            filtered_features(filtered_features.Isotope == 1,:) = [];

            filtered_features.Isotope = [];
            filtered_features.Sum = [];

            % remove empty rows from table
            mask = all(filtered_features{:,1}==0,2);
            filtered_features(mask,:) = [];
        end
        function generateProcessingReport(app)
    % 1. Selection of Save Location
    [file, path] = uiputfile('*.txt', 'Save Processing Report', 'MS_Full_Audit_Log.txt');
    if isequal(file, 0), return; end
    
    fileID = fopen(fullfile(path, file), 'w');
    
    % --- Header ---
    fprintf(fileID, '====================================================\n');
    fprintf(fileID, 'MS DATA PROCESSING FULL AUDIT TRAIL\n');
    fprintf(fileID, 'Report Generated: %s\n', datestr(now));
    fprintf(fileID, '====================================================\n\n');

    % --- 1. Source Data Information ---
    fprintf(fileID, '[SOURCE DATA]\n');
    if isprop(app, 'sourceFiles') && ~isempty(app.sourceFiles)
        fprintf(fileID, 'Data Folder: %s\n', app.sourceFiles(1).folder);
        fprintf(fileID, 'File Type: .%s\n', app.fileType);
        fprintf(fileID, 'Files Processed:\n');
        for k = 1:length(app.sourceFiles)
            startStr = 'N/A';
            if isprop(app, 'msStartTimes') && k <= length(app.msStartTimes)
                startStr = datestr(app.msStartTimes(k), 'yyyy-mm-dd HH:MM:SS');
            end
            fprintf(fileID, '  - %s (Acquisition Start: %s)\n', app.sourceFiles(k).name, startStr);
        end
    end
    fprintf(fileID, '\n');

    % --- 2. Event Timing & Log Sync ---
    fprintf(fileID, '[EVENT TIMING & EXTERNAL LOGS]\n');
    % Check if log synchronization was used (assuming a checkbox or logic exists)
    if isprop(app, 'LogSyncCheckBox') && app.LogSyncCheckBox.Value
        fprintf(fileID, 'Log File Synchronization: ENABLED\n');
    else
        fprintf(fileID, 'Log File Synchronization: DISABLED (Manual/Internal detection)\n');
    end

    if isprop(app, 'times_sc') && ~isempty(app.times_sc)
        fprintf(fileID, 'Total Events Detected: %d\n', size(app.times_sc, 1));
        fprintf(fileID, '----------------------------------------------------\n');
        fprintf(fileID, '%-10s | %-12s | %-12s | %-10s\n', 'Event #', 'Start (min)', 'End (min)', 'Width (s)');
        fprintf(fileID, '----------------------------------------------------\n');
        for i = 1:size(app.times_sc, 1)
            duration_sec = (app.times_sc(i,2) - app.times_sc(i,1)) * 60;
            fprintf(fileID, '%-10d | %-12.3f | %-12.3f | %-10.1f\n', ...
                i, app.times_sc(i,1), app.times_sc(i,2), duration_sec);
        end
    end
    fprintf(fileID, '\n');

    % --- 3. Background Settings ---
    fprintf(fileID, '[BACKGROUND SUBTRACTION]\n');
    if isprop(app, 'spectra_comb') && ~isempty(app.spectra_comb)
        fprintf(fileID, 'Background Scans per Window: %d\n', app.spectra_comb{1, 5});
    end
    fprintf(fileID, 'Fold Change Threshold (SC/BG): %0.2f\n', app.FoldchangeEditField.Value);
    fprintf(fileID, '\n');

    % --- 4. Alignment & Feature Processing ---
    fprintf(fileID, '[ALIGNMENT & FEATURE DETECTION]\n');
    fprintf(fileID, 'PPM Alignment Gap: %0.2f\n', app.ppmAlignmentEditField.Value);
    fprintf(fileID, 'Min Scans per Feature: %d\n', app.MinScansSCEditField.Value);
    fprintf(fileID, 'Detection Frequency Threshold: %0.1f%%\n', app.DetectionFreqEditField.Value);
    fprintf(fileID, 'Intensity Calculation Mode: %s\n', app.IntensitiesDropDown.Value);
    
    % Filters
    fprintf(fileID, 'Isotope Filtering: %s (PPM: %0.1f, Mass: %0.4f)\n', ...
        app.checkToOnOff(app.IsotopeFilteringCheckBox.Value), app.ppmIsotopeFiltering.Value, app.IsotopeExactMassEditField.Value);
    fprintf(fileID, 'Mass Defect Filtering: %s\n', app.checkToOnOff(app.MassDefectFilteringCheckBox.Value));
    fprintf(fileID, '\n');

    % --- 5. Quantification & Normalization ---
    fprintf(fileID, '[QUANTIFICATION & NORMALIZATION]\n');
    fprintf(fileID, 'TIC Normalization: %s\n', app.checkToOnOff(app.TICNormalizationCheckBox.Value));
    fprintf(fileID, 'IS Normalization: %s\n', app.checkToOnOff(app.ISNormalizationCheckBox.Value));
    
    if app.ISNormalizationCheckBox.Value
        fprintf(fileID, 'Carrier Flow Rate: %0.3f uL/min\n', app.FlowrateEditField.Value);
        fprintf(fileID, 'IS Detection PPM: %0.2f\n', app.ppmSearch.Value);
        fprintf(fileID, 'Min Detection Freq (Quant): %0.1f%%\n', app.DetectionFrequncyQuantified.Value);
    end
    fprintf(fileID, '\n');

    % --- 6. Annotation Settings ---
    fprintf(fileID, '[ANNOTATION]\n');
    fprintf(fileID, 'Search Tolerance (PPM): %0.2f\n', app.ppmSearch.Value);
    fprintf(fileID, 'Remove Annotated Duplicates: %s\n', ...
        app.checkToOnOff(app.RemoveDuplicatesCheckBox_2.Value));
    fprintf(fileID, '\n');

    % --- Footer ---
    fprintf(fileID, '====================================================\n');
    fprintf(fileID, 'END OF REPORT\n');
    fprintf(fileID, '====================================================\n');
    
    fclose(fileID);
    uialert(app.UIFigure, 'Detailed Processing Report Generated.', 'Success');
end

% Simple helper inside the app for the report logic
function out = checkToOnOff(~, val)
    if val, out = 'ON'; else, out = 'OFF'; end
end

        function updateTableWithGlobalBG(app)
            % 1. Check if there is data in the table
            if isempty(app.TimeWindowsTable.Data)
                return;
            end

            % 2. Get the current table data (T is likely a Table where columns are Cells)
            T = app.TimeWindowsTable.Data;
            num_rows = height(T);

            if app.UseGlobalBackgroundCheckBox.Value
                % --- Selection Check ---
                if isempty(app.GlobalBGTime) || length(app.GlobalBGTime) < 2
                    app.UseGlobalBackgroundCheckBox.Value = false;
                    uialert(app.UIFigure, 'Please select a Global Background window first.', 'No Global BG');
                    return;
                end

                % GLOBAL MODE: Wrap the doubles in cells to avoid the conversion error
                % Using num2cell(repmat(...)) ensures every row gets the global value correctly
                T.BG_Start = num2cell(repmat(app.GlobalBGTime(1), num_rows, 1));
                T.BG_End = num2cell(repmat(app.GlobalBGTime(2), num_rows, 1));

            else
                % ROLLING MODE: Re-sync with the rolling arrays
                if ~isempty(app.times_bg) && size(app.times_bg, 1) == num_rows
                    % Convert rolling doubles to cells before pushing back to table
                    T.BG_Start = num2cell(app.times_bg(:,1));
                    T.BG_End = num2cell(app.times_bg(:,2));
                end
            end

            % 3. Push back to UI
            app.TimeWindowsTable.Data = T;
        end

        function plotMzTraceWithLogWindows(app)
            % 1. Clear the axes for fresh plot
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');

            % 2. Get TIC data (assuming it's in app.rawData)
            % rawData{1} contains [mz, int, TIC, etc] for each scan
            times = cell2mat(app.rawData{1}(:, 2));
            tic_values = cell2mat(app.rawData{1}(:, 3));

            % Plot the main trace
            plot(app.UIAxes, times, tic_values, 'Color', [0.4 0.4 0.4], 'LineWidth', 1);

            % 3. Overlay the Sync Windows from app.times_sc
            if ~isempty(app.times_sc)
                ylims = app.UIAxes.YLim;
                for i = 1:size(app.times_sc, 1)
                    t_start = app.times_sc(i,1);
                    t_end = app.times_sc(i,2);

                    % Create a semi-transparent red rectangle for each event
                    fill(app.UIAxes, [t_start, t_end, t_end, t_start], ...
                        [0, 0, ylims(2), ylims(2)], 'r', ...
                        'FaceAlpha', 0.2, 'EdgeColor', 'r', 'LineWidth', 1);
                end
            end

            title(app.UIAxes, 'TIC with Log-Synchronized Event Windows');
            xlabel(app.UIAxes, 'Time (min)');
            ylabel(app.UIAxes, 'Intensity');
            hold(app.UIAxes, 'off');
        end

        function plotEICForSelectedMz(app, target_mz, source_table)
            % 1. Data Source Selection
            if strcmp(source_table, 'untargeted')
                if isempty(app.untargetedTableEIC)
                    uialert(app.UIFigure, 'No untargeted EIC data available', 'Error');
                    return;
                end
                eic_data = app.untargetedTableEIC;
                table_results = app.UntargetedResultsTable.Data;
            else
                if isempty(app.targetedTableEIC)
                    uialert(app.UIFigure, 'No targeted EIC data available', 'Error');
                    return;
                end
                eic_data = app.targetedTableEIC;
                table_results = app.AnnotatedResultsTable.Data;
            end

            % 2. Convert Table to Matrix if necessary
            % eic_data must be numeric for the math below to work
            if istable(eic_data)
                eic_matrix = table2array(eic_data);
            else
                eic_matrix = eic_data;
            end

            % 3. Find matching m/z
            mz_values = eic_matrix(:,1);
            ppm_tolerance = app.ppmSearch.Value;
            ppm_diffs = (abs(mz_values - target_mz)./target_mz)*1e6;
            matching_indices = find(ppm_diffs <= ppm_tolerance);

            if isempty(matching_indices)
                % Fallback: find absolute closest if ppm fails (for debugging)
                [~, closest_idx] = min(abs(mz_values - target_mz));
                fprintf('Match failed. Closest m/z is %.4f (diff: %.1f ppm)\n', mz_values(closest_idx), min(ppm_diffs));
                return;
            end

            [~, best_match] = min(ppm_diffs(matching_indices));
            selected_idx = matching_indices(best_match);

            % Extract Intensities
            selected_intensities = eic_matrix(selected_idx, 2:end);
            selected_intensities(isnan(selected_intensities)) = 0;

            % 4. Generate Title Text (Moved up so it's ready for the plot)
            if strcmp(source_table, 'targeted') && ismember('Analyte', table_results.Properties.VariableNames)
                % Match by mz in the results table to get the name
                [~, name_idx] = min(abs(table_results.mz - target_mz));
                name_str = table_results.Analyte{name_idx};
                title_text = sprintf('EIC for %s (m/z %.4f)', name_str, target_mz);
            else
                title_text = sprintf('EIC for m/z %.4f', target_mz);
            end
            % --- INSERT THE DIAGNOSTIC CODE HERE ---
            if max(selected_intensities) == 0 && size(eic_matrix, 2) > 2
                fprintf('Row %d is all zeros in the intensity columns.\n', selected_idx);
                % Check the first column (where m/z lives) to see if data was shifted there
                actual_max_entire_row = max(eic_matrix(selected_idx, :));
                fprintf('Maximum value in the entire row (including col 1): %f\n', actual_max_entire_row);

                % Check a different column range just in case
                if size(eic_matrix, 2) > 5
                    fprintf('Max in columns 3-5: %f\n', max(eic_matrix(selected_idx, 3:5)));
                end
            end
            % --- END OF DIAGNOSTIC CODE ---

            % 5. Plotting
            cla(app.EICAxes);
            plot(app.EICAxes, selected_intensities, 'LineWidth', 2, 'Color', [0 0.447 0.741]);

            % Set limits and grid
            max_val = max(selected_intensities);
            if max_val > 0
                ylim(app.EICAxes, [0 max_val * 1.1]);
            else
                ylim(app.EICAxes, [0 1]);
                title_text = [title_text ' - NO INTENSITY DATA'];
            end

            title(app.EICAxes, title_text);
            xlabel(app.EICAxes, 'Scan Number');
            ylabel(app.EICAxes, 'Intensity');
            grid(app.EICAxes, 'on');

            % 6. Highlight row
            highlightTableRow(app, target_mz, source_table);
        end

        function highlightTableRow(app, target_mz, source_table)
            % Reset previous highlighting in both tables
            removeStyle(app.UntargetedResultsTable);
            removeStyle(app.AnnotatedResultsTable);

            if strcmp(source_table, 'untargeted')
                table = app.UntargetedResultsTable;
                mz_values = table.Data{:,1};
            else
                table = app.AnnotatedResultsTable;
                if istable(table.Data) && ismember('mz', table.Data.Properties.VariableNames)
                    mz_values = table.Data.mz;
                else
                    return;
                end
            end

            % Find the row to highlight
            [~, row] = min(abs(mz_values - target_mz));

            % Apply highlighting with valid RGB color
            highlightStyle = uistyle('BackgroundColor', [0.9 0.9 1]); % Light blue
            addStyle(table, highlightStyle, 'row', row);
        end

        function [temp] = background_filtering_division_baseline_correction_orig(app, single_cells, fold_change_SC_BG, min_hits_of_all_SC_scans, min_scans, IS_list, n_highest_SC_spectra)
            % Setup threshold
            min_hits_thresh = min_hits_of_all_SC_scans / 100;
            temp = cell(size(single_cells, 1), 19);

            for i = 1:size(single_cells, 1)
                spectra_i = single_cells{i, 12}; % Raw input data
                num_bg = single_cells{i, 11};
                num_bg_minus_1 = num_bg - 1;

                % --- Internal Standard (IS) Handling ---
                % We extract IS data here so it remains RAW for the denominator
                if ~isempty(IS_list)
                    diff_is = (abs(IS_list.Var1 - spectra_i(:,1)')./spectra_i(:,1)')*1e6 < app.ppmSearch.Value;
                    sum_of_isomers = sum(diff_is, 2);
                    annotated_mz = diff_is * spectra_i(:,1) ./ sum_of_isomers;
                    annotated_mz(isnan(annotated_mz)) = 0;

                    % IS intensities are pulled from raw spectra_i
                    ints_is = (diff_is * spectra_i(:, [2, 3+num_bg:end]) ./ sum_of_isomers);
                    ints_is(isnan(ints_is)) = 0;
                    spectra_i_inc = [annotated_mz, ints_is];
                    [~, uidx] = unique(spectra_i_inc(:, 1));
                    spectra_i_inc = spectra_i_inc(uidx, :);
                    spectra_i_inc(spectra_i_inc(:, 1) == 0, :) = [];
                    temp{i, 3} = ismissing(spectra_i_inc, 0);
                end

                % --- Background Filtering Preparation ---
                spectra_IS_ex = spectra_i;
                if ~isempty(IS_list)
                    sum_diff = sum(diff_is, 1);
                    idx_is = find(sum_diff);
                    spectra_IS_ex(idx_is, :) = []; % Remove IS from analyte processing
                end

                bg = spectra_IS_ex(:, 3:3+num_bg_minus_1);
                sc = spectra_IS_ex(:, 3+num_bg:end);
                mz = spectra_IS_ex(:, 1);
                freq = spectra_IS_ex(:, 2);

                % Populate Column 18 for Chronogram (Raw data)
                temp{i, 18} = [mz, spectra_IS_ex(:, 3:end)];

                % Background filtering math
                avg_bg = mean(bg, 2);
                avg_sc = mean(maxk(sc, n_highest_SC_spectra, 2), 2);

                temp{i, 1} = bg; temp{i, 2} = sc;
                temp{i, 13} = [mz, freq, avg_bg];

                % Filter by Fold Change
                temp{i, 14} = [mz, freq, avg_sc./avg_bg, spectra_IS_ex(:, 3:end)];
                temp{i, 14} = temp{i, 14}(temp{i, 14}(:, 3) > fold_change_SC_BG, :);
                temp{i, 14}(:, 3) = []; % Remove the ratio column

                % --- Baseline Correction (ANALYTES ONLY) ---
                temp{i, 15} = temp{i, 14};
                if app.BaselineCorrectionCheckbox.Value
                    % Calculate baseline from BG columns
                    baseline_vec = mean(temp{i, 15}(:, 3:3+num_bg_minus_1), 2);
                    % Subtract from analyte scans
                    temp{i, 15}(:, 3:end) = temp{i, 15}(:, 3:end) - baseline_vec;
                    temp{i, 15}(temp{i, 15} < 0) = 0; % Ensure no negative values
                end

                [~, uidx] = unique(temp{i, 15}(:, 1));
                temp{i, 15} = temp{i, 15}(uidx, :);

                temp{i, 17} = temp{i, 15}; % Copy for EIC

                % REMOVE BG scans before analyte filtering
                temp{i, 15}(:, 3:3+num_bg_minus_1) = [];

                % Filter Analytes by Hit Counts
                sc_data = temp{i, 15}(:, 3:end);
                hit_counts = sum(sc_data > 0, 2);
                total_sc_scans = size(sc_data, 2);
                keep_idx = (hit_counts >= min_scans) & (hit_counts / total_sc_scans >= min_hits_thresh);
                temp{i, 15} = temp{i, 15}(keep_idx, :);

                if ~isempty(temp{i, 15})
                    temp{i, 15}(:, 2) = hit_counts(keep_idx);
                end

                % --- ADD RAW INTERNAL STANDARDS BACK ---
                if ~isempty(IS_list) && exist('spectra_i_inc', 'var')
                    % spectra_i_inc was never baseline corrected
                    is_sc_counts = sum(spectra_i_inc(:, 3:end) > 0, 2);
                    spectra_i_inc(:, 2) = is_sc_counts;
                    temp{i, 15} = [temp{i, 15}; spectra_i_inc];
                end

                temp{i, 15} = sortrows(temp{i, 15});

                % Sync EIC and survivors
                if ~isempty(temp{i, 15})
                    final_mz = temp{i, 15}(:, 1);
                    temp{i, 17} = temp{i, 17}(ismember(temp{i, 17}(:,1), final_mz), :);
                    temp{i, 19} = temp{i, 13}(ismember(temp{i, 13}(:,1), final_mz), :);
                else
                    temp{i, 17} = []; temp{i, 19} = [];
                end
            end
        end

        function [analytes_found, analytes_found_no_dup, diff] = find_analytes_from_list(app, analytes_list, ~, ~, table_norm_filtered, ppm)
            % 1. Auto-detect columns in the uploaded database list
            colNames = analytes_list.Properties.VariableNames;
            pos_mz = find(strcmpi(colNames, 'mz') | contains(colNames, 'mz', 'IgnoreCase', true), 1);
            pos_name = find(strcmpi(colNames, 'Name') | contains(colNames, 'Name', 'IgnoreCase', true), 1);

            if isempty(pos_mz) || isempty(pos_name)
                uialert(app.UIFigure, 'The uploaded list must have "mz" and "Name" headers.', 'Column Error');
                analytes_found = table(); analytes_found_no_dup = table(); diff = [];
                return;
            end

            db_mz = analytes_list{:, pos_mz};
            db_names = analytes_list{:, pos_name};
            found_mz = table_norm_filtered.mz;

            % 2. Core Match Matrix
            % Rows = Database Entries, Cols = Sample Peaks
            diff = (abs(db_mz - found_mz')) ./ found_mz' * 1e6 < ppm;
            [db_idx, exp_idx] = find(diff);

            if isempty(db_idx)
                analytes_found = table(); analytes_found_no_dup = table(); return;
            end

            % 3. Build the annotated table from matching pairs
            match_names = db_names(db_idx);
            match_mz = found_mz(exp_idx);
            match_ints = table2array(table_norm_filtered(exp_idx, 2:end));

            var_names = table_norm_filtered.Properties.VariableNames(2:end);
            analytes_found = table(match_names, match_mz, match_ints, 'VariableNames', ["Analyte", "mz", "Int"]);
            analytes_found = splitvars(analytes_found, "Int", "NewVariableNames", var_names);

            % 4. Duplicate Removal logic using RemoveDuplicatesCheckBox_2
            if isprop(app, 'RemoveDuplicatesCheckBox_2') && app.RemoveDuplicatesCheckBox_2.Value
                % Keep only the first annotation found for each unique experimental m/z
                [~, uidx] = unique(analytes_found(:, 2), 'stable');
                analytes_found = analytes_found(uidx, :);

                % Remove rows that are all zeros across cells
                analytes_found(~any(table2array(analytes_found(:, 3:end)), 2), :) = [];
                disp("Annotation complete - duplicates removed via RemoveDuplicatesCheckBox_2");
            else
                % Remove rows with all zero intensities but keep duplicates
                analytes_found(~any(table2array(analytes_found(:, 3:end)), 2), :) = [];
                disp("Annotation complete - duplicates kept");
            end

            analytes_found_no_dup = analytes_found;
        end

        function [analytesKDF] = MassDefectFiltering(app,untargeted_IS_excluded, position_of_mz, ppm)
            % Mass Defect Filtering with Nominal Mass-Dependent Range
            % Input: List of exact masses
            exact_masses = untargeted_IS_excluded{:,position_of_mz};

            % Function to calculate mass defect range based on nominal mass
            % Define your rules for mass defect range
            get_mass_defect_range = @(nominal_mass) [0, 0.00112 * nominal_mass+0.01953]; % Example rule: max defect = 0.001 * nominal mass

            % Preallocate array for filtered masses
            filtered_masses = [];

            % Perform mass defect filtering
            for i = 1:length(exact_masses)
                % Calculate nominal mass
                nominal_mass = floor(exact_masses(i));

                % Calculate mass defect
                mass_defect = exact_masses(i) - nominal_mass;

                % Get mass defect range for the nominal mass
                mass_defect_range = get_mass_defect_range(nominal_mass); % Call the function
                mass_defect_min = mass_defect_range(1); % Extract min value
                mass_defect_max = mass_defect_range(2); % Extract max value

                % Check if mass defect is within the range
                if mass_defect >= mass_defect_min && mass_defect <= mass_defect_max
                    filtered_masses = [filtered_masses, exact_masses(i)];
                end
            end
            filtered_masses = table(filtered_masses');
            analytesKDF = app.keep_significant_analytes_untargeted_annotated(untargeted_IS_excluded, filtered_masses, 1, ppm);
        end

        function [IS_excluded] = exclude_mz(app,feature_table,exclusion_list,position_of_mz, ppmgap)

            try ischar(feature_table{1,1}{1,1});
                disp("Targeted Exclusion")
                annotated_array = table2array(feature_table(:,2:end));
                IS = unique(exclusion_list{:,position_of_mz});
                disp(head(IS))
                diff = (abs(IS-feature_table.("mz")'))./feature_table.("mz")'*1e6<ppmgap;
                sum_diff = sum(diff,1); % find the indices where IS is found
                idx = find(sum_diff); % exclude those indices
                annotated_array(idx, :) = [];
                names = feature_table.Analyte;
                names(idx) = [];
                table_IS_excluded = table(names, annotated_array, VariableNames=["Analyte", "annotated_array"]);
                var_names = feature_table.Properties.VariableNames;
                var_names_1 = var_names(2:end);
                IS_excluded = splitvars(table_IS_excluded, "annotated_array", "NewVariableNames",var_names_1);
            catch
                untargeted_array = table2array(feature_table);
                IS = unique(exclusion_list{:,position_of_mz});
                disp("Untargeted Exclusion")
                disp(head(IS))
                diff = (abs(IS-feature_table.("mz")'))./feature_table.("mz")'*1e6<ppmgap;
                sum_diff = sum(diff,1); % find the indices where IS is found
                idx = find(sum_diff); % exclude those indices
                untargeted_array(idx, :) = [];

                var_names = feature_table.Properties.VariableNames;
                var_names_1 = var_names(1:end);

                IS_excluded = table(untargeted_array);
                IS_excluded = splitvars(IS_excluded, "untargeted_array", "NewVariableNames",var_names_1);
            end
        end

        function [table_final_norm, table_norm_filtered] = find_final_table_norm(app, filtered_features, single_cells, ppmgap, times, min_hits_of_all_cells_norm)
            % Function to get the final table with normalized labelled features against the cells
            mass_list = filtered_features(:,1);
            min_hits_of_all_cells_norm = min_hits_of_all_cells_norm/100;
            %labelled_table = readtable('C:\Users\fried\Documents\MATLAB\PeakPickingFunctions\Book2.csv');

            %remove empty rows
            single_cells = single_cells(~cellfun(@isempty, single_cells(:,2)), :);

            data_out = {};
            for i = 1:size(single_cells,1)
                apeaks = single_cells{i,9};

                %diff = (abs(analytes_list.Var2-masses_out(:,2)'))./masses_out(:,2)'*1e6<ppmgap
                diff = (abs(mass_list(:,1)-apeaks(:,1)'))./apeaks(:,1)'*1e6<ppmgap;
                sum_of_isomers = sum(diff,2);

                annotated_mz = (diff*apeaks(:,1))./sum_of_isomers;
                annotated_mz(isnan(annotated_mz))=0;

                ints  = (diff*apeaks(:,2))./sum_of_isomers;
                ints(isnan(ints))=0;

                table_mz = mass_list(annotated_mz~=0);
                mz_val = annotated_mz(annotated_mz~=0);
                int_val = ints(annotated_mz~=0);
                data_out{i,1} = table(table_mz,mz_val,int_val);
            end


            % join all single cells together
            t_loop = data_out{1,1}(:,[1 3]);
            for i = 2:length(data_out)
                t_loop = outerjoin(t_loop, data_out{i,1}(:,[1 3]), "Keys","table_mz", MergeKeys=true);
            end
            % Formatting final table
            table_final_norm = fillmissing(t_loop,"constant",0);
            rounded_start = round(times(:,1),2);
            rounded_end = round(times(:,2),2);
            formatSpec = '%.2f';
            n_single_cell = size(single_cells,1);

            cell_times_start = {};
            cell_times_end = {};
            cell_names = {};
            for i = 1:n_single_cell
                cell_times_start{i} = num2str(rounded_start(i), formatSpec);
                cell_times_end{i} = num2str(rounded_end(i), formatSpec);
                cell_names{i,1} = "cell"+cell_times_start(i)+"_"+cell_times_end(i);
            end

            cell_test = [cell_names{:,1}]';
            cell_test = ["mz"; cell_test];
            table_final_norm.Properties.VariableNames = cell_test;
            table_final_norm = table_final_norm(sum(table_final_norm{:,2:end},2)>0,:);

            table_final_norm(:, end+1) = table((size(table_final_norm(:,2:end),2)-sum(ismissing(table_final_norm(:, 2:end), 0),2))/size(table_final_norm(:,2:end),2)); % get frequency of mz across cells
            table_norm_filtered = table_final_norm(table_final_norm{:,end}>min_hits_of_all_cells_norm, :);
            table_norm_filtered(:,end) = [];
        end

        function [table_final, untargeted_table_filtered] = find_final_table(app, all_features, single_cells, column, ppm, times_sc, mode, min_hits_of_all_cells)
            % Function to get the final table with features against the cells
            times = times_sc;
            min_hits_of_all_cells = min_hits_of_all_cells/100;
            %mass_list = filtered_features;
            mass_list = all_features(:,1);
            %mass_list = masses_out(:,1);

            %remove empty rows
            single_cells(~cellfun(@isempty, single_cells(:,column)), :);

            % if statement for selection of either average of scans in a single cell or
            % the sum of the scans
            if mode == "mean"
                mz_table = {};
                for i = 1:size(single_cells,1)
                    mz_table{i, 1} = single_cells{i,column}(:,[1 3:end]);
                    mz_table{i, 2} = mean(mz_table{i,1}(:,2:end),2);
                end
            else % mode == "sum"
                mz_table = {};
                for i = 1:size(single_cells,1)
                    mz_table{i, 1} = single_cells{i,column}(:,[1 3:end]);
                    mz_table{i, 2} = sum(mz_table{i,1}(:,2:end),2);
                end
            end

            data_out = {};
            for i = 1:length(mz_table)
                apeaks = mz_table{i,:};

                %diff = (abs(analytes_list.Var2-masses_out(:,2)'))./masses_out(:,2)'*1e6<ppmgap
                diff = (abs(mass_list(:,1)-apeaks(:,1)'))./apeaks(:,1)'*1e6<ppm;
                sum_of_isomers = sum(diff,2);

                annotated_mz = (diff*apeaks(:,1))./sum_of_isomers;
                annotated_mz(isnan(annotated_mz))=0;

                ints  = (diff*mz_table{i,2})./sum_of_isomers;
                ints(isnan(ints))=0;

                table_mz = mass_list(annotated_mz~=0);
                mz_val = annotated_mz(annotated_mz~=0);
                int_val = ints(annotated_mz~=0,:);
                data_out{i,1} = table(table_mz,mz_val,int_val);
            end

            % join all single cells together
            t_loop = data_out{1,1}(:,[1 3]);
            for i = 2:length(data_out)
                t_loop = outerjoin(t_loop, data_out{i,1}(:,[1 3]), "Keys","table_mz", MergeKeys=true);
            end
            % Formatting final table
            table_final = fillmissing(t_loop,"constant",0);
            rounded_start = round(times(:,1),2);
            rounded_end = round(times(:,2),2);
            formatSpec = '%.2f';
            n_single_cell = size(single_cells,1);

            cell_times = {};
            cell_names = {};
            for i = 1:n_single_cell
                cell_times{i,1} = num2str(rounded_start(i), formatSpec);
                cell_times{i,2} = num2str(rounded_end(i), formatSpec);
                cell_names{i,1} = "cell"+cell_times(i,1)+"_"+cell_times(i,2);
            end

            cell_test = [cell_names{:,1}]';
            cell_test = ["mz"; cell_test];
            table_final.Properties.VariableNames = cell_test;

            table_final(:, end+1) = table((size(table_final(:,2:end),2)-sum(ismissing(table_final(:, 2:end), 0),2))/size(table_final(:,2:end),2));
            untargeted_table_filtered = table_final(table_final{:,end}>min_hits_of_all_cells, :);
            untargeted_table_filtered(:,end) = [];
        end

        function [all_features] = find_experiment_features(app, single_cells, ppm)
            fprintf('\n>>> DEBUG 3: find_experiment_features (Entry)\n');
            fprintf('DEBUG 3: input "single_cells" size: [%d x %d]\n', size(single_cells, 1), size(single_cells, 2));

            % Check the loop range
            num_windows = size(single_cells, 1);
            fprintf('DEBUG 3: Function will loop %d times\n', num_windows);

            for i = 1:num_windows
                if isempty(single_cells{i,1})
                    fprintf('*** ERROR: single_cells{%d, 1} is EMPTY. Crash imminent at line 4009. ***\n', i);
                    % Force a stop to see the workspace
                    % error('Stopping for debug');
                end
            end
            %single_cells = spectra_reshaped;
            %remove empty rows
            single_cells(~cellfun(@isempty, single_cells(:,1)), :);

            % Get intensity threshold from app (0 means no threshold)
            intensity_thresh = app.IntensitythresholdEditField.Value;

            % Preallocate cells
            allocate = 0;
            num_scan = 0;
            for i = 1:size(single_cells,1)
                num_scan = num_scan+single_cells{i,1}(1,1);
                for j = 1:size(single_cells{i,1},1)
                    allocate = allocate+size(single_cells{i,1}(j,1),1);
                end
            end

            %Stack all mass spectra in a preallocated array with intensity filtering
            comp_masslist = zeros(allocate,2);
            count = 1;
            for j = 1:size(single_cells,1)
                for i = 1:size(single_cells{j},2)
                    datnow = single_cells{j,1};

                    % Apply intensity threshold if specified
                    if intensity_thresh > 0
                        valid_peaks = datnow(:,2) >= intensity_thresh;
                        datnow = datnow(valid_peaks,:);
                    end

                    if ~isempty(datnow)
                        comp_masslist(count:count+size(datnow,1)-1,:) = datnow;
                        count = count+size(datnow,1);
                    end
                end
            end

            % Trim unused preallocated space
            comp_masslist = comp_masslist(1:count-1,:);

            % Sort the list of all m/z and intensity values
            comp_masslist = comp_masslist(comp_masslist(:,1)~=0,:);
            comp_masslist = sortrows(comp_masslist,1);

            % Determine peak groups by ppm tolerance
            i = 1;
            pk = 1;
            c = 1;
            mz = 0;
            pkgroup = {};

            while lt(i,length(comp_masslist)+1)
                mz = mz+comp_masslist(i,1);
                mz_norm = mz/numel(pk:1:i);

                if abs((mz_norm-comp_masslist(i,1))/mz_norm)*1E6<ppm
                    i = i+1;
                else
                    pkgroup{c,1} = pk:1:i-1;
                    c = c+1;
                    pk=i;
                    mz = 0;
                end
            end
            pkgroup{c,1} = pk:1:i-1; % adds last peak group

            % Calculate feature statistics with additional intensity checks
            no_features=size(pkgroup,1);
            all_features = zeros(no_features,11);
            valid_feature_count = 0;

            for feat = 1:no_features
                temp_set = comp_masslist(pkgroup{feat,1},:);

                % Additional intensity check for the feature group
                if intensity_thresh > 0 && max(temp_set(:,2)) < intensity_thresh
                    continue; % Skip features that don't meet threshold
                end

                valid_feature_count = valid_feature_count + 1;

                % Feature statistics
                all_features(valid_feature_count,1) = sum(temp_set(:,2).*temp_set(:,1))./sum(temp_set(:,2));
                all_features(valid_feature_count,5) = size(temp_set,1)/num_scan;
                all_features(valid_feature_count,6) = mean(temp_set(:,2));

                % Additional statistics you might want to keep
                % all_features(valid_feature_count,7) = max(temp_set(:,2));
                % all_features(valid_feature_count,8) = std(temp_set(:,2));
            end

            % Trim unused feature rows
            all_features = all_features(1:valid_feature_count,:);
        end

        function [spectra_reshaped] = reshape_bg_filtered_feat_for_aligning(app, single_cells)

            for i = 1:size(single_cells, 1)
                single_cell = single_cells{i,2};
                mz = single_cell(:,1);
                single_cell(:,1:2) = [];

                reshaped = reshape(single_cell, [],1);
                mz_all = mz;
                for k = 2:size(single_cell,2)
                    mz_all = vertcat(mz_all, mz);
                end

                re_comb = [mz_all, reshaped];
                spectra_reshaped{i, 1} = re_comb;
                spectra_reshaped{i, 2} = size(single_cell,2);
            end
        end

        function find_total_concentration(app, single_cells, flow_rate)
            % Initialize outputs
            app.scanWise = cell(size(single_cells,1), 1);
            app.eventWise = cell(size(single_cells,1), 1);

            for i = 1:size(single_cells,1)
                % 1. Get processed intensities (already BG-removed in filtering)
                if size(single_cells{i, 6}, 2) > 1
                    matrix1 = single_cells{i, 6}(:, 2:end);
                else
                    matrix1 = single_cells{i, 6};
                end

                % 2. Get Duty Cycles
                if iscell(single_cells{i, 5})
                    m2_full = cell2mat(single_cells{i, 5}(:, 1));
                else
                    m2_full = single_cells{i, 5}(:, 1);
                end

                % 3. Align Duty Cycles to the Cell Window
                num_bg = single_cells{i, 11};
                matrix2 = m2_full(num_bg + 1 : end);
                matrix2 = matrix2(:); % Column vector for matrix2' transpose

                % 4. Protect against minor scan-count mismatches
                num_cols = size(matrix1, 2);
                if length(matrix2) > num_cols
                    matrix2 = matrix2(1:num_cols);
                elseif length(matrix2) < num_cols
                    matrix1 = matrix1(:, 1:length(matrix2));
                end

                % 5. Original Mathematical Calculation
                % Intensity * Duty Cycle * Flow Rate * 10^-6
                product = matrix1 .* matrix2' * flow_rate * 1E-6;

                % 6. Store Scan-Wise and Event-Wise (Summed)
                mz_values = single_cells{i, 6}(1:size(matrix1,1), 1);
                single_cells{i, 8} = [mz_values, product];
                app.scanWise{i} = single_cells{i, 8};

                single_cells{i, 9} = [mz_values, sum(product, 2)];
                app.eventWise{i} = single_cells{i, 9};
            end
        end

        function [data_norm, data_norm_sum] = find_normalized_analytes_every_scan(app, labelled_table, single_cell, ppm)
            % Function to normalize endogenous molecules with standards
            diff_endo = (abs(labelled_table{:,2}-single_cell(:,1)'))./single_cell(:,1)'*1e6<ppm;
            diff_standards = (abs(labelled_table{:,3}-single_cell(:,1)'))./single_cell(:,1)'*1e6<ppm;

            sum_of_isomers_endo = sum(diff_endo,2); % sum of isobars in row
            sum_of_isomers_standards = sum(diff_standards,2);

            found_mz_endo = diff_endo*single_cell(:,1)./sum_of_isomers_endo; % divide by the number of isobars
            found_mz_endo(isnan(found_mz_endo))=0; % replace nan with 0

            ints_standards = diff_standards*single_cell(:,3:end)./sum_of_isomers_standards; % intensities of standards
            ints_endo = (diff_endo*single_cell(:,3:end))./sum_of_isomers_endo; % intensities of endogenous molecules
            ints_endo(isnan(ints_endo))=0; % replace nan with 0

            %Multiply intensity of endogenous analytes with the concentration of the
            %internal standards
            ints_mult_conc = ints_endo.*labelled_table{:,4}*1E-6; % concentration in uM

            ints_normalized = ints_mult_conc./ints_standards; % ratio of Analyte/IS
            ints_normalized(isnan(ints_normalized))=0; % replaces NAN with 0ints_normalized(isinf(ints_normalized))=0; % replaces Inf with 0
            ints_normalized(isinf(ints_normalized))=0; % replaces NAN with 0ints_normalized(isinf(ints_normalized))=0; % replaces Inf with 0

            data_norm = [found_mz_endo, ints_normalized];
            data_norm_sum = [found_mz_endo, sum(ints_normalized,2)];
            data_norm = data_norm(any(data_norm,2),:);
            data_norm = sortrows(data_norm, 1);
        end

        function [TIC_normalized, sum_TIC, avg_TIC] = TIC_normalization(app,~, background_scans)

            TIC_normalized = app.extractedEvents(:,2);
            TIC_data = app.extractedEvents(:,13);

            sum_TIC = zeros(length(app.extractedEvents(:,2)),1);
            avg_TIC = zeros(length(app.extractedEvents(:,2)),1);
            for i = 1:length(app.extractedEvents(:,2))
                mat = cell2mat(TIC_data{i,1}(background_scans+1:end,1)); % extract TIC data
                rep_mat = repmat((mat'),size(TIC_normalized{i,1}(:,3:end),1),1); % duplicate it so it applies to all rows
                TIC_normalized{i,1}(:,3:end) = TIC_normalized{i,1}(:,3:end)./rep_mat;
                sum_TIC(i,1) = sum(mat);
                avg_TIC(i,1) = mean(mat);
            end

        end
        % Function to find all m/z features from the linescans in the apeaks
        % structure in the defined time regions in the variable region1. The
        % algorithm accounts for mass tolerance (ppmgap), frequency of detection (min_hits)
        % in the given region and scan events (n and m).

        function [masses_out, all_int] = find_features_all_int(app, spectra_comb, ppmgap, min_hits, intensity_thresh)

            % Use this parameters if not using it as a function
            %ppmgap = 4.9;
            %min_hits = 10;
            %single_cell = spectra(98,:);

            % percentage in how many spectra the features need to be present
            min_hits = min_hits/100;

            % Calculate the number of scan events in the region of interest and
            % preallocates a vector
            allocate = 0;
            num_scan = 0;
            for i = 1:size(spectra_comb,1)
                num_scan = num_scan+size(spectra_comb{i,1},1);
                for j = 1:size(spectra_comb{i,1},1)
                    allocate = allocate+size(spectra_comb{i,1},1);
                end
            end

            %Stack all massspectra in a preallocated array and an intitialized counter
            %variable
            comp_masslist = zeros(allocate,5);
            count = 1;
            for j = 1:size(spectra_comb,1)
                for i = 1:size(spectra_comb{j},1)
                    datnow = spectra_comb{j,1}{i,:};
                    datnow(:,5) = i;
                    comp_masslist(count:count+size(datnow,1)-1,:) = datnow;
                    count = count+size(datnow,1);
                end
            end

            % Sort the list of all m/z and intensity values
            comp_masslist= comp_masslist(comp_masslist(:,1)~=0,:);
            comp_masslist=sortrows(comp_masslist);
            % comp_masslist=comp_masslist(comp_masslist(:,2)>intensity_thresh,:);

            % Determine peak groups by determining where the ppm error is bigger than
            % the treshold ppmgap value. All values that pass the test are sorted into
            % the same peak group. The variable pk initializes the ppm calculation
            % frame, i is the iterator for comparing with pk and c is the peakgroup
            % counter.
            i = 1;
            pk = 1;
            c = 1;
            %intensity = 0;
            mz = 0;
            pkgroup = {};
            while lt(i,length(comp_masslist)+1)

                %intensity = intensity+comp_masslist(i,2);
                mz = mz+comp_masslist(i,1);%*comp_masslist(i,2);

                mz_norm = mz/numel(pk:1:i);
                if abs((mz_norm-comp_masslist(i,1))/mz_norm)*1E6<ppmgap
                    i = i+1;
                else
                    pkgroup{c,1} = pk:1:i-1;
                    c = c+1;
                    pk=i;
                    %intensity = 0;
                    mz = 0;
                end
            end

            pkgroup{c,1} = pk:1:i-1; % adds the last peak group

            % Extract data and calculate statisitcs on the peaks in peak groups that
            % are bigger than the threshold min_hits.
            no_features=size(pkgroup,1);
            mass_stats = zeros(no_features,11);
            all_int = zeros(no_features, size(spectra_comb{1,1},1)+1);

            for feat=(1:no_features)
                %if size(pkgroup{feat,1},2)/num_scan
                temp_set=comp_masslist(pkgroup{feat,1},:);
                %         tmp_avg = zeros(num_scan,1);
                %         tmp_avg(1:length(temp_set(:,1))) = temp_set(:,2);
                %Feature number
                mass_stats(feat,1)=feat;
                %Average accurate mass value weighted based on intensity
                mass_stats(feat,2)= sum(temp_set(:,2).*temp_set(:,1))./sum(temp_set(:,2));
                %Standard deviation of accurate masss
                mass_stats(feat,3)=std(temp_set(:,1));
                %Spread of accurate mass
                mass_stats(feat,4)=max(temp_set(:,1))-min(temp_set(:,1));
                %Detection frequency
                mass_stats(feat,5)=size(temp_set,1)/num_scan;
                %Mean intensity over the whole region of interest
                mass_stats(feat,6)=mean(temp_set(:,2));
                %Max intensity
                mass_stats(feat,7)=max(temp_set(:,2));
                %Standard deviation of intensity
                mass_stats(feat,8)=std(temp_set(:,2));
                %Mean noise over all spectra
                mass_stats(feat,9) = mean(temp_set(:, 3));
                %Mean baseline over all spectra
                mass_stats(feat,10) = mean(temp_set(:,4));
                % Sum over all scans
                mass_stats(feat,11) = sum(temp_set(:,2),"all");
                %end

                all_int(feat,1) = sum(temp_set(:,2).*temp_set(:,1))./sum(temp_set(:,2)); %Average accurate mass value weighted based on intensity
                %all_int(feat,2) = size(temp_set,1)/num_scan; % Detection frequency

                % Here the intensites for each scan are added to a column
                for k = 1:size(temp_set,1)
                    col = temp_set(k, 5);
                    all_int(feat,col+2) = temp_set(k, 2);
                end
            end
            % Filter features with detection frequency
            all_int(:,2) = sum(all_int > 0, 2);
            all_int(:,2) = all_int(:,2)/(num_scan+1); % detection frequency in all single cell scans
            all_int = all_int(all_int(:,2)>min_hits,:);
            %all_int = all_int(all_int(:,2)>intensity_thresh,:);


            % Remove zero vals from master list by using thresholds
            masses_out=mass_stats(mass_stats(:,5)>min_hits,:);
            masses_out=masses_out(masses_out(:,6)>intensity_thresh,:);

        end

        function plotAllScans(app, normalize, smoothData)
            intensities = app.untargetedTableEIC(:,2:end);

            % Plot
            plot(app.EICAxes, intensities', 'LineWidth', 1.5);
            title(app.EICAxes, 'All Scans EIC');
        end

        function plotByCell(app, normalize, smoothData)
            colors = lines(length(app.sc_all_data));
            legendEntries = cell(1, length(app.sc_all_data));

            hold(app.EICAxes, 'on');
            for i = 1:length(app.sc_all_data)
                cell_data = app.sc_all_data{i}(:,2:end);

                % Preprocess data
                if normalize
                    cell_data = cell_data ./ max(cell_data(:));
                end
                if smoothData
                    cell_data = smoothdata(cell_data, 2, 'gaussian', 5);
                end

                plot(app.EICAxes, cell_data', 'Color', colors(i,:), 'LineWidth', 1.5);
                legendEntries{i} = sprintf('Cell %d', i);
            end
            hold(app.EICAxes, 'off');

            % Add legend
            legend(app.EICAxes, legendEntries, 'Location', 'best');
            title(app.EICAxes, 'Individual Cell EICs');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Initialize DLLs when the app starts
            app.importDLLs();
            createContextMenu(app);

            if ~isempty(app.predictedFeatures) && size(app.predictedFeatures, 2) >= 2
                updateEICPlotGallery(app, "All");
            end
            app.setLampReady();
            % Set up table callbacks for both tables
            app.UntargetedResultsTable.CellSelectionCallback = @(src,event) app.tableCellSelection(src, event, 'untargeted');
            app.AnnotatedResultsTable.CellSelectionCallback = @(src,event) app.tableCellSelection(src, event, 'targeted');
            app.UntargetedResultsTable.UserData.SelectedCell = [];
            app.AnnotatedResultsTable.UserData.SelectedCell = [];
            app.UntargetedResultsTable.SelectionType = 'row';
            app.AnnotatedResultsTable.SelectionType = 'row';
            app.UntargetedResultsTable.Multiselect = 'off';
            app.AnnotatedResultsTable.Multiselect = 'off';
            app.UntargetedResultsTable_2.CellSelectionCallback = @(src,event) app.storeSelectedRow(src, event);
            % Set up table callbacks for DL tab tables
            app.UntargetedResultsTable_2.CellSelectionCallback = @(src,event) app.tableCellSelectionDL(src, event, 'untargeted');
            app.UntargetedResultsTable_2.UserData.SelectedCell = [];
            app.UntargetedResultsTable_2.SelectionType = 'row';
            app.UntargetedResultsTable_2.Multiselect = 'off';

        end

        % Button pushed function: SelectDataFolderButton
        function SelectDataFolderButtonPushed(app, event)
            app.selectFolder();
        end

        % Value changed function: ScanTypeDropdown
        function ScanTypeDropdownValueChanged(app, event)
            % Update the selected scan type
            % Update the selected scan type
            app.selectedScanType = upper(app.ScanTypeDropdown.Value); % Normalize to uppercase

            % Display the selected scan type in the status text area
            app.StatusTextArea.Value = "Scan type set to: " + app.selectedScanType;
        end

        % Button pushed function: DetectEvents
        function DetectEventsButtonPushed(app, event)
            cla(app.UIAxes); % Clear the axes
            hold(app.UIAxes, 'off'); % Reset the hold state

            % Call the detectEventScans function to generate the new plot
            app.detectEventScans();
        end

        % Button pushed function: UploadEventListButton
        function UploadEventListButtonPushed(app, event)
            [file, path] = uigetfile({'*.xlsx;*.csv'}, 'Select Event List File');
            if isequal(file, 0), return; end

            try
                % 1. DATA PREPARATION
                if isempty(app.rawData)
                    error('No MS data loaded. Please select a file first.');
                end

                selectedFile = app.FileDropdown.Value;
                selectedIndex = find(strcmp({app.sourceFiles.name}, selectedFile));
                app.scanData = app.rawData{selectedIndex};

                % Apply active filters (FTMS/ITMS)
                if strcmpi(app.selectedScanType, 'FTMS')
                    app.scanData = app.scanData(contains(string(app.scanData(:, 4)), "FTMS", 'IgnoreCase', true), :);
                elseif strcmpi(app.selectedScanType, 'ITMS')
                    app.scanData = app.scanData(contains(string(app.scanData(:, 4)), "ITMS", 'IgnoreCase', true), :);
                end

                % 2. LOAD EXCEL & EXTRACT GLOBAL VALUES
                tempTable = readtable(fullfile(path, file));
                num_data = table2array(tempTable);

                % The extraction function needs the raw numeric data
                app.time_list_events = num_data;

                % 3. TRIGGER EXTRACTION
                % We use your manual function to find the actual scans associated with these times
                [~, ~, ~, t_sc, t_bg] = app.find_single_cell_extraction_manually(...
                    app.PrecedingScans.Value, ...
                    app.scanData, ...
                    app.BackgroundScans.Value, ...
                    app.BackgroundGap.Value, ...
                    app.time_list_events);

                % 4. FORCE GLOBAL BACKGROUND MAPPING
                % Since find_single_cell_extraction_manually might try to calculate rolling BG,
                % we override the table with your specific Excel values if they are provided.
                % We convert your Excel Minutes to the app's timeline if necessary.

                num_cells = size(t_sc, 1);
                durations = t_sc(:, 2) - t_sc(:, 1);

                % Construct the 7-column table for the UI
                T = table((1:num_cells)', false(num_cells, 1), t_sc(:,1), t_sc(:,2), ...
                    durations, t_bg(:,1), t_bg(:,2), ...
                    'VariableNames', {'#', 'Select', 'SC_Start', 'SC_End', 'Duration', 'BG_Start', 'BG_End'});

                % Override the Background columns specifically with the fixed Excel values
                % This ensures the "Global" feel from your file is preserved
                T.BG_Start = num_data(:, 3);
                T.BG_End = num_data(:, 4);

                app.TimeWindowsTable.Data = T;
                app.UploadedFileNameLabel.Text = file;

                % 5. REFRESH VISUALS
                app.updatePlotAfterRowDeletion();
                app.appendStatusMessage(sprintf('Mapped %d events with Global Background.', num_cells));

            catch ME
                uialert(app.UIFigure, ME.message, 'Upload Error');
            end
        end

        % Button pushed function: ClearEventListButton
        function ClearEventListButtonPushed(app, event)
            % 1. Clear the internal event list and labels
            app.time_list_events = [];
            app.UploadedFileNameLabel.Text = "No file uploaded";

            % 2. Clear the UI Table
            app.TimeWindowsTable.Data = [];

            % 3. Clear the internal extraction arrays (Crucial for Plotting/Processing)
            app.times_sc = [];
            app.times_bg = [];
            app.spectra_sc = {};
            app.spectra_bg = {};
            app.spectra_comb = {};

            % 4. Re-enable Automated Sync controls (in case they were disabled by upload)
            app.TouchdownDurationEditField.Enable = 'on';
            app.ManualDelayOffsetEditField.Enable = 'on';
            app.SyncCalculatedButton.Enable = 'on';

            % 5. Refresh Plot to remove the blue/gold patches
            app.plotMzTrace();

            % 6. UI Feedback
            app.StatusTextArea.Value = "Event list and extracted data cleared. Automated mode re-enabled.";
        end

        % Button pushed function: LoadDLLsButton
        function LoadDLLsButtonPushed(app, event)
            importDLLs(app);
        end

        % Value changed function: mz, mzPlotting
        function mzValueChanged(app, event)
            value = app.mz.Value;
            if isnan(value) || value <= 0
                app.appendStatusMessage('Error: Invalid m/z value. Please enter a positive number.');
                app.mz.Value = 0; % Reset to default value
            end
        end

        % Value changed function: ppm
        function ppmValueChanged(app, event)
            value = app.ppm.Value;
            if isnan(value) || value <= 0
                app.appendStatusMessage('Error: Invalid ppm value. Please enter a positive number.');
                app.ppm.Value = 10; % Reset to default value
            end
        end

        % Value changed function: SN
        function SNValueChanged(app, event)

            value = app.SN.Value;
            if isnan(value) || value < 0
                app.appendStatusMessage('Error: Invalid SN value. Please enter a non-negative number.');
                app.SN.Value = 3; % Reset to default value
            end
        end

        % Value changed function: CutoffTime
        function CutoffTimeValueChanged(app, event)
            value = app.CutoffTime.Value;
            if isnan(value) || value < 0
                app.appendStatusMessage('Error: Invalid Cutoff Time value. Please enter a non-negative number.');
                app.CutoffTime.Value = 0.1; % Reset to default value
            end
        end

        % Button pushed function: DeleteSelectedRowsButton
        function DeleteSelectedRowsButtonPushed(app, event)
            % 1. Get the current table data
            table_data = app.TimeWindowsTable.Data;
            if isempty(table_data), return; end

            % 2. Extract 'Select' column safely
            % Handles both logical arrays and cell arrays of logicals
            if iscell(table_data.Select)
                selected_rows = cell2mat(table_data.Select);
            else
                selected_rows = logical(table_data.Select);
            end

            % 3. Process Deletion
            if any(selected_rows)
                % 1. Delete rows
                table_data(selected_rows, :) = [];

                % 2. Reset the Ticks and Row Numbers
                if ~isempty(table_data)
                    table_data.Select = repmat({false}, height(table_data), 1);
                    table_data.('#') = (1:height(table_data))';
                end
                app.TimeWindowsTable.Data = table_data;

                % 3. CRITICAL: Convert cells to numeric BEFORE calling the plotter
                % This prevents 'isnan' errors in the plotting function
                app.times_sc = cell2mat(table_data{:, {'SC_Start', 'SC_End'}});
                app.times_bg = cell2mat(table_data{:, {'BG_Start', 'BG_End'}});
                % Handle Background potential empty cells
                bg_raw = table_data{:, {'BG_Start', 'BG_End'}};
                app.times_bg = zeros(size(bg_raw));
                for r = 1:size(bg_raw,1)
                    for c = 1:2
                        val = bg_raw{r,c};
                        if isempty(val), app.times_bg(r,c) = NaN; else, app.times_bg(r,c) = val; end
                    end
                end

                % 4. Now run the update and plot
                try
                    % We pass the cleaned numeric versions
                    app.spectra_comb = app.updateSpectraCombAfterDeletion(app.times_sc, app.times_bg);

                    % This will now work because app.times_bg is no longer a cell!
                    app.plotMzTrace();

                    app.appendStatusMessage('Success: Rows deleted and plot updated.');
                catch ME
                    app.appendStatusMessage(['Plotting failed: ', ME.message]);
                end
            end
        end

        % Button pushed function: PlotEICButton
        function PlotEICButtonPushed(app, event)
            % Get the m/z value to plot (e.g., from a UI input field)
            %mzPlotting = app.mzPlotting.Value; % Assuming you have a UI field for the m/z value
            %ppmPlotting = app.ppm.Value;
            app.plotMzTrace(); % Call the plotting function
        end

        % Button pushed function: ExportEventDataButton
        function ExportEventDataButtonPushed(app, event)
            exportData(app);
        end

        % Button pushed function: UploadISExclusionListButton
        function UploadISExclusionListButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx;*.csv', 'Select Event List File');

            % Check if the user selected a file
            if isequal(file, 0)
                app.StatusTextAreaProcessing.Value = "No file selected.";
                return;
            end

            % Read the event list file
            try
                filePath = fullfile(path, file);
                tempTable = readtable(filePath);

                % Validate file format
                if size(tempTable, 2) > 1
                    app.StatusTextAreaProcessing.Value = "Error: File must have one column: IS m/z).";
                    return;
                end

                % Store the event list and update UI
                app.ISExclusion = tempTable;
                app.UploadedISExclusionLabel.Text = file;
                app.StatusTextAreaProcessing.Value = "IS exclusion list uploaded successfully.";
            catch ME
                app.StatusTextAreaProcessing.Value = "Error reading file: " + ME.message;
            end
        end

        % Button pushed function: ClearISExclusionListButton
        function ClearISExclusionListButtonPushed(app, event)
            % Clear the IS exclusion list and reset UI
            app.ISExclusion = [];
            app.UploadedISExclusionLabel.Text = "No file uploaded";
            app.StatusTextAreaProcessing.Value = "IS exclusion list cleared.";
        end

        % Button pushed function: DataProcessingTargeted
        function DataProcessingTargetedPushed(app, event)
            app.targetedProcessing();
        end

        % Button pushed function: UploadISPaired
        function UploadISPairedButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx;*.csv', 'Select Event List File');

            % Check if the user selected a file
            if isequal(file, 0)
                app.StatusTextAreaProcessing.Value = "No file selected.";
                return;
            end

            % Read the event list file
            try
                filePath = fullfile(path, file);
                tempTable = readtable(filePath);

                % Validate file format
                if size(tempTable, 2) < 3
                    app.StatusTextAreaProcessing.Value = "Error: File must have four columns in that order (IS name, m/z endo, m/z IS, IS conc. [µmol/L]).";
                    return;
                end

                % Store the event list and update UI
                app.ISPaired = tempTable;
                app.UploadedISPairedLabel.Text = file;
                app.StatusTextAreaProcessing.Value = "IS Paired list uploaded successfully.";
            catch ME
                app.StatusTextAreaProcessing.Value = "Error reading file: " + ME.message;
            end
        end

        % Button pushed function: ClearISPairedListButton
        function ClearISPairedListButtonPushed(app, event)
            % Clear the IS paired list and reset UI
            app.ISExclusion = [];
            app.UploadedISPairedLabel.Text = "No file uploaded";
            app.StatusTextAreaProcessing.Value = "IS exclusion list cleared.";
        end

        % Button pushed function: UploadExclusionList
        function UploadExclusionListButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx;*.csv', 'Select Event List File');

            % Check if the user selected a file
            if isequal(file, 0)
                app.StatusTextAreaProcessing.Value = "No file selected.";
                return;
            end

            % Read the event list file
            try
                filePath = fullfile(path, file);
                tempTable = readtable(filePath);

                % Validate file format
                if size(tempTable, 2) > 1
                    app.StatusTextAreaProcessing.Value = "Error: File must have one column (m/z IS/Solvent/etc. .";
                    return;
                end

                % Store the event list and update UI
                app.ExclusionList = tempTable;
                app.UploadedExclusionLabel.Text = file;
                app.StatusTextAreaProcessing.Value = "Exclusion list uploaded successfully.";
            catch ME
                app.StatusTextAreaProcessing.Value = "Error reading file: " + ME.message;
            end
        end

        % Button pushed function: ClearExclusionButton
        function ClearExclusionButtonPushed(app, event)
            % Clear the IS paired list and reset UI
            app.ExclusionList = [];
            app.UploadedISPairedLabel.Text = "No file uploaded";
            app.StatusTextAreaProcessing.Value = "Exclusion list cleared.";
        end

        % Button pushed function: AnnotationListButton
        function AnnotationListButtonPushed(app, event)
            [file, path] = uigetfile('*.xlsx;*.csv', 'Select Event List File');

            % Check if the user selected a file
            if isequal(file, 0)
                app.StatusTextAreaProcessing.Value = "No file selected.";
                return;
            end

            % Read the event list file
            try
                filePath = fullfile(path, file);
                tempTable = readtable(filePath);

                % Validate file format
                if size(tempTable, 2) > 2
                    app.StatusTextAreaProcessing.Value = "Error: File must have two columns (Analyte name, m/z";
                    return;
                end

                % Store the event list and update UI
                app.AnnotationList = tempTable;
                app.AnnotationListLabel.Text = file;
                app.StatusTextAreaProcessing.Value = "Annotation list uploaded successfully.";
            catch ME
                app.StatusTextAreaProcessing.Value = "Error reading file: " + ME.message;
            end
        end

        % Button pushed function: ClearAnnotationInclusionListButton
        function ClearAnnotationInclusionListButtonPushed(app, event)
            % Clear the Annotation list and reset UI
            app.AnnotationList = [];
            app.AnnotationListLabel.Text = "No file uploaded";
            app.StatusTextAreaProcessing.Value = "Exclusion list cleared.";
        end

        % Cell edit callback: UntargetedResultsTable
        function UntargetedResultsTableCellEdit(app, event)
            % Handle cell edit events
            if ~isempty(event.Indices)
                selectedRow = event.Indices(1);
                selectedMz = app.UntargetedResultsTable.Data{selectedRow, 1};
                plotEICForSelectedMz(app, selectedMz, 'untargeted'); % Added table type argument
            end
        end

        % Selection changed function: UntargetedResultsTable
        function UntargetedResultsTableSelectionChanged(app, event)
            % Store selected cell
            if ~isempty(event.Selection)
                app.UntargetedResultsTable.UserData.SelectedCell = event.Selection;

                % Get the selected m/z and plot
                selectedRow = event.Selection(1);
                selectedMz = app.UntargetedResultsTable.Data{selectedRow, 1};
                plotEICForSelectedMz(app, selectedMz, 'untargeted'); % Added table type argument
            end
        end

        % Cell selection callback: UntargetedResultsTable
        function UntargetedResultsTableCellSelection(app, event)
            % Handle cell selection events
            if ~isempty(event.Indices)
                selectedRow = event.Indices(1);
                selectedMz = app.UntargetedResultsTable.Data{selectedRow, 1};
                plotEICForSelectedMz(app, selectedMz);
            end
        end

        % Button pushed function: LoadDLNetworkButton
        function LoadDLNetworkButtonPushed(app, event)
            importDLNetwork(app);
        end

        % Button pushed function: ExportTableTab2
        function ExportTableTab2ButtonPushed(app, event)
            exportTableDataTab2(app);
        end

        % Button pushed function: LoadUntargetedDataButton
        function LoadUntargetedDataButtonPushed(app, event)
            uploadUntargetedTable(app, event);
        end

        % Button pushed function: ExportEIC
        function ExportEICButtonPushed(app, event)
            exportEICData(app, event);
        end

        % Button pushed function: LoadEICDataButton
        function LoadEICDataButtonPushed(app, event)
            uploadEICData(app, event);
        end

        % Button pushed function: ClassifyEICButton
        function ClassifyEICButtonPushed(app, event)
            classifyEIC(app);
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            app.CurrentEICFilter = string(app.FilterDropDown.Value);
            app.CurrentEICPage = 1; % reset to first page
            updateEICPlotGallery(app, app.CurrentEICFilter);
        end

        % Cell edit callback: UntargetedResultsTable_2
        function UntargetedResultsTable_2CellEdit(app, event)
            updateEICPlotGallery(app, app.FilterDropDown.Value);
        end

        % Button pushed function: ExportButtonDL
        function ExportButtonDLPushed(app, event)
            exportDLResults(app);
        end

        % Button pushed function: UploadcsvFolderButton
        function ImportAlignFilesButtonPushed(app, event)
            importAlignFiles(app);
        end

        % Button pushed function: AlignFilesButton
        function AlignFilesButtonPushed(app, event)
            alignFiles(app);
        end

        % Button pushed function: ExportAlignedTableButton
        function ExportAlignedTableButtonPushed(app, event)
            exportAlignment(app);
        end

        % Value changed function: DetectionFreqAlignmentEditField
        function DetectionFrequencyEditFieldValueChanged(app, event)
            newValue = app.DetectionFreqAlignmentEditField.Value;
            filterAlignment(app);
        end

        % Value changed function: DetectionFreqAlignmentEachEditField
        function DetectionFreqAlignmentEachEditFieldValueChanged(app, event)
            value = app.DetectionFreqAlignmentEachEditField.Value;
            applyMultiExperimentDetectionFilter(app);
        end

        % Button pushed function: PrevEICButton
        function PrevEICButtonPushed(app, event)
            app.CurrentEICPage = app.CurrentEICPage - 1;
            updateEICPlotGallery(app, app.CurrentEICFilter);
        end

        % Button pushed function: NextEICButton
        function NextEICButtonPushed(app, event)
            app.CurrentEICPage = app.CurrentEICPage + 1;
            updateEICPlotGallery(app, app.CurrentEICFilter);
        end

        % Selection change function: DLClassificationTabGroup
        function DLClassificationTabGroupSelectionChanged(app, event)
            % Refresh gallery whenever the user swaps tabs
            updateEICPlotGallery(app, app.FilterDropDown.Value);

            % Update status message for clarity
            appendStatusMessage(app, sprintf('Switched to %s view.', event.NewValue.Title));

        end

        % Cell selection callback: TargetedResultsTable_2
        function TargetedResultsTable_2CellSelection(app, event)
            if ~isempty(event.Indices)
                row = event.Indices(1);
                src = event.Source; % This is your table

                % --- DEBUG PRINT ---
                fprintf('\n--- TARGETED TABLE ROW DATA (Row %d) ---\n', row);
                % Extract the whole row
                rowData = src.Data(row, :);
                disp(rowData);
                % --------------------

                % Pass control back to your shared logic
                app.tableCellSelectionDL(src, event, 'targeted');
            end
        end

        % Button pushed function: UploadLogFileButton
        function UploadLogFileButtonPushed(app, event)
            [file, path] = uigetfile('*.txt', 'Select LabVIEW Stage Log File');
            if isequal(file, 0), return; end

            try
                % Read the table with preserved headers
                opts = detectImportOptions(fullfile(path, file));
                opts.VariableNamingRule = 'preserve';
                data = readtable(fullfile(path, file), opts);

                % Convert Timestamps (Crucial for the Sync math we fixed)
                data.Timestamp = datetime(data.Timestamp, 'InputFormat', 'yyyy-MM-dd HH:mm:ss.SSS');

                % SAVE TO APP PROPERTY
                app.rawLogTable = data;

                % Update UI Label
                % Suggestion: Use a specific label like app.LogFileNameLabel if possible
                app.UploadedFileNameLabel_2.Text = [file];

                app.appendStatusMessage(['Log file uploaded: ' file]);
            catch ME
                uialert(app.UIFigure, ['Error: ' ME.message], 'File Error');
            end
        end

        % Button pushed function: SyncCalculatedButton
        function SyncCalculatedButtonPushed(app, event)
            % 1. Validation: Ensure all necessary data and parameters are present
            if isempty(app.rawLogTable) || isempty(app.msStartTimes) || isempty(app.rawData)
                uialert(app.UIFigure, 'Ensure MS Data, Scan Data, and Log are loaded.', 'Sync Error');
                return;
            end

            if app.TouchdownDurationEditField.Value <= 0
                uialert(app.UIFigure, 'Please set a Touchdown Duration > 0.', 'Input Error');
                return;
            end

            try
                % 2. Identify the active MS Scan Data
                selectedFile = app.FileDropdown.Value;
                selectedIndex = find(strcmp({app.sourceFiles.name}, selectedFile));
                scanData = app.rawData{selectedIndex};

                % Filter by scan type (FTMS/ITMS) to match your automated detection
                if strcmpi(app.selectedScanType, 'FTMS')
                    scanData = scanData(contains(string(scanData(:, 4)), "FTMS", 'IgnoreCase', true), :);
                elseif strcmpi(app.selectedScanType, 'ITMS')
                    scanData = scanData(contains(string(scanData(:, 4)), "ITMS", 'IgnoreCase', true), :);
                end
                app.scanData = scanData;
                all_ms_times = [scanData{:, 2}]'; % Column 2: Scan Start Times (min)

                % --- UPDATED SECTION 3: Calculate Synchronization Timing ---
                msStart = app.msStartTimes(1);
                logStart = app.rawLogTable.Timestamp(1);

                % 1. Calculate the base AUTO delay (Natural difference between MS and Log)
                auto_delay_sec = seconds(msStart - logStart);

                % 2. Get the user's manual offset
                manual_offset_sec = app.ManualDelayOffsetEditField.Value;

                % 3. Calculate Final Offset
                % We REMOVE the abs() so negative values are preserved.
                % Logic: Total Delay = Auto Base + Manual Adjustment
                final_delay_sec = auto_delay_sec + manual_offset_sec;

                % Update the UI Checkbox/Labels
                if manual_offset_sec ~= 0
                    app.ManualOverrideCheckBox.Value = true;
                    delay_source = 'MANUAL ADJ';
                else
                    app.ManualOverrideCheckBox.Value = false;
                    delay_source = 'AUTO';
                end

                % 4. Apply the Sync
                log_rel_sec = seconds(app.rawLogTable.Timestamp - logStart);

                % Change: Use '+' to ensure positive manual values move windows RIGHT
                % and negative manual values move windows LEFT.
                sync_start_min = (log_rel_sec + final_delay_sec) / 60;
                touchdown_min = app.TouchdownDurationEditField.Value / 60;

                % 4. Initialize Data Arrays
                num_cells = length(sync_start_min);
                app.times_sc = zeros(num_cells, 2);
                app.times_bg = zeros(num_cells, 2);

                % Get BG parameters from your UI
                bg_scans = app.BackgroundScans.Value;
                bg_gap = app.BackgroundGap.Value;

                % 5. Core Calculation Loop: Align Log to MS Scans
                for i = 1:num_cells
                    t_start_log = sync_start_min(i);
                    t_end_log = t_start_log + touchdown_min;

                    [~, idx_start] = min(abs(all_ms_times - t_start_log));
                    [~, idx_end] = min(abs(all_ms_times - t_end_log));

                    app.times_sc(i, 1) = all_ms_times(idx_start);
                    app.times_sc(i, 2) = all_ms_times(idx_end);

                    % --- SYNC LOGIC FOR GLOBAL BG ---
                    if app.UseGlobalBackgroundCheckBox.Value && ~isempty(app.GlobalBGTime)
                        % Use the Global coordinates selected by the user
                        app.times_bg(i, 1) = app.GlobalBGTime(1);
                        app.times_bg(i, 2) = app.GlobalBGTime(2);
                    else
                        % ROLLING BACKGROUND: Calculate based on gap/scans
                        idx_bg_end = idx_start - bg_gap;
                        idx_bg_start = idx_bg_end - bg_scans;

                        if idx_bg_start > 0 && idx_bg_end > 1
                            app.times_bg(i, 1) = all_ms_times(max(1, idx_bg_start));
                            app.times_bg(i, 2) = all_ms_times(max(1, idx_bg_end));
                        else
                            app.times_bg(i, :) = [NaN, NaN];
                        end
                    end
                end

                % 6. Clean up: Remove windows that fall outside the MS run
                valid_sc = ~any(isnan(app.times_sc), 2) & app.times_sc(:,1) >= 0;
                app.times_sc = app.times_sc(valid_sc, :);
                app.times_bg = app.times_bg(valid_sc, :);

                % Chronological Filter (Prevents the "jump-backs")
                if ~isempty(app.times_sc)
                    keepIdx = true(size(app.times_sc, 1), 1);
                    last_end = -1;
                    for k = 1:size(app.times_sc, 1)
                        if app.times_sc(k, 1) < last_end
                            keepIdx(k) = false;
                        else
                            last_end = app.times_sc(k, 2);
                        end
                    end
                    app.times_sc = app.times_sc(keepIdx, :);
                    app.times_bg = app.times_bg(keepIdx, :);
                end

                % 7. Project onto TimeWindowsTable with DURATION
                max_rows = size(app.times_sc, 1);
                if max_rows > 0
                    durations = app.times_sc(:, 2) - app.times_sc(:, 1);

                    % Determine BG display values for the table
                    if app.UseGlobalBackgroundCheckBox.Value && ~isempty(app.GlobalBGTime)
                        % Fill the entire column with Global BG values
                        bg_start_col = repmat(app.GlobalBGTime(1), max_rows, 1);
                        bg_end_col = repmat(app.GlobalBGTime(2), max_rows, 1);
                    else
                        % Use the rolling values calculated in the loop
                        bg_start_col = app.times_bg(:,1);
                        bg_end_col = app.times_bg(:,2);
                    end

                    row_numbers = (1:max_rows)';
                    checkboxes = num2cell(false(max_rows, 1));

                    % Assemble: [SC_Start, SC_End, Duration, BG_Start, BG_End]
                    combined_mat = [app.times_sc(:,1), app.times_sc(:,2), durations, bg_start_col, bg_end_col];

                    table_data = [num2cell(row_numbers), checkboxes, num2cell(combined_mat)];
                    column_names = {'#', 'Select', 'SC_Start', 'SC_End', 'Duration', 'BG_Start', 'BG_End'};
                    app.TimeWindowsTable.Data = array2table(table_data, 'VariableNames', column_names);
                else
                    app.TimeWindowsTable.Data = [];

                    appendStatusMessage(app, 'Warning: No windows within MS time range.');
                end

                % 8. UI Feedback and Visualization
                app.MSStartTimeStatusLabel.Text = sprintf('[%s] Offset: %.2f s', delay_source, final_delay_sec);
                appendStatusMessage(app, sprintf('Sync Complete: %d events mapped.', max_rows));

                app.plotMzTrace();

            catch ME
                uialert(app.UIFigure, ['Sync failed: ' ME.message], 'Sync Error');
            end
        end

        % Button pushed function: ExportEventDataButton_2
        function ExportEventDataButton_2Pushed(app, event)
            % 1. Get the cleaned data from your app
            T = app.TimeWindowsTable.Data;
            if isempty(T), return; end

            try
                % 2. Prepare the clean numeric data (stripping the jump-backs)
                % Using the conversion logic that finally worked in your debugs
                raw_values = table2array(T(:, {'SC_Start', 'SC_End', 'BG_Start', 'BG_End'}));
                if iscell(raw_values), raw_values = cell2mat(raw_values); end

                % Filter out jump-backs/duplicates (Rows 27-31 in your images)
                clean_rows = [];
                last_e = -1;
                for i = 1:size(raw_values, 1)
                    if raw_values(i,1) >= last_e && raw_values(i,2) > raw_values(i,1)
                        clean_rows = [clean_rows; raw_values(i,:)];
                        last_e = raw_values(i,2);
                    end
                end

                exportT = array2table(clean_rows, 'VariableNames', ...
                    {'SC_Start', 'SC_End', 'BG_Start', 'BG_End'});

                % 3. SAVE DIALOG
                [file, path] = uiputfile('*.xlsx', 'Save Clean Windows');
                if isequal(file, 0), return; end
                full_path = fullfile(path, file);

                % 4. THE FIX: DELETE PREVIOUS FILE TO PREVENT OVERWRITING GHOSTS
                if exist(full_path, 'file')
                    delete(full_path);
                end

                % 5. Write fresh (WriteMode 'overwrite' for modern MATLAB)
                try
                    writetable(exportT, full_path, 'WriteMode', 'replacefile');
                catch
                    % Fallback for older MATLAB versions
                    writetable(exportT, full_path);
                end

                uialert(app.UIFigure, sprintf('Fresh file created with %d rows.', height(exportT)), 'Success');

            catch ME
                uialert(app.UIFigure, ['Export Failed: ', ME.message], 'Error');
            end
        end

        % Button pushed function: ClearSyncListButton
        function ClearSyncListButtonPushed(app, event)
            % 1. Clear Data Properties
            app.rawLogTable = [];      % Clears the Log
            app.time_list_events = []; % Clears the Manual List
            app.times_sc = [];         % Clears current windows
            app.times_bg = [];         % Clears current backgrounds

            % 2. Reset the UI Table
            app.TimeWindowsTable.Data = [];

            % 3. Reset UI Labels
            app.UploadedFileNameLabel_2.Text = "No file uploaded";

            % 4. Clear the plot patches
            app.plotMzTrace();

            app.appendStatusMessage('All logs and event lists cleared.');
        end

        % Value changed function: UseGlobalBackgroundCheckBox
        function UseGlobalBackgroundCheckBoxValueChanged(app, event)
            % 1. Get current checkbox state
            value = app.UseGlobalBackgroundCheckBox.Value;

            % 2. Safety Check: If Global is selected, make sure a window was actually set
            if value
                if isempty(app.GlobalBGTime) || length(app.GlobalBGTime) < 2
                    app.UseGlobalBackgroundCheckBox.Value = false;
                    uialert(app.UIFigure, 'Please select a Global Background window on the plot first.', 'Mode Error');
                    return;
                end
            end

            % 3. Determine the mode string for the status message
            if value
                modeStr = "Global";
            else
                modeStr = "Rolling";
            end

            % 4. Update the UI Table coordinates (Swaps between app.times_bg and app.GlobalBGTime)
            app.updateTableWithGlobalBG();

            % 5. CRITICAL: Re-extract if table is not empty
            % This ensures the app.spectra_bg and app.spectra_comb are updated
            if ~isempty(app.TimeWindowsTable.Data)
                % If we have an event list, re-run the extraction to update the spectra
                [~, ~, ~, ~, ~] = app.find_single_cell_extraction_manually(...
                    app.PrecedingScans.Value, ...
                    app.scanData, ...
                    app.BackgroundScans.Value, ...
                    app.BackgroundGap.Value, ...
                    app.TimeWindowsTable.Data);
            end

            % 6. Refresh Plot (to update the gold background patches)
            app.plotMzTrace();

            % 7. Final Feedback
            app.appendStatusMessage(sprintf('Background mode changed to: %s', modeStr));
        end

        % Button pushed function: SetGlobalBGButton
        function SetGlobalBGButtonPushed(app, event)
            % 1. Capture the current zoom levels from the plot
            current_x_limits = app.UIAxes.XLim;

            % 2. Store as the Global Background Time
            app.GlobalBGTime = [current_x_limits(1), current_x_limits(2)];

            % 3. Calculate how many scans are actually in this window
            selectedFile = app.FileDropdown.Value;
            selectedIndex = find(strcmp({app.sourceFiles.name}, selectedFile));
            scan_times = [app.rawData{selectedIndex}{:, 2}]';
            bg_idx = scan_times >= app.GlobalBGTime(1) & scan_times <= app.GlobalBGTime(2);
            num_scans = sum(bg_idx);

            % 4. UI Feedback
            app.UseGlobalBackgroundCheckBox.Value = true;

            % --- FIXED TYPO BELOW ---
            % Changed current_limits to current_x_limits to match line 3
            app.appendStatusMessage(sprintf('Global BG Set and Table Updated: %.2f-%.2f min (%d scans)', ...
                current_x_limits(1), current_x_limits(2), num_scans));

            % 5. CRITICAL: Update the Table and Plot
            app.updateTableWithGlobalBG(); % Updates the BG columns in the UI table
            app.plotMzTrace();             % Updates the gold patch
        end

        % Value changed function: GlobalBGEnd, GlobalBGStart
        function GlobalBGStartValueChanged(app, event)
            % 1. Get the raw values
            t_start = app.GlobalBGStart.Value;
            t_end = app.GlobalBGEnd.Value;

            % 2. Safety: Sort them so GlobalBGTime is always [Smaller, Bigger]
            app.GlobalBGTime = sort([t_start, t_end]);

            % 3. Automatically enable Global BG if the user is typing values
            if diff(app.GlobalBGTime) > 0
                app.UseGlobalBackgroundCheckBox.Value = true;
            end

            % 4. Refresh the plot so the gold patch updates in real-time
            app.plotMzTrace();

            % 5. Feedback in status bar
            app.appendStatusMessage(sprintf('Global BG updated: %.2f - %.2f min', ...
                app.GlobalBGTime(1), app.GlobalBGTime(2)));

            % --- TABLE UPDATE LOGIC ---

            % 1. Check if there is data in the table
            if isempty(app.TimeWindowsTable.Data)
                return;
            end

            % 2. Get the current table data
            T = app.TimeWindowsTable.Data;
            numRows = height(T);

            if app.UseGlobalBackgroundCheckBox.Value
                % FIX: Create a cell array of the same number for every row
                T.BG_Start = num2cell(repmat(app.GlobalBGTime(1), numRows, 1));
                T.BG_End   = num2cell(repmat(app.GlobalBGTime(2), numRows, 1));
            else
                % ROLLING MODE: Re-sync with the internal rolling arrays
                if ~isempty(app.times_bg) && size(app.times_bg, 1) == numRows
                    % FIX: Convert numeric array to cell array
                    T.BG_Start = num2cell(app.times_bg(:,1));
                    T.BG_End   = num2cell(app.times_bg(:,2));
                end
            end

            % 3. Push back to UI
            app.TimeWindowsTable.Data = T;
        end

        % Button pushed function: GenerateFileReportButton
        function GenerateFileReportButtonPushed(app, event)
            app.generateProcessingReport();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataSelection = uitab(app.TabGroup);
            app.DataSelection.Title = 'Data Selection';
            app.UIAxes = uiaxes(app.DataSelection);
            title(app.UIAxes, 'Extracted Ion Chronogram (EIC)')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TimeWindowsTable = uitable(app.DataSelection);
            app.TimeWindowsTable.ColumnName = {'''#'''; '''Remove'''; '''Event Start'''; '''Event End'''; '''Duration'''; 'Background Start'''; '''Background End'''};
            app.TimeWindowsTable.RowName = {};
            app.TimeWindowsTable.ColumnEditable = [false true false false false false false];
            app.ActionsLabel = uilabel(app.DataSelection);
            app.ActionsLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.DataSelection);
            app.StatusTextArea.Editable = 'off';
            app.PlotEICTracePanel = uipanel(app.DataSelection);
            app.PlotEICTracePanel.Title = 'Plot EIC Trace';
            app.PlotEICButton = uibutton(app.PlotEICTracePanel, 'push');
            app.PlotEICButton.ButtonPushedFcn = createCallbackFcn(app, @PlotEICButtonPushed, true);
            app.PlotEICButton.Text = 'Plot EIC';
            app.mzLabel_3 = uilabel(app.PlotEICTracePanel);
            app.mzLabel_3.Text = 'm/z';
            app.mzPlotting = uieditfield(app.PlotEICTracePanel, 'numeric');
            app.mzPlotting.Limits = [0 Inf];
            app.mzPlotting.ValueChangedFcn = createCallbackFcn(app, @mzValueChanged, true);
            app.mzPlotting.Value = 148.0604;
            app.ppmLabel = uilabel(app.PlotEICTracePanel);
            app.ppmLabel.Text = 'ppm';
            app.ppm = uieditfield(app.PlotEICTracePanel, 'numeric');
            app.ppm.Limits = [0 Inf];
            app.ppm.ValueChangedFcn = createCallbackFcn(app, @ppmValueChanged, true);
            app.ppm.Value = 3;
            app.PlotIntensityCheckbox = uicheckbox(app.PlotEICTracePanel);
            app.PlotIntensityCheckbox.Text = 'Plot Intensity';
            app.DataSelectionPanel = uipanel(app.DataSelection);
            app.DataSelectionPanel.Title = 'Data Selection';
            app.SelectDataFolderButton = uibutton(app.DataSelectionPanel, 'push');
            app.SelectDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderButtonPushed, true);
            app.SelectDataFolderButton.Text = 'Select Data Folder';
            app.LoadDLLsButton = uibutton(app.DataSelectionPanel, 'push');
            app.LoadDLLsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDLLsButtonPushed, true);
            app.LoadDLLsButton.Text = 'Load DLLs';
            app.FileLabel = uilabel(app.DataSelectionPanel);
            app.FileLabel.Text = 'File';
            app.FileDropdown = uidropdown(app.DataSelectionPanel);
            app.FileDropdown.Items = {'{''''}'};
            app.FileDropdown.Value = '{''''}';
            app.ScanTypeLabel = uilabel(app.DataSelectionPanel);
            app.ScanTypeLabel.Text = 'Scan Type';
            app.ScanTypeDropdown = uidropdown(app.DataSelectionPanel);
            app.ScanTypeDropdown.Items = {'FTMS', 'ITMS'};
            app.ScanTypeDropdown.ValueChangedFcn = createCallbackFcn(app, @ScanTypeDropdownValueChanged, true);
            app.ScanTypeDropdown.Value = 'FTMS';
            app.StatusLampImport = uilamp(app.DataSelection);
            app.TabGroup3 = uitabgroup(app.DataSelection);
            app.AutomatedDetectionTab = uitab(app.TabGroup3);
            app.AutomatedDetectionTab.Title = 'Automated Detection';
            app.EventWindowExtractionPanel = uipanel(app.AutomatedDetectionTab);
            app.EventWindowExtractionPanel.Title = 'Event Window Extraction';
            app.DetectEvents = uibutton(app.EventWindowExtractionPanel, 'push');
            app.DetectEvents.ButtonPushedFcn = createCallbackFcn(app, @DetectEventsButtonPushed, true);
            app.DetectEvents.Text = 'Detect Cells';
            app.UploadEventListButton = uibutton(app.EventWindowExtractionPanel, 'push');
            app.UploadEventListButton.ButtonPushedFcn = createCallbackFcn(app, @UploadEventListButtonPushed, true);
            app.UploadEventListButton.Text = 'Upload Event List';
            app.UploadedFileNameLabel = uilabel(app.EventWindowExtractionPanel);
            app.UploadedFileNameLabel.Text = 'No file uploaded';
            app.ClearEventListButton = uibutton(app.EventWindowExtractionPanel, 'push');
            app.ClearEventListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearEventListButtonPushed, true);
            app.ClearEventListButton.Text = 'Clear Event List';
            app.ManualExtractionLabel = uilabel(app.EventWindowExtractionPanel);
            app.ManualExtractionLabel.Text = 'Manual Extraction ';
            app.AutomaticDetectionLabel = uilabel(app.EventWindowExtractionPanel);
            app.AutomaticDetectionLabel.Text = 'Automatic Detection';
            app.mzLabel = uilabel(app.EventWindowExtractionPanel);
            app.mzLabel.Text = 'Event marker (m/z)';
            app.mz = uieditfield(app.EventWindowExtractionPanel, 'numeric');
            app.mz.Limits = [0 Inf];
            app.mz.ValueChangedFcn = createCallbackFcn(app, @mzValueChanged, true);
            app.mz.Value = 148.0604;
            app.mzLabel_2 = uilabel(app.EventWindowExtractionPanel);
            app.mzLabel_2.Text = 'S/N (raw) / Int. (mzML)';
            app.SN = uieditfield(app.EventWindowExtractionPanel, 'numeric');
            app.SN.Limits = [0 Inf];
            app.SN.ValueChangedFcn = createCallbackFcn(app, @SNValueChanged, true);
            app.SN.Value = 80;
            app.CutoffTimeLabel = uilabel(app.EventWindowExtractionPanel);
            app.CutoffTimeLabel.Text = 'Refractory period (min)';
            app.CutoffTime = uieditfield(app.EventWindowExtractionPanel, 'numeric');
            app.CutoffTime.ValueChangedFcn = createCallbackFcn(app, @CutoffTimeValueChanged, true);
            app.CutoffTime.Value = 0.1;
            app.ExportEventDataButton = uibutton(app.EventWindowExtractionPanel, 'push');
            app.ExportEventDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportEventDataButtonPushed, true);
            app.ExportEventDataButton.Text = 'Export Event Times';
            app.LogfileSyncTab = uitab(app.TabGroup3);
            app.LogfileSyncTab.Title = 'Logfile Sync';
            app.EventWindowExtractionPanel_2 = uipanel(app.LogfileSyncTab);
            app.EventWindowExtractionPanel_2.Title = 'Event Window Extraction';
            app.SyncCalculatedButton = uibutton(app.EventWindowExtractionPanel_2, 'push');
            app.SyncCalculatedButton.ButtonPushedFcn = createCallbackFcn(app, @SyncCalculatedButtonPushed, true);
            app.SyncCalculatedButton.Text = 'Sync Cell Windows';
            app.ExportEventDataButton_2 = uibutton(app.EventWindowExtractionPanel_2, 'push');
            app.ExportEventDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportEventDataButton_2Pushed, true);
            app.ExportEventDataButton_2.Text = 'Export Event Times';
            app.UploadLogFileButton = uibutton(app.EventWindowExtractionPanel_2, 'push');
            app.UploadLogFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadLogFileButtonPushed, true);
            app.UploadLogFileButton.Text = 'Upload Log File';
            app.TouchdownDurationsLabel = uilabel(app.EventWindowExtractionPanel_2);
            app.TouchdownDurationsLabel.Text = 'Touchdown Duration (s)';
            app.TouchdownDurationEditField = uieditfield(app.EventWindowExtractionPanel_2, 'numeric');
            app.ManualTimeDelayLabel = uilabel(app.EventWindowExtractionPanel_2);
            app.ManualTimeDelayLabel.Text = 'Manual Time Delay (s)';
            app.ManualDelayOffsetEditField = uieditfield(app.EventWindowExtractionPanel_2, 'numeric');
            app.ManualOverrideCheckBox = uicheckbox(app.EventWindowExtractionPanel_2);
            app.ManualOverrideCheckBox.Text = 'Manual Time Delay Selected';
            app.UploadedFileNameLabel_2 = uilabel(app.EventWindowExtractionPanel_2);
            app.UploadedFileNameLabel_2.Text = 'No file uploaded';
            app.ClearSyncListButton = uibutton(app.EventWindowExtractionPanel_2, 'push');
            app.ClearSyncListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSyncListButtonPushed, true);
            app.ClearSyncListButton.Text = 'Clear Sync List';
            app.DeleteSelectedRowsButton = uibutton(app.DataSelection, 'push');
            app.DeleteSelectedRowsButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedRowsButtonPushed, true);
            app.DeleteSelectedRowsButton.Text = 'Delete Selected Events';
            app.TabGroup4 = uitabgroup(app.DataSelection);
            app.AuromatedBGTab = uitab(app.TabGroup4);
            app.AuromatedBGTab.Title = 'Auromated BG';
            app.BackgroundWindowLabel = uilabel(app.AuromatedBGTab);
            app.BackgroundWindowLabel.Text = 'Background Window ';
            app.BackgroundScans = uieditfield(app.AuromatedBGTab, 'numeric');
            app.BackgroundScans.Value = 10;
            app.BackgroundScansLabel = uilabel(app.AuromatedBGTab);
            app.BackgroundScansLabel.Text = 'Background Scans';
            app.PrecedingScansLabel = uilabel(app.AuromatedBGTab);
            app.PrecedingScansLabel.Text = 'Preceding Scans';
            app.PrecedingScans = uieditfield(app.AuromatedBGTab, 'numeric');
            app.PrecedingScans.Value = 3;
            app.BackgroundGapLabel = uilabel(app.AuromatedBGTab);
            app.BackgroundGapLabel.Text = 'Background Gap';
            app.BackgroundGap = uieditfield(app.AuromatedBGTab, 'numeric');
            app.GlobalBGTab = uitab(app.TabGroup4);
            app.GlobalBGTab.Title = 'Global BG';
            app.BackgroundWindowLabel_2 = uilabel(app.GlobalBGTab);
            app.BackgroundWindowLabel_2.Text = 'Background Window ';
            app.UseGlobalBackgroundCheckBox = uicheckbox(app.GlobalBGTab);
            app.UseGlobalBackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseGlobalBackgroundCheckBoxValueChanged, true);
            app.UseGlobalBackgroundCheckBox.Text = 'Use Global Background';
            app.SetGlobalBGButton = uibutton(app.GlobalBGTab, 'push');
            app.SetGlobalBGButton.ButtonPushedFcn = createCallbackFcn(app, @SetGlobalBGButtonPushed, true);
            app.SetGlobalBGButton.Text = 'Set Global BG';
            app.StarttimeminLabel = uilabel(app.GlobalBGTab);
            app.StarttimeminLabel.Text = 'Start time   (min)';
            app.GlobalBGStart = uieditfield(app.GlobalBGTab, 'numeric');
            app.GlobalBGStart.ValueChangedFcn = createCallbackFcn(app, @GlobalBGStartValueChanged, true);
            app.EndtimeminLabel = uilabel(app.GlobalBGTab);
            app.EndtimeminLabel.Text = 'End time (min)';
            app.GlobalBGEnd = uieditfield(app.GlobalBGTab, 'numeric');
            app.GlobalBGEnd.ValueChangedFcn = createCallbackFcn(app, @GlobalBGStartValueChanged, true);
            app.Processing = uitab(app.TabGroup);
            app.Processing.Title = 'Data Processing';
            app.EICAxes = uiaxes(app.Processing);
            title(app.EICAxes, 'EIC')
            xlabel(app.EICAxes, 'X')
            ylabel(app.EICAxes, 'Y')
            zlabel(app.EICAxes, 'Z')
            app.ActionsLabel_2 = uilabel(app.Processing);
            app.ActionsLabel_2.Text = 'Status';
            app.StatusTextAreaProcessing = uitextarea(app.Processing);
            app.Label = uilabel(app.Processing);
            app.TargetedDataProcessingOptionsPanel = uipanel(app.Processing);
            app.TargetedDataProcessingOptionsPanel.Title = 'Targeted Data Processing Options';
            app.UploadISPaired = uibutton(app.TargetedDataProcessingOptionsPanel, 'push');
            app.UploadISPaired.ButtonPushedFcn = createCallbackFcn(app, @UploadISPairedButtonPushed, true);
            app.UploadISPaired.Text = 'Upload IS Paired List';
            app.UploadedISPairedLabel = uilabel(app.TargetedDataProcessingOptionsPanel);
            app.UploadedISPairedLabel.Text = 'No file uploaded';
            app.ClearISPairedListButton = uibutton(app.TargetedDataProcessingOptionsPanel, 'push');
            app.ClearISPairedListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearISPairedListButtonPushed, true);
            app.ClearISPairedListButton.Text = 'Clear IS Paired List';
            app.ISNormalizationCheckBox = uicheckbox(app.TargetedDataProcessingOptionsPanel);
            app.ISNormalizationCheckBox.Text = 'IS Normalization';
            app.DetectionFrequncyquantifiedLabel = uilabel(app.TargetedDataProcessingOptionsPanel);
            app.DetectionFrequncyquantifiedLabel.Text = 'Detection Freq. (quant.) [%]';
            app.DetectionFrequncyQuantified = uieditfield(app.TargetedDataProcessingOptionsPanel, 'numeric');
            app.DetectionFrequncyQuantified.Value = 33;
            app.FlowrateuLminEditFieldLabel = uilabel(app.TargetedDataProcessingOptionsPanel);
            app.FlowrateuLminEditFieldLabel.Text = 'Flow rate [uL/min]';
            app.FlowrateEditField = uieditfield(app.TargetedDataProcessingOptionsPanel, 'numeric');
            app.FlowrateEditField.Limits = [0 Inf];
            app.FlowrateEditField.Value = 0.1;
            app.UploadISExclusionListButton = uibutton(app.TargetedDataProcessingOptionsPanel, 'push');
            app.UploadISExclusionListButton.ButtonPushedFcn = createCallbackFcn(app, @UploadISExclusionListButtonPushed, true);
            app.UploadISExclusionListButton.Text = 'Upload IS Exclusion List';
            app.UploadedISExclusionLabel = uilabel(app.TargetedDataProcessingOptionsPanel);
            app.UploadedISExclusionLabel.Text = 'No file uploaded';
            app.ClearISExclusionListButton = uibutton(app.TargetedDataProcessingOptionsPanel, 'push');
            app.ClearISExclusionListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearISExclusionListButtonPushed, true);
            app.ClearISExclusionListButton.Text = 'Clear IS Exclusion List';
            app.AnnotationListButton = uibutton(app.TargetedDataProcessingOptionsPanel, 'push');
            app.AnnotationListButton.ButtonPushedFcn = createCallbackFcn(app, @AnnotationListButtonPushed, true);
            app.AnnotationListButton.Text = 'Upload Annotation/Inclusion List';
            app.AnnotationListLabel = uilabel(app.TargetedDataProcessingOptionsPanel);
            app.AnnotationListLabel.Text = 'No file uploaded';
            app.ClearAnnotationInclusionListButton = uibutton(app.TargetedDataProcessingOptionsPanel, 'push');
            app.ClearAnnotationInclusionListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAnnotationInclusionListButtonPushed, true);
            app.ClearAnnotationInclusionListButton.Text = 'Clear Annotation/Inclusion List';
            app.BaselineCorrectionCheckbox = uicheckbox(app.TargetedDataProcessingOptionsPanel);
            app.BaselineCorrectionCheckbox.Text = 'Baseline Correction';
            app.QuantificationLabel = uilabel(app.TargetedDataProcessingOptionsPanel);
            app.QuantificationLabel.Text = 'Quantification';
            app.AnnotationLabel = uilabel(app.TargetedDataProcessingOptionsPanel);
            app.AnnotationLabel.Text = 'Annotation';
            app.RemoveDuplicatesCheckBox_2 = uicheckbox(app.TargetedDataProcessingOptionsPanel);
            app.RemoveDuplicatesCheckBox_2.Text = 'Remove Duplicates';
            app.ProcessingOptionsPanel = uipanel(app.Processing);
            app.ProcessingOptionsPanel.Title = 'Processing Options';
            app.TICNormalizationCheckBox = uicheckbox(app.ProcessingOptionsPanel);
            app.TICNormalizationCheckBox.Text = 'TIC Normalization';
            app.IntensitiesDropDownLabel = uilabel(app.ProcessingOptionsPanel);
            app.IntensitiesDropDownLabel.Text = ' Intensities';
            app.IntensitiesDropDown = uidropdown(app.ProcessingOptionsPanel);
            app.IntensitiesDropDown.Items = {'Sum', 'Average'};
            app.IntensitiesDropDown.Value = 'Sum';
            app.IsotopeFilteringCheckBox = uicheckbox(app.ProcessingOptionsPanel);
            app.IsotopeFilteringCheckBox.Text = 'Isotope Filtering';
            app.IsotopeexactmassLabel = uilabel(app.ProcessingOptionsPanel);
            app.IsotopeexactmassLabel.Text = 'Isotope (exact mass)';
            app.IsotopeExactMassEditField = uieditfield(app.ProcessingOptionsPanel, 'numeric');
            app.IsotopeExactMassEditField.Limits = [0 Inf];
            app.IsotopeExactMassEditField.Value = 1.00335;
            app.ppmEditField_2Label = uilabel(app.ProcessingOptionsPanel);
            app.ppmEditField_2Label.Text = 'ppm';
            app.ppmIsotopeFiltering = uieditfield(app.ProcessingOptionsPanel, 'numeric');
            app.ppmIsotopeFiltering.Limits = [0 Inf];
            app.ppmIsotopeFiltering.Value = 3;
            app.MassDefectFilteringCheckBox = uicheckbox(app.ProcessingOptionsPanel);
            app.MassDefectFilteringCheckBox.Text = 'Mass Defect Filtering';
            app.UploadExclusionList = uibutton(app.ProcessingOptionsPanel, 'push');
            app.UploadExclusionList.ButtonPushedFcn = createCallbackFcn(app, @UploadExclusionListButtonPushed, true);
            app.UploadExclusionList.Text = 'Exclusion List';
            app.UploadedExclusionLabel = uilabel(app.ProcessingOptionsPanel);
            app.UploadedExclusionLabel.Text = 'No file uploaded';
            app.ClearExclusionButton = uibutton(app.ProcessingOptionsPanel, 'push');
            app.ClearExclusionButton.ButtonPushedFcn = createCallbackFcn(app, @ClearExclusionButtonPushed, true);
            app.ClearExclusionButton.Text = 'Clear Exclusion List';
            app.ppmDatabaseSearchLabel = uilabel(app.ProcessingOptionsPanel);
            app.ppmDatabaseSearchLabel.Text = 'ppm (Search)';
            app.ppmSearch = uieditfield(app.ProcessingOptionsPanel, 'numeric');
            app.ppmSearch.Value = 3;
            app.ExcludemzLabel = uilabel(app.ProcessingOptionsPanel);
            app.ExcludemzLabel.Text = 'Exclude m/z';
            app.FilteringLabel = uilabel(app.ProcessingOptionsPanel);
            app.FilteringLabel.Text = 'Filtering';
            app.IntensitythresholdEditFieldLabel = uilabel(app.ProcessingOptionsPanel);
            app.IntensitythresholdEditFieldLabel.Text = 'Intensity threshold ';
            app.IntensitythresholdEditField = uieditfield(app.ProcessingOptionsPanel, 'numeric');
            app.IntensitythresholdEditField.Limits = [0 Inf];
            app.IntensitythresholdEditField.Value = 1000;
            app.TabGroup2 = uitabgroup(app.Processing);
            app.UntargetedTab = uitab(app.TabGroup2);
            app.UntargetedTab.Title = 'Untargeted';
            app.UntargetedResultsTable = uitable(app.UntargetedTab);
            app.UntargetedResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UntargetedResultsTable.RowName = {};
            app.UntargetedResultsTable.CellEditCallback = createCallbackFcn(app, @UntargetedResultsTableCellEdit, true);
            app.UntargetedResultsTable.CellSelectionCallback = createCallbackFcn(app, @UntargetedResultsTableCellSelection, true);
            app.UntargetedResultsTable.SelectionChangedFcn = createCallbackFcn(app, @UntargetedResultsTableSelectionChanged, true);
            app.AnnotatedTab = uitab(app.TabGroup2);
            app.AnnotatedTab.Title = 'Annotated';
            app.AnnotatedResultsTable = uitable(app.AnnotatedTab);
            app.AnnotatedResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.AnnotatedResultsTable.RowName = {};
            app.UntargetedOptionsPanel = uipanel(app.Processing);
            app.UntargetedOptionsPanel.Title = 'Untargeted Options ';
            app.FoldchangeEditFieldLabel = uilabel(app.UntargetedOptionsPanel);
            app.FoldchangeEditFieldLabel.Text = 'Fold change';
            app.FoldchangeEditField = uieditfield(app.UntargetedOptionsPanel, 'numeric');
            app.FoldchangeEditField.Value = 20;
            app.BackgroundFilteringLabel = uilabel(app.UntargetedOptionsPanel);
            app.BackgroundFilteringLabel.Text = 'Background Filtering';
            app.DetectionFreqEditFieldLabel = uilabel(app.UntargetedOptionsPanel);
            app.DetectionFreqEditFieldLabel.Text = 'Detection Freq.';
            app.DetectionFreqEditField = uieditfield(app.UntargetedOptionsPanel, 'numeric');
            app.DetectionFreqEditField.Limits = [0 Inf];
            app.DetectionFreqEditField.Value = 66;
            app.MinScansLabel = uilabel(app.UntargetedOptionsPanel);
            app.MinScansLabel.Text = 'Min. Scans (SC)';
            app.MinScansSCEditField = uieditfield(app.UntargetedOptionsPanel, 'numeric');
            app.MinScansSCEditField.Limits = [0 Inf];
            app.MinScansSCEditField.Value = 4;
            app.DetectFreqinclBGEditFieldLabel = uilabel(app.UntargetedOptionsPanel);
            app.DetectFreqinclBGEditFieldLabel.Text = 'Detect. Freq. (incl. BG)';
            app.MinHitsEditField = uieditfield(app.UntargetedOptionsPanel, 'numeric');
            app.MinHitsEditField.Limits = [0 100];
            app.MinHitsEditField.Value = 5;
            app.InsideCellographicPeakLabel = uilabel(app.UntargetedOptionsPanel);
            app.InsideCellographicPeakLabel.Text = 'Inside Cellographic Peak';
            app.StatusLampProcessing = uilamp(app.Processing);
            app.DataProcessingTargeted = uibutton(app.Processing, 'push');
            app.DataProcessingTargeted.ButtonPushedFcn = createCallbackFcn(app, @DataProcessingTargetedPushed, true);
            app.DataProcessingTargeted.Text = 'Process Data';
            app.ExportTableTab2 = uibutton(app.Processing, 'push');
            app.ExportTableTab2.ButtonPushedFcn = createCallbackFcn(app, @ExportTableTab2ButtonPushed, true);
            app.ExportTableTab2.Text = 'Export Selected Table';
            app.ExportEIC = uibutton(app.Processing, 'push');
            app.ExportEIC.ButtonPushedFcn = createCallbackFcn(app, @ExportEICButtonPushed, true);
            app.ExportEIC.Text = 'Export EIC';
            app.GenerateFileReportButton = uibutton(app.Processing, 'push');
            app.GenerateFileReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFileReportButtonPushed, true);
            app.GenerateFileReportButton.Text = 'Generate File Report';
            app.DeepLearning = uitab(app.TabGroup);
            app.DeepLearning.Title = 'Deep Learning Classification';
            app.EICAxes_2 = uiaxes(app.DeepLearning);
            title(app.EICAxes_2, 'EIC')
            xlabel(app.EICAxes_2, 'X')
            ylabel(app.EICAxes_2, 'Y')
            zlabel(app.EICAxes_2, 'Z')
            app.DLClassificationTabGroup = uitabgroup(app.DeepLearning);
            app.DLClassificationTabGroup.SelectionChangedFcn = createCallbackFcn(app, @DLClassificationTabGroupSelectionChanged, true);
            app.UntargetedTab_2 = uitab(app.DLClassificationTabGroup);
            app.UntargetedTab_2.Title = 'Untargeted';
            app.UntargetedResultsTable_2 = uitable(app.UntargetedTab_2);
            app.UntargetedResultsTable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UntargetedResultsTable_2.RowName = {};
            app.UntargetedResultsTable_2.CellEditCallback = createCallbackFcn(app, @UntargetedResultsTable_2CellEdit, true);
            app.TargetedTab_2 = uitab(app.DLClassificationTabGroup);
            app.TargetedTab_2.Title = 'Targeted';
            app.TargetedResultsTable_2 = uitable(app.TargetedTab_2);
            app.TargetedResultsTable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TargetedResultsTable_2.RowName = {};
            app.TargetedResultsTable_2.CellSelectionCallback = createCallbackFcn(app, @TargetedResultsTable_2CellSelection, true);
            app.ActionsLabel_3 = uilabel(app.DeepLearning);
            app.ActionsLabel_3.Text = 'Status';
            app.StatusTextAreaDL = uitextarea(app.DeepLearning);
            app.ScrollablePanel = uipanel(app.DeepLearning);
            app.ScrollablePanel.Title = 'EIC Gallery';
            app.ClassificationLabel = uilabel(app.DeepLearning);
            app.ClassificationLabel.Text = 'Classification';
            app.FilterDropDown = uidropdown(app.DeepLearning);
            app.FilterDropDown.Items = {'All', 'High S/N', 'Low S/N', 'Background'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'High S/N';
            app.ExportClassifiedDataPanel = uipanel(app.DeepLearning);
            app.ExportClassifiedDataPanel.Title = 'Export Classified Data ';
            app.ClassificationDropDownLabel = uilabel(app.ExportClassifiedDataPanel);
            app.ClassificationDropDownLabel.Text = 'Classification';
            app.ExportFilterDropDown = uidropdown(app.ExportClassifiedDataPanel);
            app.ExportFilterDropDown.Items = {'All', 'High S/N', 'Low S/N', 'Background'};
            app.ExportFilterDropDown.Value = 'All';
            app.ExportButtonDL = uibutton(app.ExportClassifiedDataPanel, 'push');
            app.ExportButtonDL.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonDLPushed, true);
            app.ExportButtonDL.Text = 'Export .csv';
            app.DeepLearningModelPanel = uipanel(app.DeepLearning);
            app.DeepLearningModelPanel.Title = 'Deep Learning Model';
            app.LoadDLNetworkButton = uibutton(app.DeepLearningModelPanel, 'push');
            app.LoadDLNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDLNetworkButtonPushed, true);
            app.LoadDLNetworkButton.Text = 'Load DL Network';
            app.LoadUntargetedDataButton = uibutton(app.DeepLearningModelPanel, 'push');
            app.LoadUntargetedDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadUntargetedDataButtonPushed, true);
            app.LoadUntargetedDataButton.Text = 'Load Untargeted Data';
            app.LoadEICDataButton = uibutton(app.DeepLearningModelPanel, 'push');
            app.LoadEICDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEICDataButtonPushed, true);
            app.LoadEICDataButton.Text = 'Load EIC Data';
            app.ClassifyEICButton = uibutton(app.DeepLearningModelPanel, 'push');
            app.ClassifyEICButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyEICButtonPushed, true);
            app.ClassifyEICButton.Text = 'Classify EIC';
            app.StatusLampDL = uilamp(app.DeepLearning);
            app.NextEICButton = uibutton(app.DeepLearning, 'push');
            app.NextEICButton.ButtonPushedFcn = createCallbackFcn(app, @NextEICButtonPushed, true);
            app.NextEICButton.Text = 'Next';
            app.PrevEICButton = uibutton(app.DeepLearning, 'push');
            app.PrevEICButton.ButtonPushedFcn = createCallbackFcn(app, @PrevEICButtonPushed, true);
            app.PrevEICButton.Text = 'Previous';
            app.FileAlignment = uitab(app.TabGroup);
            app.FileAlignment.Title = 'File Alignment';
            app.AligningcsvFIlesPanel = uipanel(app.FileAlignment);
            app.AligningcsvFIlesPanel.Title = 'Aligning .csv FIles';
            app.UploadcsvFolderButton = uibutton(app.AligningcsvFIlesPanel, 'push');
            app.UploadcsvFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAlignFilesButtonPushed, true);
            app.UploadcsvFolderButton.Text = 'Upload .csv Folder';
            app.AlignFilesButton = uibutton(app.AligningcsvFIlesPanel, 'push');
            app.AlignFilesButton.ButtonPushedFcn = createCallbackFcn(app, @AlignFilesButtonPushed, true);
            app.AlignFilesButton.Text = 'Align Files';
            app.ExportAlignedTableButton = uibutton(app.AligningcsvFIlesPanel, 'push');
            app.ExportAlignedTableButton.ButtonPushedFcn = createCallbackFcn(app, @ExportAlignedTableButtonPushed, true);
            app.ExportAlignedTableButton.Text = 'Export Aligned Table';
            app.ppmAlignmentLabel = uilabel(app.AligningcsvFIlesPanel);
            app.ppmAlignmentLabel.Text = 'ppm Alignment';
            app.ppmAlignmentEditField = uieditfield(app.AligningcsvFIlesPanel, 'numeric');
            app.ppmAlignmentEditField.Limits = [0 Inf];
            app.ppmAlignmentEditField.Value = 5;
            app.RemoveDuplicatesCheckBox = uicheckbox(app.AligningcsvFIlesPanel);
            app.RemoveDuplicatesCheckBox.Text = ' Remove Duplicates (Annotated)';
            app.RemoveDuplicatesCheckBox.Value = true;
            app.ActionsLabel_4 = uilabel(app.FileAlignment);
            app.ActionsLabel_4.Text = 'Status';
            app.StatusTextAreaAlignment = uitextarea(app.FileAlignment);
            app.FileTable = uitable(app.FileAlignment);
            app.FileTable.ColumnName = {'Use'; 'Filename'};
            app.FileTable.RowName = {};
            app.FileTable.ColumnEditable = [true false];
            app.FinalAlignmentTable = uitable(app.FileAlignment);
            app.FinalAlignmentTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.FinalAlignmentTable.RowName = {};
            app.FilteringPanel = uipanel(app.FileAlignment);
            app.FilteringPanel.Title = 'Filtering';
            app.DetectionFrequencyGlobalLabel = uilabel(app.FilteringPanel);
            app.DetectionFrequencyGlobalLabel.Text = 'Detection Frequency Global [%]';
            app.DetectionFreqAlignmentEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.DetectionFreqAlignmentEditField.ValueChangedFcn = createCallbackFcn(app, @DetectionFrequencyEditFieldValueChanged, true);
            app.DetectionFrequencyEditField_2Label_2 = uilabel(app.FilteringPanel);
            app.DetectionFrequencyEditField_2Label_2.Text = 'Detection Frequency Each [%]';
            app.DetectionFreqAlignmentEachEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.DetectionFreqAlignmentEachEditField.ValueChangedFcn = createCallbackFcn(app, @DetectionFreqAlignmentEachEditFieldValueChanged, true);
            app.StatusLampFileAlignment = uilamp(app.FileAlignment);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellMateV19Published

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end